classdef ClosingDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SegmentationToolClosingDialogUIFigure  matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        AppclosingLabel      matlab.ui.control.Label
        WouldyouliketosaveyourprogressLabel  matlab.ui.control.Label
        SaveCloseButton      matlab.ui.control.StateButton
        DontSaveCloseButton  matlab.ui.control.StateButton
        CancelButton         matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        seg_tool_base % main app object
        active_closing_button;
        %
    end
    %
    methods (Access = private)
        %
        function handle_closing_dialog_click(app, button_id)
            %
            handle_button_reset(app)
            %
            handle_button_click(app.seg_tool_base, ...
                app.([button_id,'Button']), 'on')
            %
            pause(.2)
            %
            app.SegmentationToolClosingDialogUIFigure.Visible = 'off';
            %
            handle_seg_tool_closing_dialog(app.seg_tool_base, ...
                app.seg_tool_base.closing_options.(button_id))
            %
            delete(app)
            %
        end
        %
        function change_active_closing_button(app)
            %%
            % change the active button based on the active_closing_button var
            %%
            handle_button_reset(app)
            %
            if app.active_closing_button > 2
                app.active_closing_button = 0;
            end
            %
            if app.active_closing_button < 0
                app.active_closing_button = 2;
            end
            %
            button_id = app.seg_tool_base.struct_idx_lookup(...
                app.seg_tool_base.closing_options, ...
                app.active_closing_button);
            handle_button_click(app.seg_tool_base, ...
                app.([button_id,'Button']), 'on')
            %
        end
        %
        function handle_button_reset(app)
        %%
        % turn off all on buttons
        %%
        handle_button_click(app.seg_tool_base, app.CancelButton, 'off')
        handle_button_click(app.seg_tool_base, app.SaveCloseButton, 'off')
        handle_button_click(app.seg_tool_base, app.DontSaveCloseButton, 'off')
        %
        end
        %
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            %
            app.seg_tool_base = mainapp;  
            handle_button_click(...
                app.seg_tool_base, app.SaveCloseButton, 'on')
            app.active_closing_button = ...
                app.seg_tool_base.closing_options.SaveClose;
            %
        end

        % Close request function: 
        % SegmentationToolClosingDialogUIFigure
        function SegmentationToolClosingDialogUIFigureCloseRequest(app, event)
            %
            handle_closing_dialog_click(app, 'Cancel')
            %
        end

        % Value changed function: SaveCloseButton
        function SaveCloseButtonValueChanged(app, event)
            %
            handle_closing_dialog_click(app, 'SaveClose')
            %
        end

        % Value changed function: DontSaveCloseButton
        function DontSaveCloseButtonValueChanged(app, event)
            %
            handle_closing_dialog_click(app, 'DontSaveClose')
            %
        end

        % Value changed function: CancelButton
        function CancelButtonValueChanged(app, event)
            %
            handle_closing_dialog_click(app, 'Cancel')
            %
        end

        % Key press function: SegmentationToolClosingDialogUIFigure
        function SegmentationToolClosingDialogUIFigureKeyPress(app, event)
            %
            switch event.Key
                %
                case 'rightarrow'
                    %
                    app.active_closing_button = app.active_closing_button + 1;
                    change_active_closing_button(app)
                    %
                case 'leftarrow'
                    %
                    app.active_closing_button = app.active_closing_button - 1;
                    change_active_closing_button(app)
                    %
                case 'return'
                    %
                    button_id = app.seg_tool_base.struct_idx_lookup(...
                        app.seg_tool_base.closing_options, ...
                        app.active_closing_button);
                    handle_closing_dialog_click(app, button_id)
                    %
            end
            %
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SegmentationToolClosingDialogUIFigure = uifigure('Visible', 'off');
            app.SegmentationToolClosingDialogUIFigure.Name = 'Segmentation Tool Closing Dialog';
            app.SegmentationToolClosingDialogUIFigure.CloseRequestFcn = createCallbackFcn(app, @SegmentationToolClosingDialogUIFigureCloseRequest, true);
            app.SegmentationToolClosingDialogUIFigure.KeyPressFcn = createCallbackFcn(app, @SegmentationToolClosingDialogUIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.SegmentationToolClosingDialogUIFigure);
            app.AppclosingLabel = uilabel(app.GridLayout);
            app.AppclosingLabel.Text = 'App closing ...';
            app.WouldyouliketosaveyourprogressLabel = uilabel(app.GridLayout);
            app.WouldyouliketosaveyourprogressLabel.Text = 'Would you like to save your progress?';
            app.SaveCloseButton = uibutton(app.GridLayout, 'state');
            app.SaveCloseButton.ValueChangedFcn = createCallbackFcn(app, @SaveCloseButtonValueChanged, true);
            app.SaveCloseButton.Text = 'Save & Close';
            app.DontSaveCloseButton = uibutton(app.GridLayout, 'state');
            app.DontSaveCloseButton.ValueChangedFcn = createCallbackFcn(app, @DontSaveCloseButtonValueChanged, true);
            app.DontSaveCloseButton.Text = 'Don''t Save & Close';
            app.CancelButton = uibutton(app.GridLayout, 'state');
            app.CancelButton.ValueChangedFcn = createCallbackFcn(app, @CancelButtonValueChanged, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.SegmentationToolClosingDialogUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ClosingDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SegmentationToolClosingDialogUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SegmentationToolClosingDialogUIFigure)
        end
    end
end