classdef SegTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SegToolUI                    matlab.ui.Figure
        UIGridLayout                 matlab.ui.container.GridLayout
        RightSideGridLayout          matlab.ui.container.GridLayout
        ToolsPanel                   matlab.ui.container.Panel
        ToolsGridLayout              matlab.ui.container.GridLayout
        ClassificationPanel          matlab.ui.container.Panel
        ClassificationGridLayout     matlab.ui.container.GridLayout
        ClassificationLabel          matlab.ui.control.Label
        DrawonDAPIButton             matlab.ui.control.StateButton
        DrawonMemButton              matlab.ui.control.StateButton
        ArtifactButton               matlab.ui.control.StateButton
        NeitherButton                matlab.ui.control.StateButton
        AButton                      matlab.ui.control.StateButton
        BButton                      matlab.ui.control.StateButton
        ABButton                     matlab.ui.control.StateButton
        DisplayPanel                 matlab.ui.container.Panel
        DisplayGridLayout            matlab.ui.container.GridLayout
        ToggleSegButton              matlab.ui.control.StateButton
        DisplayLabel                 matlab.ui.control.Label
        AppliedSegButton             matlab.ui.control.StateButton
        DAPIButton                   matlab.ui.control.StateButton
        MEMButton                    matlab.ui.control.StateButton
        ContrastSliderLabel          matlab.ui.control.Label
        ContrastSlider               matlab.ui.control.Slider
        BrightnessSliderLabel        matlab.ui.control.Label
        BrightnessSlider             matlab.ui.control.Slider
        AdditionalOptionsPanel       matlab.ui.container.Panel
        AdditionalOptionsGridLayout  matlab.ui.container.GridLayout
        ObjectPlaneSpinnerLabel      matlab.ui.control.Label
        ObjectPlaneSpinner           matlab.ui.control.Spinner
        AddcellButton                matlab.ui.control.StateButton
        FlagButton                   matlab.ui.control.StateButton
        MultiNucButton               matlab.ui.control.StateButton
        TrashButton                  matlab.ui.control.StateButton
        ChangeCellGridLayout         matlab.ui.container.GridLayout
        backOnecellButton            matlab.ui.control.StateButton
        forwardOnecellButton         matlab.ui.control.StateButton
        NextCellButton               matlab.ui.control.StateButton
        JumptocellButton             matlab.ui.control.StateButton
        JumpCellButton               matlab.ui.control.NumericEditField
        SettingsGridLayout           matlab.ui.container.GridLayout
        LoadImageButton              matlab.ui.control.StateButton
        SaveTableButton              matlab.ui.control.StateButton
        iButton                      matlab.ui.control.StateButton
        FVButton                     matlab.ui.control.StateButton
        LeftSideGridLayout           matlab.ui.container.GridLayout
        Images_Panel                 matlab.ui.container.Panel
        ImagesGridLayout             matlab.ui.container.GridLayout
        AM_ImagePanel                matlab.ui.container.Panel
        BM_ImagePanel                matlab.ui.container.Panel
        AD_ImagePanel                matlab.ui.container.Panel
        BD_ImagePanel                matlab.ui.container.Panel
        LabelsGridLayout             matlab.ui.container.GridLayout
        ALabel                       matlab.ui.control.Label
        BLabel                       matlab.ui.control.Label
    end

    %
    properties (Access = public)
        %% -----------------------------------------------
        % file name and formats that can be changed in the settings window
        %
        color_image_filename; % path to file name that will be built on 
        % execution
        color_image_identifier = '_component_data.tif' % extension after 
        % the filename for the color image 
        %
        image_1_type = 'inform_data'; % the name of the data type for image
        % 1, specifying the 1st level of the folder tree
        image_2_type = 'superpixel'; % the name of the data type for image
        % 2, specifying the 1st level of the folder tree
        image_1_abbreviation = 'IF'; % how to abbreviate the name for image
        % 1 type data in the tables
        image_2_abbreviation = 'SP'; % how to abbreviate the name for image
        % 2 type data in the tables
        image_folder = 'Component_Tiffs'; % the second level of the folder
        % tree, should be the subfolder of image_[x]_type folders that hold
        % the images
        image_identifier_parse = '_component'; % the part of the file name 
        % that separates it between a unqiue 'image_id' and the
        % segmentation image file separators
        seg_tool_identifier = '_comparison'; % file names for the tool 
        % ouput are:
        % [image_id]_[seg_tool_indentifier]_table_[seg_tool_identifier_end]
        % [image_id]_[seg_tool_indentifier]_image_[seg_tool_identifier_end]
        seg_tool_identifier_end = '_seg_data'; % see above
        image_2_input_boundary_type = 'mask'; % the boundary type of the 
        % image 2 input image (mask or outline)
        image_1_input_boundary_type = 'outline';  % the boundary type of 
        % the image 2 input image (mask or outline)
        image_spec = '*seg*'; % how to identify the segmentation images
        boundary_type = 'membrane'; % membrane vs nucleus
        %
        image_1_layers_nucleus = [12 13];
        image_1_layers_membrane = [14 15];
        image_2_layers_nucleus = [1 2];
        image_2_layers_membrane = 1;
        image_1_color_layers = [1 9];
        %
        overlap_percent_to_cleanup = 0.80; % when cells in the applied / 
        % working segmentation mask overlap by this amount or more they are 
        % removed.
        same_cell_overlap_percent = 0.80; % determines how much cells have 
        % to overlap for them to be compared in cell to cell comparison
        %% -----------------------------------------------
        % segmentation masks
        segmentation_image_1; % segmentation overlay image for image 1 
        % centered around the current cell
        segmentation_image_2; % segmentation overlay image for image 2
        % centered around the current cell
        A; % image segmentation to apply for A side 
        B; % image segmenation to apply for B side 
        applied_seg_mask; % applied segmentation mask, for cells already 
        % reviewed
        applied_seg_mask_image_1; % applied segmentation mask, with a mask
        % around the current cell from segmentation 1
        applied_seg_mask_image_2; % applied segmentation mask, with a mask
        % around the current cell from segmentation 2
        template_image; % a template image size nRows x nCols
        Flag_mask; % mask for the cells flagged by user
        MultiNuc_mask; % mask for the cells indicated as multi-nucleated
        %% -----------------------------------------------
        % computation variables for various settings
        class_selection_complete = 1; % whether or not the class selection
        % button has finished running during various interupts
        display_add_cell_instructions = true; % used to specifiy if add 
        % cell instructions have been show for the user.
        %
        class_ids = struct(...
            'not_reviewed', 0, ...
            'image_1', 1, ...
            'image_2', 2, ...
            'drawn', 3, ...
            'DrawonDAPI', 3, ...
            'DrawonMem', 3, ...
            'auto_reviewed', 4,...
            'AB', 5, ...
            'Artifact', -1, ...
            'Neither', -2, ...
            'Trash', -3);
        cell_check_ids = struct(...
            'dont_review', 0, ...
            'review', 1, ...
            'overlap_test_set', 2);
        flag_ids = struct(...
            'AppliedSeg', 0, ...
            'MultiNuc', 1, ...
            'Flag', 2, ...
            'AppliedSegCells', 4, ...
            'Labels', 8, ...
            'Applied_RGB', 16);
        closing_options = struct(...
            'Cancel', 2, ...
            'DontSaveClose', 1, ...
            'SaveClose', 0);
        next_cell_options = struct(...
            'forwardOnecell', 0, ...
            'NextCell', 1, ...
            'backOnecell', 2, ...
            'Jumptocell', 3);
        button_interrupt_options = struct(...
            'new_cell', 0, ...
            'class_selection', 1, ...
            'trash', 2, ...
            'flag', 3);
        %% -------------------------------------------------
        % for storing cells
        overlap_table; % final\ resulting table with overlaps and other
        % relevant info
        draw_im; % the image with the drawn objects
        paired_cells % matlab cell struct where each stores value stores
        % linear indicies of the respective cell
        bounding_box % the viewing bounds
        image_1_cell_coordinates; % the [row col] coordinates for the cell 
        % from image 1
        image_2_cell_coordinates; % the [row col] coordinates for the cell 
        % from image 2
        %% -----------------------------------------------
        % variables read from input images
        nRows; % rows
        nCols; % columns
        image_id; % current image name
        input_color_vectors; % dapi and membrane component layers in 
        % column vector
        region_color_vectors; % dapi and membrane component layers for the 
        % image regions displayed per cell pair
        %% -----------------------------------------------
        % for image display
        my_colors = [0 0 1; % DAPI
            1 1 1];   % Membrane
        a_m = 2.5; % default membrane contrast
        a_d = 2.5; % default dapi contrast
        b_m = 200; % default membrane scaling
        b_d = 200; % default dapi scaling
        a_slider_scale_value = 0.05; % value to multiply 'a' values by when
        % performing the color scaling
        b_slider_scale_value = 4; % value to multiply 'a' values by when
        % performing the color scaling
        im_dapi; % dapi image
        im_membrane_and_dapi; % membrane and dapi image
        segmentation_color = 225;
        mask_alpha = 0.75; % segmentation mask alpha for display
        buffer_edge = 50; % use a 50 pixel buffer when building the
        % segmentation image views to get cells that are only partially 
        % segmented
        %% -----------------------------------------------
        active_ui; % the active ui for the error messages and notifications
        axis_to_select_on; % the axis to use to select on for trash, flag,
        % and multinucleated selection operations
        %% -------------------------------------------------
        % for error handling
        error; % error variable passed between functions; 0 for no error
        error_title; % title message for errors
        error_message; % message for errors
        active_app; % the active app, used to create ui notifications
        % shared by multiple apps
        finished_status = 0;
        %% -----------------------------------------------
        % apps
        apps = struct(...
            'seg_tool_base', 0, ...
            'add_cell', 1, ...
            'full_image_view', 2) 
        pgbar; % progress bar passed between apps for the image loading
        object_handles; % handle passed between function for the closing 
        % dialog
    end
    %
    properties (Access = private)
        %% -----------------------------------------------
        % for image display
        % format
        AD_ImageAxes; % the dapi A side image axes
        AM_ImageAxes; % the membrane and dapi A side image axes
        BD_ImageAxes; % the dapi B side image axes
        BM_ImageAxes; % the membrane and dapi B side image axes
        %
        AD_Image; % the dapi A side image itself
        AM_Image; % the membrane and dapi A side image itself
        BD_Image; % the dapi B side image itself
        BM_Image; % the membrane and dapi B side image itself
        %
        axes_ids = {'AD', 'AM', 'BD', 'BM'}; % vector of the axis ids
        %
        axes_position = [0 0 343 344];
        %% -----------------------------------------------
        % variables read from input images and for table computation
        image_1_filename; % the image 1 file name
        image_2_filename; % image 1 file name
        image_info_1 % the image info for image 1
        image_info_2 % the image info for image 2
        im_image_1; % original stored image 1
        im_image_2; % original stored image 2
        init_tables; % for table computation
        data_directory; % directory with image 1 and image 2 subfolders in 
        % it
        %% -------------------------------------------------
        % app handles
        load_handle; % handle for load image button
        i_handle; % handle for settings window button 
        FV_handle; % handle for full image view button
        %% -------------------------------------------------
        % current cell tracking variables
        current_cell_number; % current cell count number in the 
        % overlap table for analysis
        current_cell_table_index; % cell index or row number for the 
        % current cell
        cell_object; % the cell object created when drawing a given cell
        brd_ii; % linear indicies of the current cell
        total_cells_to_check % max count value for progress tracking
        %% -------------------------------------------------
        % highly overlapping cells settings
        pct_overlapping_cells_to_test = .2 % fraction of (check_cell == 2) 
        % allowed to be differing
        n_overlapping_cells_to_test; % number of cells to test for 
        % validation of the criteria
        %% -------------------------------------------------
        % UI button settings
        skip_to_full_view = 0; % skip to the full image view and skip
        % cleaning up overlapping cells
        %% -------------------------------------------------
        % static segmentation A and B masks
        im_image_2_seg_brds; % image 2 original segmentation mask
        im_image_1_seg_brds; % image 1 original segmentation mask
        %% -----------------------------------------------
        % button colors
        button_options = struct(...
            'on', [100/256 200/256, 100/256], ... %[ 8/255, 199/255, 42/255],...
            'off', [.08 .08 .08], ...
            'off_light', [.90 .90 .90]) 
        %% -----------------------------------------------
        % for image output
        output_image_props = struct(...
            'Photometric', Tiff.Photometric.MinIsBlack, ...
            'BitsPerSample',16, ...
            'SamplesPerPixel', 1, ...
            'SampleFormat', Tiff.SampleFormat.UInt, ...
            'RowsPerStrip', 41, ...
            'MaxSampleValue', 256, ...
            'MinSampleValue', 0, ...
            'PlanarConfiguration', Tiff.PlanarConfiguration.Chunky, ...
            'Software', 'MATLAB', ...
            'ResolutionUnit', Tiff.ResolutionUnit.Inch, ...
            'XResolution', 300, ...
            'YResolution', 300, ...
            'Compression', Tiff.Compression.LZW)
        %
        output_file_props; % csv and tif output file names
        
    end
    %
    methods (Access = private)
        %
        function update_image(app, input_image_filename)
            %
            parse_image_filename(app, input_image_filename)
            if handle_loading_errors(app); return; end
            %
            get_image_meta_data(app)
            if handle_loading_errors(app); return; end
            %
            read_images(app)
            if handle_loading_errors(app); return; end
            %
            get_cell_table(app)
            if handle_loading_errors(app); return; end
            %
            update_image_clean_up(app)
            %
            enable_default_handles(app)
            %
            change_current_cell(app, 'NextCell')       
            %
        end
        %
        function quit = handle_loading_errors(app)
            %
            if app.error ~= 0
                %
                image_filename_error_dictionary(app)
                image_read_error_dictionary(app)
                table_load_error_dictionary(app)
                %
                uialert(app.SegToolUI, app.error_message, app.error_title);
                %
                app.error = 0;
                quit = true;
                enable_default_handles(app)
                %
            else
                quit = false;
            end
            %
        end
        %
        function image_filename_error_dictionary(app)
            %
            switch app.error
                case {1, 4}
                   app.error_message = ['Image not loaded: ',...
                        'input path must contain either ',...
                        '"', app.image_1_type, '" or "',...
                        app.image_2_type,'".'];
                    app.error_title = "Image Path Error";
                case 2
                    app.error_message = ['Image not loaded: ',....
                        app.image_1_type, ' path: ', ...
                        app.image_1_filename,' does not exist. ',...
                        'Check file structure.'];
                    app.error_title = "Image Path Error";
                case 3
                    app.error_message = ['Image not loaded: ',....
                        app.image_1_type, ' path: ', ...
                        app.image_1_filename,' does not exist. ',...
                        'Check file structure.'];
                    app.error_title = "Image Path Error";
            end
            %
        end
        %
        function image_read_error_dictionary(app)
            %
            switch app.error
                case 5
                    app.error_message = [app.image_1_type, ...
                        ' Image Error: could not load metadata. ', ...
                        'check path ', app.image_1_filename,'.'];
                    app.error_title = "Loading Image Error";
                case 6
                    app.error_message = [app.image_2_type, ...
                        ' Image Error: could not load metadata. ', ...
                        'check path ', app.image_2_filename,'.'];
                    app.error_title = "Loading Image Error";
                case 7
                    app.error_message = [app.image_1_type, ...
                        ' Image Error: could not load image. ', ...
                        'check path ', app.image_1_filename,...
                        ' and number of image layers in the (i) menu.'];
                    app.error_title = "Loading Image Error";
                case 8
                    app.error_message = [app.image_2_type, ...
                        ' Image Error: could not load image. ', ...
                        'check path ', app.image_2_filename,...
                        ' and number of image layers in the (i) menu.'];
                    app.error_title = "Loading Image Error";
                case 9
                    app.error_message = [...
                        'Color Image Error: check path ', ...
                        app.color_image_filename,'.'];
                    app.error_title = "Loading Image Error";
            end
            %
        end
        %
        function table_load_error_dictionary(app)
            %
            switch app.error
                case 10
                    app.error_message = ['error loading previous ',...
                        'results: please try again.'];
                    app.error_title = "Loading Input Error";
                case 11
                    app.error_message = ['error computing ',...
                        'overlaps: please try again.'];
                    app.error_title = "Computing Overlaps Error";
            end
            %
        end
        %
        function parse_image_filename(app, input_image_filename)
            %%
            % this function parses the input image filename to get both the
            % input images
            %%
            %
            % get the image_id by removing the input 'identifier'
            %
            [input_image_path, input_image_name, ~] = ...
                fileparts(input_image_filename);
            app.image_id = extractBefore(...
                input_image_name, app.image_identifier_parse);
            %
            % if there is no image id the file parts are not correct
            %
            if isempty(app.image_id)
                app.error = 1;
                return
            end
            %
            % output filename
            %
            output_fname = [app.image_id, app.seg_tool_identifier, ...
                app.seg_tool_identifier_end];
            %
            % get the secondary file path given a provided input
            %
            get_secondary_file_path(...
                app, input_image_path, input_image_filename)
            %
            if app.error
                return
            end
            %
            % get the image that has the multiplex data in it
            %
            app.color_image_filename = fullfile(...
                app.data_directory, app.image_1_type, app.image_folder,...
                [app.image_id, app.color_image_identifier]);
            %
            if ~isfile(app.color_image_filename)
                app.color_image_filename = app.image_1_filename;
            end
            %
            app.output_file_props.csv = fullfile(app.data_directory, ...
                app.image_1_type, app.image_folder, ...
                [output_fname, '.csv']);
            app.output_file_props.tif = fullfile(app.data_directory, ...
                app.image_1_type, app.image_folder, ...
                [output_fname, '.tif']);
            %
        end
        %
        function get_secondary_file_path(...
                app, input_image_path, input_image_filename)
            %%
            % when a user selects an image for input this function allows 
            % us to build the path to the other image
            %%
            if contains(input_image_filename, app.image_1_type)
                %
                image_a_type = 'image_1_type';
                image_b_type = 'image_2_type';
                %
                image_a_filename = 'image_1_filename';
                image_b_filename = 'image_2_filename';
                %
                app.error = 2;
                %
            elseif contains(input_image_filename, app.image_2_type)
                %
                image_a_type = 'image_2_type';
                image_b_type = 'image_1_type';
                %
                image_a_filename = 'image_2_filename';
                image_b_filename = 'image_1_filename';
                %
                app.error = 3;
                %
            else 
                %
                % input does not match either 'types'
                %
                app.error = 4;
                return
            end
            %
            app.data_directory = extractBefore(...
                    input_image_path, fullfile(...
                    app.(image_a_type), app.image_folder));
            image_path_b = replace(...
                input_image_path, app.(image_a_type), app.(image_b_type));
            app.(image_a_filename) = input_image_filename;
            %
            image_files_b = dir(...
                fullfile(image_path_b, [app.image_id, app.image_spec]));
            image_file_cell = {image_files_b(:).name};
            %
            for i1 = 1:length(image_file_cell)
                if (isempty(regexp(...
                        image_files_b(i1).name, ...
                        app.seg_tool_identifier, 'ONCE')))
                    image_name_b = image_files_b(i1).name;
                end
            end
            %
            app.(image_b_filename) = fullfile(image_path_b, image_name_b);
            %
            if isfile(app.(image_b_filename))
                app.error = 0;
            end
            %
        end
        %
        function get_image_meta_data(app)
            %%
            % get the image meta data 
            %%
            app.update_pgbar(app.pgbar, .15, 'Getting image file metadata')
            %
            % get the inform image number of layers and dims
            %
            try
                app.image_info_1 = imfinfo(app.image_1_filename);
            catch
                app.error = 5;
            end
            %
            try
                app.image_info_2 = imfinfo(app.image_2_filename);
            catch
                app.error = 6;
            end
            %
            app.nRows = app.image_info_1(1).Height;
            app.nCols = app.image_info_1(1).Width;
            app.template_image = zeros(app.nRows, app.nCols, 'uint16');
            %
            % set image output properties
            %
            app.output_image_props.ImageLength = app.nRows;
            app.output_image_props.ImageWidth = app.nCols;
            %
        end
        %
        function read_images(app)
            %%
            % this function reads in the images 
            %%
            for image_type_count = 1:2
                %
                app.update_pgbar(app.pgbar, (.20 * image_type_count), ...
                    ['Reading ', app.(['image_', num2str(1), '_type']), ...
                    ' image layers into memory.'])
                %
                try
                    %
                    read_segmentation_masks(app, num2str(image_type_count))
                    %
                catch
                    app.error = 6 + image_type_count;
                    return
                end
            end
            %
            % read the image for visualization
            %
            app.update_pgbar(app.pgbar, .45, ...
                'Reading image for color display')
            %
            try
                ic = 0;
                app.input_color_vectors = ...
                    zeros(app.nRows * app.nCols, 2);
                for i1 = app.image_1_color_layers
                    im = imread(app.color_image_filename, i1);
                    im = im ./ max(im,[],'all');
                    ic = ic + 1;
                    app.input_color_vectors(:, ic) = ...
                        reshape(im, [], 1);
                end
                %
            catch
                app.error = 9;
            end
            %
        end
        %
        function read_segmentation_masks(app, image_type)
            %
            % determine which layers to read
            %
            image_layers_to_read = app.(['image_', image_type,...
                '_layers_', app.boundary_type]);
            %
            image_input_layers = length(app.(['image_info_', image_type]));
            %
            image_layers_to_read_n = length(image_layers_to_read);
            %
            if image_input_layers == 1
                im = imread(app.(['image_', image_type, '_filename']));
            end
            %
            % read the images
            %
            if image_layers_to_read_n ~= 1
                %
                image_layers_to_read_n = length(...
                    image_layers_to_read(1):image_layers_to_read(2));
                ic = 0;
                im_out = repmat(app.template_image, ...
                    [1 1 image_layers_to_read_n]);
                %
                for i1 = image_layers_to_read(1):image_layers_to_read(2)
                    ic = ic + 1;
                    if image_input_layers ~= 1
                        im_out(:,:,ic) = imread(...
                            app.(['image_', image_type, '_filename']), i1);
                    else
                        im_out(:,:,ic) = im(:, :, i1);
                    end
                end
                %
            else
                %
                if image_input_layers ~= 1
                    im_out = imread(...
                        app.(['image_', image_type, '_filename']), ...
                        image_layers_to_read);
                else
                    im_out = im(:, :, image_layers_to_read);
                end
                %
            end
            %
            if ~strcmp(app.(['image_', image_type, ...
                    '_input_boundary_type']), 'mask')
                im_out = convert_labels_to_mask(app, im_out);
            end
            %
            % create segmenation boundary image
            %
            im_seg_boundaries = app.template_image;
            %
            for i1 = 1:image_layers_to_read_n
                brd = (imdilate(im_out(:,:,i1), ...
                    ones(3,3)) - im_out(:,:,i1)) > 0;
                im_seg_boundaries(brd) = app.segmentation_color;
            end
            %
            % assign final images
            %
            app.(['im_image_', image_type]) = im_out;
            app.(['im_image_', image_type, '_seg_brds']) = ...
                im_seg_boundaries;
            %
        end
        %
        function mask_label_matrix = convert_labels_to_mask(app, image)
            %
            %
            % get image dimensions
            %
            image_size = size(image);
            %
            mask_label_matrix = zeros(image_size);
            %
            if length(image_size) > 2
                n_image_layers = image_size(3);
            else
                n_image_layers = 1;
            end
            %
            [~, edge_im] = image_edge_indicies(app, 2);
            %
            for image_layer_id = 1:n_image_layers
                %
                % get cells
                %
                image_layer = image(:, :, image_layer_id);
                edge_cells = unique(double(image_layer) .* edge_im);
                edge_cells = edge_cells(edge_cells > 0);
                %
                max_cell_id = max(image_layer, [], 'all');
                %
                pixel_index_list = label2idx(image_layer);   
                pixel_index_list(cellfun(@(x) isempty(x), ...
                    pixel_index_list)) = {0};
                %
                % fill the cells and get them in the proper format
                %
                cells = organize_cells(...
                    app, image_size, max_cell_id, pixel_index_list, edge_cells);
                mask_label_matrix(:, :, image_layer_id) = ...
                    create_label_matrix(app, cells, 1, []);
                %
            end
            %
        end
        %    
        function obj = organize_cells(...
                app, image_size, max_cell_id, pixel_index_list, edge_cells)
            %% ------------------------------------------------------------
            %% Created by: Benjamin Green - Johns Hopkins - 01/03/2019
            %% ------------------------------------------------------------
            %% Description
            % close the cells on the edge of the tissue and organize the 
            % numbers inside the object cell to a circle so that the inside
            % of the cell can be filled
            %% ------------------------------------------------------------
            %
            image_size_cell = repmat({image_size}, 1, max_cell_id);
            [y, x] = cellfun(@ind2sub, image_size_cell, ...
                pixel_index_list, 'Uni', 0);
            %
            [x(edge_cells), y(edge_cells)] = cellfun(@(x,y) ...
                fill_edges(app, [x, y], image_size), ...
                x(1, edge_cells), y(1, edge_cells), 'Uni', 0);
            %
            % locate cells on edge of tissue & fill edges of cells on 
            % edge of image
            %
            X = cellfun(@min, x);
            Y = cellfun(@min, y);
            Y2 = cellfun(@max, y);
            X2 = cellfun(@max, x);
            %
            % find midpoints of the cell objects
            %{
            mx = cellfun(@mean, x);
            my = cellfun(@mean, y);
            %
            % get the angle of each point from the midpoint
            %
            ang = cellfun(@(x, y, mx, my)atan2(y - my, x - mx),...
                x, y, num2cell(mx), num2cell(my), 'Uni', 0);
            %
            % sorting the angles from the midpoint we can get indicies 
            % for a circle
            %
            [~, o] = cellfun(@sort, ang, 'Uni', 0);
            %
            % order the cells in the circle
            %
            x = cellfun(@(x, o)x(o), x, o, 'Uni', 0);
            y = cellfun(@(y, o)y(o), y, o, 'Uni', 0);
            %
            % put the cells in reference coordinate rectangles
            %}
            x1 = cellfun(@(x, y)x - y, x, num2cell(X), 'Uni', 0);
            y1 = cellfun(@(x, y)x - y, y, num2cell(Y), 'Uni', 0);
            %
            % fill objects
            %
            obj = arrayfun(@(X, X2, Y, Y2) zeros(Y2 - Y + 1, ...
                X2 - X + 1), X, X2, Y, Y2, 'Uni', 0);
            obj = cellfun(@(x, y, z) app.fill_objects(x, y, z), ...
                obj, y1, x1, 'Uni', 0);
            [objy, objx] = cellfun(@(x)find(x == 1), obj, 'Uni', 0);
            %
            % convert back to orginal image coordinates
            %
            objy = cellfun(@(x, y)x + y - 1, objy, num2cell(Y), 'Uni', 0);
            objx = cellfun(@(x, y)x + y - 1, objx, num2cell(X), 'Uni', 0);
            obj = cell(1, length(objy));
            %
            for i2 = 1:length(objy)
                if length(objy{i2}) > 1
                    obj{i2} = sub2ind(image_size, objy{i2}, objx{i2});
                end
            end
            %
            % if the cell is very small then the obj is written as a 
            % column vector instead of a row vector to fix this find 
            % those cells
            %
            [~, r2] = cellfun(@size, obj, 'Uni', 0);
            r2 = find(cat(1, r2{:}) ~= 1);
            %
            % check if any of these cells exist
            %
            if ~isempty(r2)
                %
                % correct them to proper dimensions
                %
                for i4 = 1:length(r2)
                    obj{r2(i4)} =  obj{r2(i4)}';
                end
            end
            %
        end
        %
        function [x, y] = fill_edges(app, b, image_size)
            %% ------------------------------------------------------------
            %% Created by: Benjamin Green - Johns Hopkins - 01/03/2019
            %% ------------------------------------------------------------
            %% Description
            % this function takes in a cell object bon the edge of the
            % image and fills in the boundaries using T,B,L,R to define
            % the edges as Top, Bottom, Left, Right
            %% ------------------------------------------------------------
            w = 1;
            tedge = [];
            %
            % get a vector that defines if the cell is on the top or
            % bottom
            % of the image
            %
            %
            % convert linear index to x,y
            %
            min_edge = [1, 2]; bottom = image_size(1);
            right = [image_size(2), image_size(2)-1];
            %
            rows = ismember(b(:,2), [min(b(:,2)), max(b(:,2))]);
            %
            [w, tedge] = app.find_open_edges(...
                b, rows, w, tedge, [1,4,6], [7,2], [3,5,8]);
            %
            % get a vector that defines if the cell is on the top or
            % bottom of the image
            %
            rows = ismember(b(:,1), [min(b(:,1)), max(b(:,1))]);
            %
            [~, tedge] = app.find_open_edges(...
                b, rows, w, tedge, [6,7,8], [4,5], [1,2,3]);
            %
            tedge = unique(tedge, 'rows');
            %
            % close the cell if it touches the edge
            %
            ns1 = []; ns2=[];
            %
            if ~isempty(tedge) && length(tedge(:,1)) >= 2
                %
                % vectors are [x (col), y (row)]
                % get the rows on the left or right side of the image
                %
                rows = ismember(tedge(:,1), [min_edge, right]);  
                %
                % a series of pixels in the y direction for the length of pixels that 
                % touched the edge
                %
                m1 = min(tedge(:, 2)) + 1;
                m2 = max(tedge(:, 2)) - 1;
                ns1(:, 2) = m1:m2;
                %
                % a series of pixels in the x direction for the length of pixels that 
                % touched the edge
                %
                m1 = min(tedge(:, 1)) + 1;
                m2 = max(tedge(:, 1)) - 1;
                ns2(:, 1) = m1:m2;
                %
                if sum(rows) == 0 
                    %
                    % edge is on the bottom or top, append the x direction 
                    % values with one of the edge pixels (since we allow
                    %  for a 2 px buffer we need to check which edge we are
                    %  on
                    %
                    if any(ismember(tedge(:, 2), min_edge))
                        ns2(:, 2) = min_edge(1);
                    else
                        ns2(:, 2) = bottom;
                    end
                    %
                    ns = ns2;
                    %
                elseif all(rows) % edge is on the left or right
                    %
                    if any(ismember(tedge(:, 1), min_edge))
                        ns1(:, 1) = min_edge(1);
                    else
                        ns1(:, 1) = right(1);
                    end
                    %
                    ns = ns1;
                    %
                else
                    %
                    if any(ismember(tedge(:, 1), min_edge))
                        ns1(:, 1) = min_edge(1);
                    else
                        ns1(:, 1) = right(1);
                    end
                    %
                    if any(ismember(tedge(:, 2), min_edge))
                        ns2(:, 2) = min_edge(1);
                    else
                        ns2(:, 2) = bottom;
                    end
                    %
                    ns = vertcat(ns1, ns2);
                end
                %
                b = vertcat(b, ns);
                %
            end
            %
            x = b(:, 1);
            y = b(:, 2);
            %
        end
        %
        function label_matrix = create_label_matrix(...
                app, cells, levels, ids)
            %%
            % create a label matrix L from the cells in a pixel index list
            % and optionally provide unique ids
            %
            %%
            %
            label_matrix = repmat(app.template_image, [1 1 levels]);
            %
            for i1 = 1:length(cells)
                %
                idx = cells{i1};
                %
                if length(idx) == 1
                    continue
                end
                %
                if ~isempty(ids)
                    label_matrix(idx) = ids(i1);
                else
                    label_matrix(idx) = i1;
                end
                %
            end
            %
        end
        %
        function get_cell_table(app)
            %%
            % read in or create the table, then perform some minor cleanup
            %%
            app.update_pgbar(app.pgbar, .60, ...
                'Checking for previous comparison files.')
            %
            if exist(app.output_file_props.csv,'file')
                try
                    reload_overlap_table(app)
                catch
                    app.error = 10;
                    return
                end
            else
                try
                    compute_pairs(app)
                catch
                    app.error = 11;
                    return
                end
            end
            %
            define_accepted_overlap_test_set(app)
            %
            remove_artifacts(app)
            %
            app.total_cells_to_check = num2str( ...
                    max(app.overlap_table.counter));
            %
        end
        %
        function compute_pairs(app)
            %%
            % this is where the tables are computed for cells and cell 
            % overlap pct is assigned
            %%
            app.update_pgbar(app.pgbar, .61,...
                ['Previous comparison files not ',...
                'found ... computing new comparison results'])
            %
            app.update_pgbar(app.pgbar, .62,...
                ['Computing new comparison results ...',...
                ' finding cell objects'])
            %
            % cell table for image 2
            %
            im = app.im_image_2(:,:,1);
            app.init_tables.cells_n = label2idx(im)';
            m1 = regionprops(im,'centroid');
            app.init_tables.tmp_mid = struct2cell(m1);
            create_initial_cell_table(app, app.image_2_abbreviation);
            %
            % cell table for image 1
            %
            get_image_1_distinct_cells(app);
            create_initial_cell_table(app, app.image_1_abbreviation);
            %
            app.update_pgbar(app.pgbar, .70,...
                ['Computing new comparison results ...',...
                ' computing overlaps'])
            compute_overlap(app)
            %
            app.update_pgbar(app.pgbar, .80,...
                ['Computing new comparison results ...',...
                ' finding non overlapping cells'])
            add_non_overlapping_cells(app);
            %
            app.update_pgbar(app.pgbar, .80,...
                ['Computing new comparison results ...',...
                ' cleaning up and saving intial table'])
            remove_edge_cells(app, 1)
            remove_edge_cells(app, 2)
            %
            % intialize the final results columns and for 80% or above 
            % overlap save image_2 algorithm segmentation
            %
            initialize_results_columns(app)
            %
            % save results
            %
            x = app.init_tables.tmp_tbl;
            %
            x.counter = zeros(height(x),1);
            ii = x.cell_check ~= app.cell_check_ids.dont_review;
            x.counter(ii) = (1:length(find(ii)))';
            %
            app.overlap_table = x;
            save_label_matrix(app)
            save_cell_table(app)
            %
        end
        %
        function create_initial_cell_table(app, tt)
            %%
            % intialize the table for the overlap computation
            %%
            cells_n1 = app.init_tables.cells_n;
            m1 = app.init_tables.tmp_mid';
            m1(cellfun(@isempty, m1)) = {[0,0]};
            m1 = cell2mat(m1);
            %
            tbl = table();
            tbl.([tt,'_cellid']) = (1:length(cells_n1))';
            tbl.([tt,'_cells']) = cells_n1;
            tbl.([tt,'_X_centroid']) = m1(:,1);
            tbl.([tt,'_Y_centroid']) = m1(:,2);
            tbl.([tt,'_n_pixels']) = cellfun('length',cells_n1);
            %
            if strcmp(tt, app.image_2_abbreviation)
                app.init_tables.image_2_table = tbl;
            elseif strcmp(tt, app.image_1_abbreviation)
                app.init_tables.image_1_table = tbl;
            end
            %
        end
        %
        function get_image_1_distinct_cells(app)
            %%
            % get the distinct cell objects from both inform cell
            %  segmentation layers
            %%
            %
            % get distinct objects in inform
            %
            if length(size(app.im_image_1)) == 2
                %
                im = app.im_image_1(:,:,1);
                image_1_cells_1 = label2idx(im);
                m1 = regionprops(im,'centroid');
                m1 = struct2cell(m1);
                %
                ii = ~cellfun(@isempty,image_1_cells_1);
                image_1_cellso = image_1_cells_1(ii);
                mo = m1(ii);
                app.init_tables.cells_n = image_1_cellso';
                app.init_tables.tmp_mid = mo;
                %
            else 
                %
                im = app.im_image_1(:,:,1);
                image_1_cells_1 = label2idx(im);
                m1 = regionprops(im,'centroid');
                m1 = struct2cell(m1);
                %
                im = app.im_image_1(:,:,2);
                image_1_cells_2 = label2idx(im);
                m2 = regionprops(im,'centroid');
                m2 = struct2cell(m2);
                %
                if length(image_1_cells_1) > length(image_1_cells_2)
                    nn = length(image_1_cells_1);
                else
                    nn = length(image_1_cells_2);
                end
                %
                image_1_cellso = cell(nn, 1);
                mo = cell(1,nn);
                %
                ii = ~cellfun(@isempty,image_1_cells_1);
                image_1_cellso(ii) = image_1_cells_1(ii);
                mo(ii) = m1(ii);
                %
                ii = ~cellfun(@isempty,image_1_cells_2);
                image_1_cellso(ii) = image_1_cells_2(ii);
                mo(ii) = m2(ii);
                %
                
                %
                app.init_tables.cells_n = image_1_cellso;
                app.init_tables.tmp_mid = mo;
                %
            end
        end
        %
        function compute_overlap(app)
            %%
            % This function computes the overlaps and adds them to the 
            % appropriate rows in the table
            %%
            ss = size(app.im_image_1);
            if length(ss) == 3
                cnt = ss(3);
            else 
                cnt = 1;
            end
            o = [];
            for level = 1:cnt
                im1 = app.im_image_1(:,:,level);
                overlap = im1 > 0 & app.im_image_2 > 0;
                pairs = [im1(overlap), app.im_image_2(overlap)];
                [ii, ~, kk] = unique(pairs, 'rows');
                pairs_cnt = [ii,accumarray(kk,1)];
                %
                idx1 = pairs_cnt(:,1);
                idx2 = pairs_cnt(:,2);
                %
                crt_ovr_tbl(app, idx1, pairs_cnt(:,2), pairs_cnt(:,3), ...
                    app.image_1_abbreviation);
                %
                crt_ovr_tbl(app, idx2, pairs_cnt(:,1), pairs_cnt(:,3), ...
                    app.image_2_abbreviation);
                %
                tbl = outerjoin(app.init_tables.o_tbl_image_1, ...
                    app.init_tables.o_tbl_image_2, 'MergeKeys',1);
                %
                tbl.([app.image_1_abbreviation, '_level']) = ...
                    repmat(level, height(tbl),1);
                o = [o;tbl]; %#ok<AGROW> 
            end
            %
            o.joint_overlap = o.([app.image_1_abbreviation, '_frac']) .*...
                o.([app.image_2_abbreviation, '_frac']);
            %
            % find cells with jo < 10% and remove them from the table
            % these cells do not actually overlap well and are probably
            % just beside one another
            %
            ii = o.joint_overlap < .1;
            o(ii,:) = [];
            %
            app.init_tables.tmp_tbl = o;
            %
        end
        %
        function crt_ovr_tbl(app,idx,pairs,cnt,tt)
            %%
            % define the table with the overlaps in it
            %
            %%
            if strcmp(tt, app.image_2_abbreviation)
                tbl_in = app.init_tables.image_2_table;
            elseif strcmp(tt, app.image_1_abbreviation)
                tbl_in = app.init_tables.image_1_table;
            end
            %
            tbl = tbl_in(idx,:);
            tbl.([tt,'_overlap']) =  cnt;
            tbl.([tt,'_frac']) = double(tbl.([tt,'_overlap']))...
                ./ tbl.([tt,'_n_pixels']);
            tbl.([tt,'_paired_w']) = pairs;
            tbl.pairid = (1:height(tbl))';
            %
            if strcmp(tt, app.image_2_abbreviation)
                app.init_tables.o_tbl_image_2 = tbl;
            elseif strcmp(tt, app.image_1_abbreviation)
                app.init_tables.o_tbl_image_1 = tbl;
            end
            %
        end
        %
        function add_non_overlapping_cells(app)
            %
            tbl = app.init_tables.tmp_tbl;
            colnames = tbl.Properties.VariableNames;
            %
            z = height(tbl);
            %
            app.init_tables.image_1_table.(...
                [app.image_1_abbreviation, '_level']) = ...
                ones(height(app.init_tables.image_1_table), 1);
            im1 = app.im_image_1(:,:,2);
            im1 = unique(im1);
            ii = ismember(app.init_tables.image_1_table.(...
                [app.image_1_abbreviation, '_cellid']), im1);
            app.init_tables.image_1_table.(...
                [app.image_1_abbreviation, '_level'])(ii) = ...
                repmat(2, sum(ii), 1);
            %
            image_1_tableo = app.init_tables.image_1_table;
            idx1 = unique(image_1_tableo.(...
                [app.image_1_abbreviation, '_cellid']));
            idx2 = unique(tbl.([app.image_1_abbreviation, '_level']));
            ii = ismember(idx1, idx2);
            %
            no_o_tbl(app, ii, image_1_tableo, colnames, z);
            %
            z = height(app.init_tables.tmp_tbl);
            app.init_tables.image_2_table.(...
                [app.image_1_abbreviation, '_level']) = ...
                ones(height(app.init_tables.image_2_table), 1);
            image_2_tableo = app.init_tables.image_2_table;
            idx1 = unique(image_2_tableo.(...
                [app.image_2_abbreviation, '_cellid']));
            idx2 = unique(tbl.([app.image_2_abbreviation, '_cellid']));
            ii = ismember(idx1, idx2);
            %
            no_o_tbl(app, ii, image_2_tableo, colnames, z);
            %
        end
        %
        function no_o_tbl(app, idx, tbl_in, colnames, z)
            %%
            % create the table of the size of the overlap table for cells
            % without overlaps
            %%
            no_tbl = tbl_in;
            no_tbl(idx,:) = [];
            no_tbl.pairid = (z + 1:z + height(no_tbl))';
            colnames_no = no_tbl.Properties.VariableNames;
            ii = ~ismember(colnames, colnames_no);
            tbl = array2table(zeros(height(no_tbl),sum(ii)));
            tbl = [no_tbl, tbl];
            tbl.Properties.VariableNames = [colnames_no,colnames(ii)];
            %
            titles = {app.image_1_abbreviation,app.image_2_abbreviation};
            for i1 = 1:2
                if ~ismember([titles{i1},'_cells'], colnames_no)
                    tbl.([titles{i1},'_cells']) = num2cell(...
                        tbl.([titles{i1},'_cells']));
                end
            end
            %
            app.init_tables.tmp_tbl = [app.init_tables.tmp_tbl; tbl];
            %
        end
        %
        function remove_edge_cells(app, opt)
            %%
            % this function removes the cells that touch the edges of the 
            % images from the training set
            %%
            x = app.init_tables.tmp_tbl;
            %
            [edge_idxs, ~] = image_edge_indicies(app);
            %
            % find where either the machine learning or the inform 
            % algorithm defines cells with any pixels touching the edge of 
            % the image
            %
            if opt == 1
                image_2_edges_p = cellfun(@(y) sum(ismember(y, edge_idxs)), ...
                    x.([app.image_2_abbreviation, '_cells']));
                image_1_edges_p = cellfun(@(y) sum(ismember(y, edge_idxs)), ...
                    x.([app.image_1_abbreviation, '_cells']));
                edges_p = image_2_edges_p + image_1_edges_p;
                %
                % remove those rows from the table
                %
                idx = edges_p > 0;
                x(idx,:) = [];
                app.init_tables.tmp_tbl = x;
                %
            elseif opt == 3
                %
                x = app.overlap_table;
                %
                image_2_edges_p = cellfun(@(y) sum(ismember(y, edge_idxs)), ...
                    x.([app.image_2_abbreviation, '_cells']));
                image_1_edges_p = cellfun(@(y) sum(ismember(y, edge_idxs)), ...
                    x.([app.image_1_abbreviation, '_cells']));
                draw_edges_p = cellfun(@(y) sum(ismember(y, edge_idxs)), ...
                    app.paired_cells);
                edges_p = image_2_edges_p + image_1_edges_p + draw_edges_p;
                %
                % remove those rows from the table
                %
                idx = edges_p > 0;
                x.class_selection(idx) = app.class_ids.Trash;
                app.paired_cells(idx) = {0};
                app.draw_im(ismember(app.draw_im, find(idx))) = 0;
                app.overlap_table = x;
                %
            elseif opt == 2
                %
                combined_seg = cat(3, app.im_image_2, app.im_image_1);
                [~, edge_im] = image_edge_indicies(app, 5);
                %
                ss = size(combined_seg);
                cnt_2 = ss(3);
                %
                idx_cells_on_edges = [];
                idx_cells_on_edges_seg = [];
                %
                for idx = 1:cnt_2
                    im = combined_seg(:,:,idx);
                    cells_on_edges = unique(im(edge_idxs));
                    cells_on_edges = cells_on_edges(cells_on_edges > 0);
                    new_idxs = find(ismember(im, cells_on_edges));
                    idx_cells_on_edges_seg = [idx_cells_on_edges_seg; new_idxs];
                    new_idxs = find(ismember(im, cells_on_edges) .* edge_im);
                    idx_cells_on_edges = [idx_cells_on_edges; new_idxs];
                end
                %
                % remove those pixels from the im_mem_dapi and segmentation
                % images 
                %
                %app.input_color_vectors(idx_cells_on_edges, :) = ...
                %    app.input_color_vectors(idx_cells_on_edges, :) * .75;
                %
                for id = ['1', '2']
                    im_2 = reshape(app.(['im_image_', id]), ...
                        app.nRows * app.nCols, []);
                    im_2(idx_cells_on_edges_seg, :) = 0;
                    app.(['im_image_', id]) = reshape(...
                        im_2, app.nRows, app.nCols, []);
                end
                %
                app.input_color_vectors = reshape(...
                    app.input_color_vectors, app.nRows, app.nCols, 2);
                %
            end
            %
        end
        %
        function [ii, im_e] = image_edge_indicies(app, edge_size)
            %%
            % get the indicies of the image edge
            %%
            if nargin == 1
                edge_size = 1;
            end
            %
            im_e = zeros(app.nRows - 2 * edge_size,...
                app.nCols - 2 * edge_size);
            im_e = padarray(im_e, [edge_size edge_size], 1, 'both');
            ii = find(im_e);
            %
        end
        %
        function initialize_results_columns(app)
            %%
            % this function intializes the results columns and defines 
            % the cells that overlap 80% above as the machine learning 
            % algorithm cell but defines the cell include parameter as 
            % 4 instead of 2
            %
            %%
            x = app.init_tables.tmp_tbl;
            %
            % remove empty cells
            %
            ii = isnan(x.([app.image_2_abbreviation,'_X_centroid']));
            x(ii,:) = [];
            ii = isnan(x.([app.image_1_abbreviation,'_X_centroid']));
            x(ii,:) = [];
            %
            % redefine the cellid
            %
            x.pairid = (1:height(x))';
            %
            % results columns
            %
            x.class_selection = zeros(height(x),1);
            x.cell_check = zeros(height(x),1);
            x.flags = zeros(height(x),1);
            %
            % settings for the 80% overlap cells; look over 20% of these
            %
            ii = x.joint_overlap >= app.same_cell_overlap_percent;
            % look over all of these
            x.cell_check(~ii) = app.cell_check_ids.review; 
            % do not look over 
            x.class_selection(ii) = app.class_ids.auto_reviewed; 
            %
            %  randomly sample cells not to look over 
            %
            ii = find(ii);
            n_overlaps = length(ii);
            r2 = (1:n_overlaps)';
            r = randsample(r2, floor((1 - ...
                app.pct_overlapping_cells_to_test) * n_overlaps));
            %
            % store the cell objects, these are in a row index vector
            % according to the pairid
            %
            app.paired_cells = cell(height(x), 1);
            app.paired_cells(ii(r)) = ...
                x.([app.image_2_abbreviation,'_cells'])(ii(r));
            %
            r3 = ~ismember(r2, r);
            x.cell_check(ii(r3)) = app.cell_check_ids.overlap_test_set; 
            % to check 
            x.class_selection(ii(r3)) = app.class_ids.not_reviewed;
            %
            x.ground_level = ones(height(x), 1);
            %
            x = x(:,{[app.image_1_abbreviation,'_cellid'], ...
                [app.image_1_abbreviation,'_X_centroid'], ...
                [app.image_1_abbreviation,'_Y_centroid'], ...
                [app.image_1_abbreviation,'_paired_w'], ...
                [app.image_2_abbreviation,'_cellid'], ...
                [app.image_2_abbreviation,'_X_centroid'], ...
                [app.image_2_abbreviation,'_Y_centroid'], ...
                [app.image_2_abbreviation,'_paired_w'], 'pairid', ...
                [app.image_1_abbreviation,'_level'], ...
                'joint_overlap','class_selection','cell_check',...
                'flags','ground_level', ...
                [app.image_1_abbreviation,'_cells'], ...
                [app.image_2_abbreviation,'_cells']});
            x.Properties.VariableNames = ...
                {[app.image_1_abbreviation,'_cellid'], ...
                [app.image_1_abbreviation,'_X_centroid'], ...
                [app.image_1_abbreviation,'_Y_centroid'], ...
                [app.image_2_abbreviation,'_paired_w_', ...
                    app.image_1_abbreviation], ...
                [app.image_2_abbreviation,'_cellid'], ...
                [app.image_2_abbreviation,'_X_centroid'], ...
                [app.image_2_abbreviation,'_Y_centroid'], ...
                [app.image_1_abbreviation,'_paired_w_', ...
                    app.image_2_abbreviation], 'pairid', ...
                [app.image_1_abbreviation,'_level'], ...
                'joint_overlap','class_selection','cell_check',...
                'flags','ground_level', ...
                [app.image_1_abbreviation,'_cells'], ...
                [app.image_2_abbreviation,'_cells']};
            app.init_tables.tmp_tbl = x;
            %
            app.draw_im = app.template_image;
            %
        end
        %
        function reload_overlap_table(app)
            %%
            % this function reads in the comparison seg data and label 
            % matrix then rearranges to the overlap_table
            %%
            app.pgbar.Value = .61;
            app.pgbar.Message = ['Previous comparison files found ',...
                '... loading into memory'];
            pause(.5)
            %
            % read the old table
            %
            ofname = app.output_file_props.csv;
            x = readtable(ofname);
            %
            ii = x.pairid == 0;
            x(ii, :) = [];
            %
            % get image 2 cell objects
            %
            app.init_tables.cells_n = label2idx(app.im_image_2)';
            m1 = regionprops(app.im_image_2(:,:,1),'centroid');
            app.init_tables.tmp_mid = struct2cell(m1);
            create_initial_cell_table(app, app.image_2_abbreviation);
            app.init_tables.image_2_table = ...
                app.init_tables.image_2_table(:, {...
                [app.image_2_abbreviation, '_cellid'],...
                [app.image_2_abbreviation, '_cells']});
            x = outerjoin(x, app.init_tables.image_2_table,...
                'Type','left','Keys',...
                {[app.image_2_abbreviation, '_cellid']}, 'MergeKeys',1);
            %
            % get image_1 cell objects
            %
            get_image_1_distinct_cells(app);
            create_initial_cell_table(app, app.image_1_abbreviation);
            app.init_tables.image_1_table = ...
                app.init_tables.image_1_table(:, {...
                [app.image_1_abbreviation, '_cellid'], ...
                [app.image_1_abbreviation, '_cells']});
            x = outerjoin(x, app.init_tables.image_1_table,...
                'Type','left','Keys', {[app.image_1_abbreviation, ...
                '_cellid']}, 'MergeKeys', 1);
            x = sortrows(x, 'pairid', 'ascend');
            %
            % remove edge cells from the displays
            %
            app.init_tables.tmp_tbl = x;
            remove_edge_cells(app, 2)
            %
            % add cell counter
            %
            x.counter = zeros(height(x),1);
            %
            app.overlap_table = set_overlap_counter(app, x);
            %
            read_comparison_image(app)
            %
            remove_edge_cells(app, 3)
            %            
        end
        %
        function x = set_overlap_counter(app, x)
            %
            ii1 = x.class_selection ~= app.class_ids.not_reviewed & ...
                x.class_selection ~= app.class_ids.auto_reviewed & ...
                x.cell_check ~= app.cell_check_ids.dont_review;
            %
            x.counter(ii1) = (1:sum(ii1))';
            %
            ii = x.class_selection == app.class_ids.not_reviewed;
            %
            x.counter(ii) = (sum(ii1) + 1: sum(ii1) + sum(ii))';
            %
            %
            app.total_cells_to_check = num2str(max(x.counter));
            %
        end
        %
        function read_comparison_image(app)
            %%
            % this function reads in the image with the drawn cells to 
            % memory
            %%
            %
            % read in drawn cells
            %
            image_info = imfinfo(app.output_file_props.tif);
            %
            switch length(image_info)
                case 6
                    %
                    % convert old version to new version
                    %
                    app.draw_im = app.template_image;
                    x = app.overlap_table;
                    for level = 1:2
                        ii = x.([app.image_1_abbreviation,'_level']) == ...
                            level & x.class_selection == ...
                            app.class_ids.drawn;
                        L = imread(app.output_file_props.tif, level + 4);
                        cells_n1 = x.pairid(ii);
                        c = reshape(L,1,[]);
                        ii = c > 0;
                        app.draw_im(ii) = cells_n1(c(ii));
                        %
                    end
                    %
                case 2
                    %
                    app.draw_im = imread(app.output_file_props.tif, 1);
                    %
                case 1
                    %
                    app.draw_im = imread(app.output_file_props.tif, 1);
                    %
            end
            %
            % get the cell objects from the drawn image
            %
            d_cells_n1 = label2idx(app.draw_im);
            %
            % get the logical index in the table and the pairid of the cell
            % of interest (pairid is the value in the label matrix)
            %
            ii = app.overlap_table.class_selection == app.class_ids.drawn;
            idx = app.overlap_table.pairid(ii);
            %
            ii1 = idx > length(d_cells_n1);
            %
            if any(ii1)
                %
                ii2 = ismember(app.overlap_table.pairid, idx(ii1));
                app.overlap_table.class_selection(ii2) = ...
                    app.class_ids.Trash;
                ii = app.overlap_table.class_selection == ...
                    app.class_ids.drawn;
                idx = app.overlap_table.pairid(ii);
                %
            end
            %
            % pre-allocate final object variables
            %
            app.paired_cells = cell(max(app.overlap_table.pairid), 1);
            %
            % insert the proper values from the drawn cells into the final
            % object variables
            %
            cells_n1 = d_cells_n1(idx);
            app.paired_cells(ii) = cells_n1;
            %
            % get image_1 objects into the final objects variables
            %
            ii = app.overlap_table.class_selection == ...
                app.class_ids.image_1;
            cells_n1 = app.overlap_table.(...
                [app.image_1_abbreviation,'_cells'])(ii);
            app.paired_cells(ii) = cells_n1;
            %
            % get the image_2 objects for the rest in the final object 
            % variables
            %
            ii = (app.overlap_table.class_selection == ...
                app.class_ids.image_2 |...
                app.overlap_table.class_selection == ...
                app.class_ids.auto_reviewed | ...
                app.overlap_table.class_selection == ...
                app.class_ids.AB);
            %
            cells_n1 = app.overlap_table.(...
                [app.image_2_abbreviation,'_cells'])(ii);
            %
            app.paired_cells(ii) = cells_n1;
            %
        end
        %
        function define_accepted_overlap_test_set(app)
            %%
            % compute n differences allowed for the cells that are 'highly'
            % overlapping and to be checked (cell_check = 2) for validating
            % the criteria for automatically accepting segmentations where
            % segmentation of the two types is >80%.
            %%
            ii = app.overlap_table.cell_check == ...
                app.cell_check_ids.overlap_test_set;
            %
            app.n_overlapping_cells_to_test = ...
                app.pct_overlapping_cells_to_test ...
                * length(find(ii));
            %
            x = app.overlap_table;
            ii = x.class_selection == app.class_ids.auto_reviewed;
            %
            if any(ii)
                check_overlapping_cell_test_set(app);
            else
                app.n_overlapping_cells_to_test = -1;
            end
            %
        end
        %
        function check_overlapping_cell_test_set(app)
            %%
            % determine the number of joint overlapped paired cells 
            % over .8, which were sampled to review, that do no have A==B. 
            % If the number of pairs is over the 20% limit then add the 
            % rest of the cells and alert the user.
            %%            
            ii = app.overlap_table.cell_check == ...
                    app.cell_check_ids.overlap_test_set & ...
                app.overlap_table.class_selection ~= ...
                    app.class_ids.AB &...
                app.overlap_table.class_selection ~= ...
                    app.class_ids.not_reviewed;
            %
            if length(find(ii)) > app.n_overlapping_cells_to_test
                %
                x = app.overlap_table;
                ii = x.class_selection == app.class_ids.auto_reviewed;
                vv = num2str(sum(ii));
                %
                msg = ['More than 20% of the sampled "highly ',...
                    'overlaping pairs" have disagreement. ',...
                    'The remaining ', vv,' cells ',...
                    'will now be added to the cell count'];
                 uialert(app.SegToolUI, msg, 'Info','Icon',...
                    'info','CloseFcn','uiresume(gcbf)');
                uiwait(gcbf)
                app.pgbar = uiprogressdlg(app.SegToolUI,'Title',...
                    'Please Wait','Message','Adding new pairs');
                %
                x.class_selection(ii) = app.class_ids.not_reviewed;
                x.cell_check(ii) = app.cell_check_ids.overlap_test_set;
                %
                app.overlap_table = set_overlap_counter(app, x);
                %
                try
                    app.update_pgbar(app.pgbar, 1, 'Finishing')
                    close(app.pgbar);
                catch
                end
                %
                app.n_overlapping_cells_to_test = -1;
                %
            end
            %
        end
        %
        function remove_artifacts(app)
            %%
            % remove any cells that are set to review but not in either
            % segmentations as these are errors
            %%
            ii = cellfun(@isempty, app.overlap_table.( ...
                    [app.image_2_abbreviation, '_cells'])) & ...
                cellfun(@isempty, app.overlap_table.( ...
                    [app.image_1_abbreviation, '_cells'])) & ...
                app.overlap_table.class_selection == ...
                    app.class_ids.not_reviewed;
            %
            if any(ii)
                app.overlap_table.class_selection(ii) = ...
                    app.class_ids.Artifact;
            end
            %
        end
        %
        function update_image_clean_up(app)
            %
            app.image_1_filename = [];
            app.image_2_filename = [];
            app.image_info_1 = [];
            app.image_info_2 = [];
            app.init_tables = [];
            %
        end
        %
        function change_current_cell(app, option)
            %%
            % change to a different cell when one of the provided
            % options are supplied
            % option: 'forawrd_one_cell', 'back_one_cell', 
            % 'next_nonreviewed_cell'
            %%
            %
            if app.n_overlapping_cells_to_test ~= -1
                check_overlapping_cell_test_set(app);
            end
            %
            switch app.next_cell_options.(option)
                %
                case app.next_cell_options.forwardOnecell
                    %
                    app.current_cell_number = app.current_cell_number + 1;
                    set_index_from_number(app)
                    %
                case app.next_cell_options.NextCell
                    %
                    app.current_cell_table_index = find(...
                        app.overlap_table.class_selection == ...
                        app.class_ids.not_reviewed, 1);
                    %
                    set_number_from_index(app)
                    %
                case app.next_cell_options.backOnecell
                    %
                    app.current_cell_number = app.current_cell_number - 1;
                    %
                    if app.current_cell_number < 1
                        app.current_cell_number = 1;
                    end
                    %
                    set_index_from_number(app)
                    %
                case app.next_cell_options.Jumptocell
                    %
                    if app.JumpCellButton.Value > ...
                            max(app.overlap_table.pairid)
                        app.JumpCellButton.Value = ...
                            max(app.overlap_table.pairid);
                    end
                    %
                    app.current_cell_table_index = find(...
                        app.overlap_table.pairid == ...
                        app.JumpCellButton.Value);
                    %
                    set_number_from_index(app)
                    %
            end
            %
            handle_final_review(app)
            display_current_cell_opts(app)
            %
            handle_button_click(app, app.([option, 'Button']), 'off')
            %
        end
        %
        function set_number_from_index(app)
            %
            if ~isempty(app.current_cell_table_index)
                app.current_cell_number = ...
                    app.overlap_table.counter(...
                    app.current_cell_table_index);
            end
            %
        end
        %
        function set_index_from_number(app)
            %
            app.current_cell_table_index = find( ...
                app.overlap_table.counter == app.current_cell_number);
            %
        end
        %
        function display_current_cell_opts(app)
            %%
            % edit the ui views appropriately
            %%
            handle_button_interrupt(app, ...
                app.button_interrupt_options.new_cell)
            %
            reset_panel_side(app)
            %
            select_previous_opt(app)
            %
            get_current_cell_masks(app)
            compile_applied_seg_mask(app, app);            
            %
            display_images(app, 1)
            %
            app.SegToolUI.Name = ['Segmentation Tool: ', app.image_id, ...
               '; Detected Object: ', num2str(app.current_cell_number),...
                'of', app.total_cells_to_check, ' - Object ID: ', ...
                num2str(app.current_cell_pairid_index(app))];
        end
        %
        function select_previous_opt(app)
            %%
            % set the push button to appropriate value and reselect cell
            % boundary
            %%
            switch app.overlap_table.class_selection(...
                    app.current_cell_table_index)
                case app.class_ids.image_1 % 1
                    %
                    if strcmp(app.A, '1')
                        handle_button_click(app, app.AButton, ...
                            'on')
                    else
                        handle_button_click(app, app.BButton, ...
                            'on')
                    end
                    %
                case app.class_ids.image_2 % 2
                    %
                    if strcmp(app.A, '2')
                        handle_button_click(app, app.AButton, ...
                            'on')
                    else
                        handle_button_click(app, app.BButton, ...
                            'on')
                    end
                    %
                case app.class_ids.drawn % 3
                    %
                    handle_button_click(app, app.DrawonDAPIButton, ...
                        'on')
                    %
                case app.class_ids.auto_reviewed % 4
                    %
                    uialert(app.SegToolUI,...
                        'Unknown error in cell selection', 'Error')
                    %
                case app.class_ids.AB % 5
                    handle_button_click(app, app.ABButton, ...
                        'on')
                case app.class_ids.Artifact % -1
                    handle_button_click(app, app.ArtifactButton, ...
                        'on')
                case app.class_ids.Neither % -2
                    handle_button_click(app, app.NeitherButton, ...
                        'on')
            end
            %
            app.ObjectPlaneSpinner.Value = ...
                app.overlap_table.ground_level(...
                app.current_cell_table_index);
            %
            parse_flags(app, 'MultiNuc')
            parse_flags(app, 'Flag')
            %
        end
        %
        function parse_flags(app, type)
            %%
            % parse the flags column
            %%
            flags = app.overlap_table.flags(app.current_cell_table_index);
            %
            if bitand(flags, app.flag_ids.(type)) == app.flag_ids.(type)
                handle_button_click(app, app.([type, 'Button']), ...
                    'on')
            end
            %
        end
        %
        function get_current_cell_masks(app)
            %%
            % The function defines the image cut out region and builds the
            % orginal image masks for the cell from image 1 and image 2
            % segmentation algorithms
            %%
            %
            [image_1_cell, image_2_cell] = select_cell_objects(app);
            [x_center, y_center] = get_cell_center(app, image_1_cell, ...
                image_2_cell);
            %
            get_bounds(app, [x_center, y_center]);
            %
            app.image_1_cell_coordinates = reference_cell_to_region( ...
                app, image_1_cell); 
            app.image_2_cell_coordinates = reference_cell_to_region( ...
                app, image_2_cell); 
            %
            % rebuild the original masks
            %
            app.segmentation_image_1 = get_region_of_seg_mask(...
                app, app.im_image_1_seg_brds, ...
                app.image_1_cell_coordinates);
            %
            app.segmentation_image_2 = get_region_of_seg_mask( ...
                app, app.im_image_2_seg_brds, ...
                app.image_2_cell_coordinates);
            %
            im_c = get_image_region(app, app.input_color_vectors);
            app.region_color_vectors = reshape(im_c, [], 2);
            %
        end
        %
        function [image_1_cell, image_2_cell] = select_cell_objects(app)
            %%
            % select the cell objects for both images 
            %%
            image_1_cell = app.overlap_table.(...
                [app.image_1_abbreviation,'_cells']){ ...
                    app.current_cell_table_index};
            %
            image_2_cell = app.overlap_table.(...
                [app.image_2_abbreviation,'_cells']){...
                    app.current_cell_table_index};
            %
        end
        %
        function [x_center, y_center] = get_cell_center(app, ...
                image_1_cell, image_2_cell)
            %%
            % determine which cell object to center on, if both values are
            % length less than 2 the cell is an added cell and find it's
            % centroid
            %%
            image_1_n_pixels = length(image_1_cell);
            image_2_n_pixels = length(image_2_cell);
            %
            if image_1_n_pixels > image_2_n_pixels
                %
                x_center = app.overlap_table.(...
                    [app.image_1_abbreviation,'_X_centroid'])(...
                    app.current_cell_table_index);
                y_center = app.overlap_table.(...
                    [app.image_2_abbreviation,'_Y_centroid'])(...
                    app.current_cell_table_index);
                %
            elseif image_1_n_pixels < 2 && image_2_n_pixels < 2 && ...
                    app.overlap_table.class_selection(...
                    app.current_cell_table_index) == app.class_ids.drawn
                %
                ii = app.draw_im == app.overlap_table.pairid(...
                    app.current_cell_table_index);
                cell_props = regionprops(ii, 'centroid');
                %
                while isempty(cell_props)
                    app.current_cell_table_index = ...
                        app.current_cell_table_index - 1;
                    ii = app.draw_im == app.overlap_table.pairid(...
                        app.current_cell_table_index);
                    cell_props = regionprops(ii, 'centroid');
                end
                %
                x_center = cell_props.Centroid(1);
                y_center = cell_props.Centroid(2);
                %
            else
                %
                x_center = app.overlap_table.(...
                    [app.image_2_abbreviation,'_X_centroid'])(...
                    app.current_cell_table_index);
                y_center = app.overlap_table.( ...
                    [app.image_2_abbreviation,'_Y_centroid'])(...
                    app.current_cell_table_index);
                %
            end
            %
        end
        %
        function [top, left, bottom, right] = get_bounds(app, input)
            %%
            % get the bounds for a cell based on a buffer region 
            % input: [x y] for center or vector of [top left bottom right]
            %%
            if nargin > 1
                %
                left = floor(input(1) - app.buffer_edge);
                right = floor(input(1) + app.buffer_edge);
                top = floor(input(2) - app.buffer_edge);
                bottom = floor(input(2) + app.buffer_edge);
                %
            else
                %
                top = app.bounding_box.top - app.buffer_edge;
                left = app.bounding_box.left - app.buffer_edge;
                bottom = app.bounding_box.bottom + app.buffer_edge;
                right = app.bounding_box.right + app.buffer_edge;
                %
            end
            %
            if top < 1
                top = 1;
            end
            %
            if bottom > app.nRows
                bottom = app.nRows;
            end
            %
            if left < 1
                left = 1;
            end
            %
            if right > app.nCols
                right = app.nCols;
            end
            %
            if nargin > 1
                %
                app.bounding_box.top = top;
                app.bounding_box.bottom = bottom;
                app.bounding_box.left = left;
                app.bounding_box.right = right;
                %
                app.bounding_box.cols = [1, right - left + 1];
                app.bounding_box.rows = [1, bottom - top + 1];
                app.bounding_box.size = [bottom - top + 1, right - left + 1];
                app.bounding_box.area = app.bounding_box.size(1) * ...
                    app.bounding_box.size(2);
                app.bounding_box.template_image = zeros(...
                    [app.bounding_box.size, 2]);
                %
            end
            %
        end
        %
        function indicies = reference_cell_to_region(app, cell)
            %%
            % get the indicies relative to the image region for a given
            % cell
            %%
            indicies = [];
            %
            if ~isempty(cell) && ~sum(cell == 0)
                [r, c] = ind2sub([app.nRows, app.nCols], cell);
                r = r - app.bounding_box.top + 1;
                c = c - app.bounding_box.left + 1;
                indicies = [r c];
            end
            %
        end
        %
        function im_s = get_region_of_seg_mask(app, im_s, cell_coordinates)
            %%
            % cut out only the desired region from the image, convert mask
            % to rgb with segmentation in red and the 
            % current cell highlighted in green
            %%
            im_s = get_image_region(app, im_s);
            im_s = cat(3, im_s, app.bounding_box.template_image);
            im_s = highlight_current_cell(app, im_s, cell_coordinates);
            %
        end
        %
        function im_s = highlight_current_cell(app, im_s, cell_coordinates)
            %%
            % highlight current cell in green
            %%
            if ~isempty(cell_coordinates)
                indicies = sub2ind(app.bounding_box.size, ...
                    cell_coordinates(:, 1), cell_coordinates(:, 2));
                im_s(indicies + app.bounding_box.area) = 255;
            end
            %
        end
        %
        function handle_final_review(app)
            %%
            % determine if we should trigger the final review window and 
            % open it if so
            %%
            %
            if ~isempty(app.current_cell_table_index) && ...
                    ~app.skip_to_full_view
                return
            end
            %
            app.current_cell_table_index = find(...
                app.overlap_table.class_selection == ...
                app.class_ids.not_reviewed, 1);
            if isempty(app.current_cell_table_index)
                app.current_cell_number = max(app.overlap_table.counter);
                app.finished_status = 1;
            end
            %
            set_index_from_number(app)
            display_current_cell_opts(app)
            %
            cleanup_overlapping_cells(app);
            %
            app.finished_status = 2;
            %
            win_state = app.SegToolUI.WindowState;
            set(app.object_handles,'Enable', 'off')
            app.SegToolUI.WindowState = 'minimized';
            %
            full_image_view_app = fullimageView(app);
            waitfor(full_image_view_app)
            %
            app.SegToolUI.WindowState = win_state;
            %
            set_active_select_axis_and_ui_seg_tool_base(app)
            set(app.object_handles,'Enable', 'on')
            %
            figure(app.SegToolUI)
            %
            msg = 'Whole image reviewed. Do you want to save?';
            save_with_dialog_box(app, msg)
            %
            app.current_cell_table_index = find(...
                app.overlap_table.class_selection == ...
                app.class_ids.not_reviewed, 1);
            %
            if isempty(app.current_cell_table_index)
                app.current_cell_number = max(app.overlap_table.counter);
            end
            %
            app.total_cells_to_check = num2str(...
                max(app.overlap_table.counter));
            set_index_from_number(app)
            %
        end
        %
        function reset_panel_side(app)
            %%
            % randomly assign the image to the left and the right panels
            %% 
            % 1 for image_1; 2 for image_2
            %
            r = randi([1, 2], 1);
            %
            if r == 1
                app.A = '1'; 
                app.B = '2';
            else
                app.B = '1';
                app.A = '2';
            end
            %
            app.class_ids.A = app.class_ids.(['image_', app.A]);
            app.class_ids.B = app.class_ids.(['image_', app.B]);
            %
        end
        %
        function display_images(app, ~)
            %%
            % calls the image display for each image panel
            %%
            if nargin == 2
                %
                % set to the size of the view
                %
                xl = app.bounding_box.cols;
                yl = app.bounding_box.rows;
                apply_image_colors(app, app)
                %
            else
                %
                xl = xlim(app.BD_ImageAxes);
                yl = ylim(app.BD_ImageAxes);
                %
            end
            %
            for axes_id = app.axes_ids
                %
                imshow_seg_tool(app, axes_id{1}, xl, yl)
                %
            end
            %
            linkaxes([app.AD_ImageAxes,app.AM_ImageAxes,...
                app.BD_ImageAxes,app.BM_ImageAxes], 'xy')
            %
        end
        %
        function imshow_seg_tool(app, ax, xl, yl)
            %%
            % this function is used to change the cell show as well as its
            % apperance
            % loc: is the location parameters defined in the startupfnc
            % membrane_ii: if the membrane or dapi image is being displayed
            % seg_id: The image segmenation to use for this image ('1' or
            % '2')
            % xl: x centroid
            % yl: y centroid
            %%           
            %
            im = get_color_image(app, (ax(2) == 'M'));
            im = handle_segmentation_masks(app, im, app.(ax(1)));
            %
            im_axis = app.([ax(1), ax(2), '_ImageAxes']);
            im_axis_image = app.([ax(1), ax(2), '_Image']);
            %
            if isempty(im_axis_image)
                app.([ax(1), ax(2), '_Image']) = ...
                    imshow(im, 'Parent', im_axis);
            else
                im_axis_image = app.([ax(1), ax(2), '_Image']);
                set(im_axis_image,'CData', im);
            end
            %
            xlim(im_axis, xl)
            ylim(im_axis, yl)
            %
        end
        %
        function im = handle_segmentation_masks(app, im, seg_id)
            %%
            % apply the segmentation masks to the image for the provided
            % buttons
            % im: the color image for display
            % seg_id: either '1' or '2' for the indicated segmentation masks
            %%
            seg = [];
            %
            if app.AppliedSegButton.Value
                %
                seg = app.(['applied_seg_mask_image_', seg_id]);
                %
            elseif app.ToggleSegButton.Value
                %
                seg = app.(['segmentation_image_', seg_id]);
                %
            end
            %
            im = apply_mask_colors(app, im, seg);
            im = uint8(im);
            %
        end
        %
        function handle_button_interrupt(app, reset_option)
            %%
            %
            %%
            app.esc()
            %
            % turn off class selection buttons
            %
            switch reset_option
                %
                case app.button_interrupt_options.new_cell
                    %
                    reset_class_selection_buttons(app)
                    handle_button_click(app, app.FlagButton, 'off')
                    handle_button_click(app, app.MultiNucButton, 'off')
                    handle_button_click(app, app.ToggleSegButton, 'on')
                    handle_button_click(app, app.AppliedSegButton, 'off')
                    %
                case app.button_interrupt_options.class_selection
                    %
                    reset_class_selection_buttons(app)
                    %
                case app.button_interrupt_options.trash
                    %
                    handle_button_click(app, app.TrashButton, 'off')
                    %
                    if ~app.class_selection_complete
                        clear_cell(app)
                        handle_button_click(app, app.DrawonMemButton, 'off')
                        handle_button_click(app, app.DrawonDAPIButton, ...
                            'off')
                        app.class_selection_complete = 1;
                    end
                    %
            end
            %
        end
        %
        function reset_class_selection_buttons(app)
            %
            handle_button_click(app, app.ABButton, 'off')
            handle_button_click(app, app.BButton, 'off')
            handle_button_click(app, app.AButton, 'off')
            handle_button_click(app, app.NeitherButton, 'off')
            handle_button_click(app, app.ArtifactButton, 'off')
            handle_button_click(app, app.DrawonMemButton, 'off')
            handle_button_click(app, app.DrawonDAPIButton, 'off')
            handle_button_click(app, app.TrashButton, 'off')
            %
        end
        %
        function handle_class_selection_click(app, id)
            %
            clear_cell(app)
            button_value = app.([id, 'Button']).Value;
            %
            handle_button_interrupt(app, ...
                app.button_interrupt_options.class_selection)
            %
            if ~button_value; recompile_and_display(app); return; end
            %
            app.class_selection_complete = 0;
            handle_button_click(app, app.([id, 'Button']), 'on')
            %
            switch id
                case {'A', 'B'}
                    %
                    set_cell_pairid_by_seg_id(app, app.(id))
                    %
                case 'AB'
                    %
                    set_cell_pairid_by_seg_id(app, '2')
                    %
                case {'DrawonDAPI', 'DrawonMem'}
                    %
                    do_draw(app, app, app.(['B',id(7), '_ImageAxes']))
                    toggle_seg(app)
                    %
            end
            %
            set_class_selection(app, app.class_ids.(id))
            recompile_and_display(app)
            app.class_selection_complete = 1;
            %
        end
        %
        function set_class_selection(app, value)
            %%
            % set the class selection for the value 
            %%
            if ~isnumeric(value)
                value = str2num(value); %#ok<ST2NM> 
            end
            %
            inp = app.current_cell_table_index;
            app.overlap_table.class_selection(inp) = value;
            %
        end
        %
        function set_cell_pairid(app, active_app, idx)
            %%
            % set the current cell in the paired cells object (where all
            % cells are stored)
            %%
            active_app.paired_cells(...
                app.current_cell_pairid_index(active_app)) = {idx};
            %
        end
        %
        function set_cell_pairid_by_seg_id(app, seg_id)
            %%
            % set the pixel indicies in the 'paired cells' object for one 
            % of the two input segmentations
            %%
            im = app.(['im_image_', seg_id]);
            im_abbreviation = app.(...
                    ['image_', seg_id, '_abbreviation']);
            %
            if strcmp(seg_id, '2')
                level = 1;
            else
                level = app.overlap_table.(...
                    [im_abbreviation, '_level'])(...
                    app.current_cell_table_index);
            end
            %
            cellid = app.overlap_table.([im_abbreviation, '_cellid'])(...
                app.current_cell_table_index);
            %
            if cellid ~= 0
                ii = im(:,:,level) == cellid;
                idx = label2idx(uint16(ii));
                set_cell_pairid(app, app, idx{1})
            end
            %
        end
        %
        function clear_cell(app)
            %%
            % clear the current cell from the paired_cells and draw_im
            %%
            set_class_selection(app, 0)
            set_cell_pairid(app, app, 0)
            app.draw_im(app.draw_im == ...
                app.current_cell_pairid_index(app)) = 0;
            %
        end
        %
        function recompile_and_display(app)
            %%
            % recompile and display the cell segmentation
            %%
            %
            compile_applied_seg_mask(app, app);
            %
            if app.AppliedSegButton.Value || ...
                    app.DrawonDAPIButton.Value || ...
                    app.DrawonMemButton.Value
                display_images(app)
            end
            %
        end
        %
        function handle_flag_click(app, id)
            %%
            %
            %%
            if app.([id, 'Button']).Value
                %
                handle_button_interrupt(app, ...
                    app.button_interrupt_options.flag)
                %
                handle_button_click(app, app.([id, 'Button']),'on')
                %
                inp = app.current_cell_table_index;
                app.overlap_table.flags(inp) = ...
                    app.overlap_table.flags(inp) + app.flag_ids.(id);
                %
            else
                %
                handle_button_click(app, app.([id, 'Button']), 'off')
                %
                inp = app.current_cell_table_index;
                app.overlap_table.flags(inp) = ...
                    app.overlap_table.flags(inp) - app.flag_ids.(id);
                %
            end
            %
        end
        %
        function do_trash(app)
            %
            trash = app.TrashButton.Value;
            %
            handle_button_interrupt(app, ...
                app.button_interrupt_options.trash)
            %
            if ~trash
                return
            end
            %
            handle_button_click(app, app.TrashButton, 'on')                 
            %
            apply_seg(app)
            %
            delete_object(app, app)
            %
            recompile_and_display(app)
            %
            handle_button_click(app, app.TrashButton, 'off')   
            %  
        end
        %
        function apply_seg(app)
            %%
            % turn on the applied segmentation mask 
            %%
            if ~app.AppliedSegButton.Value
                toggle_seg(app)
                display_images(app)
            end
            %
        end
        %
        function toggle_seg(app)
            %%
            % turn on the applied segmentation mask buttons without
            % displaying
            %%
            handle_button_click(app, app.AppliedSegButton, 'on')
            handle_button_click(app, app.ToggleSegButton, 'off')
            %
        end
        %
        function enable_default_handles(app)
            %%
            % most handles won't work without loading an image, this 
            % handles which should be active and which should be inactive
            % in that state
            %%
            if isempty(app.overlap_table)
                set(app.object_handles,'Enable', 'off')
                set(app.load_handle,'Enable', 'on')
                set(app.i_handle,'Enable', 'on')
                set(app.FV_handle,'Enable', 'on')
            else
                set(app.object_handles,'Enable', 'on')
            end
            %
        end
        %
        function set_active_select_axis_and_ui_seg_tool_base(app)
            %
            app.object_handles = findall(...
                app.SegToolUI, '-property', 'Enable');
            %
            app.load_handle = findall(app.SegToolUI, '-property', ...
                'Enable', 'Tag', 'load_image');
            app.i_handle = findall(app.SegToolUI, '-property', ...
                'Enable', 'Tag', 'i');
            app.FV_handle = findall(app.SegToolUI, '-property', ...
                'Enable', 'Tag', 'FV');
            %
            app.axis_to_select_on = app.BD_ImageAxes;
            app.active_ui = app.SegToolUI;
            app.active_app = app.apps.seg_tool_base;
            %
        end
        %
    end
    %
    methods (Access = public)
        %
        function return_to_seg_base(app, active_app)
            %
            app.overlap_table = active_app.overlap_table;
            app.draw_im = active_app.draw_im;
            app.paired_cells = active_app.paired_cells;
            %
        end
        %
        function handle_seg_tool_closing_dialog(app, closing_option)
            %
            switch closing_option
                %
                case app.closing_options.DontSaveClose
                    %
                    delete(app)
                    %
                case app.closing_options.SaveClose
                    %
                    save_success = save_protocol(app);
                    %
                    if save_success; delete(app); end
                    %
                case app.closing_options.Cancel
                    %
                    set(app.object_handles, 'Enable','on')
                    %
            end
            %
        end

        %
        function handle_button_click(app, hnd, status)
            %
            hnd.BackgroundColor = app.button_options.(status);
            %
            if strcmp(status, 'on')
                hnd.Value = 1;
            else
                hnd.Value = 0;
            end
            %
        end
        %
        function compile_applied_seg_mask(app, active_app)
            %%
            % get the applied segmentation mask
            % cell_table: the overlap table
            % cells: paired cell vector
            %%
            im_s = compile_mask(app, active_app, 'AppliedSeg');
            %
            im_s = get_image_region(app, im_s);
            im_s (im_s > 0) = app.segmentation_color;
            app.applied_seg_mask = cat(3, im_s, ...
                app.bounding_box.template_image);
            %
            if is_full_image_view(app)
                return
            end
            %
            app.applied_seg_mask_image_1 = highlight_current_cell(app, ...
                app.applied_seg_mask, app.image_1_cell_coordinates);
            app.applied_seg_mask_image_2 = highlight_current_cell(app, ...
                app.applied_seg_mask, app.image_2_cell_coordinates);
            %
        end
        %
        function flag_object(app, active_app, flag_type)
            %
            [table_index, ~] = select_a_cell(app, active_app);
            %
            if isempty(table_index)
                return
            end
            %
            flag_id = bitand(...
                active_app.overlap_table.flags(table_index), ...
                app.flag_ids.(flag_type)) == app.flag_ids.(flag_type);
            %
            if flag_id 
                active_app.overlap_table.flags(table_index) = ...
                    active_app.overlap_table.flags(table_index) - ...
                    app.flag_ids.(flag_type);
            else 
                active_app.overlap_table.flags(table_index) = ...
                    active_app.overlap_table.flags(table_index) + ...
                    app.flag_ids.(flag_type);
            end
            %
            app.([flag_type, '_mask']) = compile_rgb_masks(app, ...
                active_app, flag_type);
            %
        end
        %
        function compile_flag_masks(app, active_app)
            %%
            % compile both flag masks for the image region
            %%
            app.Flag_mask = compile_rgb_masks(app, active_app, 'Flag');
            app.MultiNuc_mask = compile_rgb_masks(app, active_app, ...
                'MultiNuc');
            %
        end
        %
        function mask = compile_rgb_masks(app, active_app, type)
            %%
            % compile the masks from the 'flags' column
            %%
            mask = compile_mask(app, active_app, type);
            mask = get_image_region(app, mask);
            mask = label2rgb(mask, 'lines', 'k', 'shuffle');
            %
        end
        %
        function mask = compile_mask(app, active_app, type)
            %%
            % compile an image mask of the provided type and for the 
            % provided cells
            % type: multinuc, flag, applied_seg
            % cell_table: the overlap table
            % cells: paired cell vector
            %%
            mask = app.template_image;
            [levels, cell_idxs, image_levels] = ...
                get_cells_in_bounds(app, active_app, type);
            %
            for image_level = 1:length(image_levels)
                %
                ii = levels == image_levels(image_level);
                cells_at_level = active_app.paired_cells(cell_idxs(ii));
                %
                cell_mask_idxs = cell2mat(cells_at_level);
                %
                switch app.flag_ids.(type)
                    %
                    case app.flag_ids.AppliedSeg
                        %
                        im2 = app.create_border_matrix( ...
                            app.template_image, cells_at_level);
                        %
                        im2 = im2(:, :, 1);
                        %
                        mask(cell_mask_idxs) = im2(cell_mask_idxs);
                        %
                    case {app.flag_ids.MultiNuc, app.flag_ids.Flag}
                        %
                        im2 = app.create_border_matrix( ...
                            app.template_image, cells_at_level);
                        %
                        im2 = im2(:, :, 2);
                        %
                        mask(cell_mask_idxs) = im2(cell_mask_idxs);
                        %
                    case app.flag_ids.AppliedSegCells
                        %
                        mask(cell_mask_idxs) = 1;
                        %
                    case app.flag_ids.Labels
                        %
                        im2 = create_label_matrix(...
                            app, cells_at_level, 1, cell_idxs(ii));
                        %
                        mask(cell_mask_idxs) = im2(cell_mask_idxs);
                        %
                    case app.flag_ids.Applied_RGB
                        %
                        im2 = app.create_border_matrix( ...
                            app.template_image, cells_at_level);
                        %
                        im2 = im2(:, :, 1);
                        %
                        mask(cell_mask_idxs) = im2(cell_mask_idxs);
                        %
                end
                %
            end
            %
        end
        %
        function image = get_image_region(app, image)
            %%
            % get the region of the input image specified by the bounding
            % box
            %%
            if is_full_image_view(app)
                return
            end
            %
            image = image(...
                app.bounding_box.top(1):app.bounding_box.bottom(1),...
                app.bounding_box.left(1):app.bounding_box.right(1), :);
            %
        end
        %
        function log = is_full_image_view(app)
            %
            if app.active_app == app.apps.full_image_view
                %
                log = true;
                %
            else 
                log = false;
            end
            %
        end
        %
        function [levels, cell_idxs, image_levels] = ...
                get_cells_in_bounds(app, active_app, type)
            %%
            % get all cells with 2*buffer region of the cell of interest
            % cell_table: the overlap table
            % cells: paired cell vector
            %%
            %
            if is_full_image_view(app)
                %
                cell_idxs = cellfun(...
                    @(x)(~isempty(x) & any(x~=0)), active_app.paired_cells);
                %
            else
                %
                [top, left, bottom, right] = get_bounds(app);
                im = app.template_image;
                im(top:bottom, left:right) = 1;
                cell_idxs = find(im);
                cell_idxs = cellfun(@(x) sum(ismember(x, ...
                    cell_idxs)) > 0, active_app.paired_cells);
                %
            end
            %
            levels = active_app.overlap_table.ground_level(cell_idxs);
            %
            image_levels = unique(levels) + 6;
            %
            levels = levels + 6;
            %
            if ~ismember(app.flag_ids.(type), [...
                    app.flag_ids.AppliedSegCells, ...
                    app.flag_ids.Labels, ...
                    app.flag_ids.Applied_RGB])
                %
                flags = bitand(active_app.overlap_table.flags(...
                    cell_idxs), app.flag_ids.(type)) == ...
                    app.flag_ids.(type);
                % 
                levels = levels .* flags;
                %
            end
            %
            cell_idxs = find(cell_idxs);
            %
        end
        %       
        function apply_image_colors(app, active_app)
            %%
            % scale the colors according to the slider and color
            % button selection
            %%
            %
            if active_app.DAPIButton.Value
                %
                app.a_d = app.a_slider_scale_value * ...
                   active_app.ContrastSlider.Value;
                app.b_d = app.b_slider_scale_value * ...
                   active_app.BrightnessSlider.Value;
               %
            end
            %
            im_d =  app.b_d .* asinh(app.a_d .* ...
                app.region_color_vectors(:,1)) * app.my_colors(1,:);
            %
            app.im_dapi = reshape(im_d, [app.bounding_box.size, 3]);
            %
            if active_app.MEMButton.Value
                app.a_m = app.a_slider_scale_value * ...
                    active_app.ContrastSlider.Value;
                app.b_m = app.b_slider_scale_value * ...
                   active_app.BrightnessSlider.Value;
            end
            %
            im_m_d =  app.b_m .* asinh(app.a_m .* ...
                app.region_color_vectors(:,2)) * app.my_colors(2,:);
            %
            im_m_d = im_m_d + im_d;
            %
            app.im_membrane_and_dapi = reshape(im_m_d, ...
                [app.bounding_box.size, 3]);
            %
        end
        %
        function im = get_color_image(app, membrane_ii)
            %
            if membrane_ii
                im = app.im_membrane_and_dapi;
            else
                im = app.im_dapi;
            end
            %
        end
        %
        function DAPI_click(app, active_app, off_color)
            %
            if active_app.DAPIButton.Value
                handle_button_click(app, active_app.MEMButton, off_color)
                handle_button_click(app, active_app.DAPIButton, 'on')
                active_app.ContrastSlider.Value = ...
                    app.a_d / app.a_slider_scale_value;
                active_app.BrightnessSlider.Value = ...
                    app.b_d / app.b_slider_scale_value;
            else
                handle_button_click(app, active_app.DAPIButton, off_color)               
            end
            %
        end
        %
        function MEM_click(app, active_app, off_color)
            %
            if active_app.MEMButton.Value
                handle_button_click(app, active_app.DAPIButton, off_color)
                handle_button_click(app, active_app.MEMButton, ...
                    'on')
                active_app.ContrastSlider.Value = app.a_m / ...
                    app.a_slider_scale_value;
                active_app.BrightnessSlider.Value = app.b_m / ...
                    app.b_slider_scale_value;
            else
                handle_button_click(app, active_app.MEMButton, off_color)
            end
            %
        end
        %
        function output_mask = draw_object(app, active_app, axis)
            %%
            % draw the cell object
            %%
            output_mask = [];
            %
            while ~validate_cell_object(app, output_mask)
                %
                remove_cell_object(app)
                %
                % if the class selection has passed through this point the 
                % handle was triggered via a MATLAB bug, we return and do
                % not draw. If both the draw values are false, the drawing
                % events were canceled. 
                %
                if handle_draw_options(app, active_app)
                    return
                end
                %
                app.cell_object = drawfreehand(axis, ...
                    'InteractionsAllowed','none', 'FaceAlpha', .2 , ...
                    'Multiclick', true);
                %
                try output_mask = createMask(app.cell_object); catch; end
                %
            end
            %
            remove_cell_object(app)
            %
        end
        %
        function do_draw(app, active_app, axis)
            %%
            % draw a cell and map it back to the full image.
            %%
            output_mask = draw_object(app, active_app, axis);
            %
            if ~isempty(output_mask)
                idx = region_mask_to_image_idx(app, output_mask);
                active_app.draw_im(idx) = ...
                    app.current_cell_pairid_index(active_app);
                set_cell_pairid(app, active_app, idx)
            end
            %
        end
        %
        function drawme = handle_draw_options(app, active_app)
            %
            switch app.active_app
                case app.apps.seg_tool_base
                    drawme = app.class_selection_complete || ...
                        (~app.DrawonMemButton.Value && ...
                        ~app.DrawonDAPIButton.Value);
                case {app.apps.add_cell, app.apps.full_image_view}
                    drawme = ~active_app.DrawButton.Value;
            end
            %
        end
        %
        function [idx] = select_object(app)
            %%
            % select a an object from an axis and return the row, col
            % indicies of it relative to the axis
            %%
            idx = [];
            %
            remove_cell_object(app)
            pause(.1)
            %
            app.cell_object = drawpoint(app.axis_to_select_on, ...
               'InteractionsAllowed','none');
            %
            if ~validate_cell_object(app, 1)
                remove_cell_object(app); return
            end
            %
            idx = floor(app.cell_object.Position);
            remove_cell_object(app)
            %
        end
        %
        function [table_index, selected_cellid] = select_a_cell(app, ...
                active_app)
            %%
            % select a cell, map it to the image, and find the table index 
            %%
            table_index = [];
            selected_cellid = [];
            %
            [idx] = select_object(app);
            %
            if isempty(idx)
                return
            end
            %
            [row, col] = reference_cell_to_image(app, idx(2), idx(1));
            idx = sub2ind([app.nRows, app.nCols], row, col);
            %
            ii = cellfun(@(x) ismember(idx, x), active_app.paired_cells);
            selected_cellid = find(ii);
            %
            if length(selected_cellid) > 1
               uialert(app.active_ui, ...
                   ['Selected point is in two cells. ',...
                   'Please select one at a time.'],...
                   'Warning', 'Icon', 'warning')
               return
            elseif isempty(selected_cellid)
               uialert(app.active_ui, ...
                   ['Selected point is not in a cell.'], ...
                   'Warning', 'Icon', 'warning')
               return
            end
            %
            table_index = find(active_app.overlap_table.pairid == ...
                selected_cellid);
            %
        end
        %
        function delete_object(app, active_app)
            %%
            % select a cell and delete it
            %%
            [table_index, selected_cellid] = select_a_cell(...
                app, active_app);
            %
            if isempty(table_index)
                return
            end
            %
            active_app.overlap_table.class_selection(table_index) = -3;
            active_app.paired_cells(selected_cellid) = {0};
            active_app.draw_im(active_app.draw_im == selected_cellid) = 0;
            %
        end
        %
        function remove_cell_object(app)
            %%
            % remove the drawing cell object if it exists. used throughout
            % the select and draw processes
            %%
            if ~isempty(app.cell_object)
               delete(app.cell_object)
            end
            %
        end
        %
        function draw = validate_cell_object(app, output_mask)
            %%
            % validate the drawn/selected cell object
            % returns true if object & position are valid.
            %%
            draw = (~isempty(app.cell_object) && ...
                    isvalid(app.cell_object) && ...
                    ~isempty(app.cell_object.Position)) && ...
                    ~isempty(output_mask) && any(output_mask(:));
            %
        end
        %
        function idx = region_mask_to_image_idx(app, output_mask)
            %%
            % map back to the image
            %%
            [row, col] = find(output_mask > 0);
            [row2, col2] = reference_cell_to_image(app, row, col);
            idx = sub2ind([app.nRows,app.nCols], row2, col2);
            %
        end
        %
        function [row, col] = reference_cell_to_image(app, x, y)
            %%
            % reference input x/y coordinates to the larger image
            %%
            row = x + app.bounding_box.top(1) - 1;
            col = y + app.bounding_box.left(1) - 1;
            %
        end
        %
        function image = apply_mask_colors(app, image, mask)
            %%
            %
            %%
            %            
            ii = mask > 0;
            %
            if length(ii) > 1
                image(ii) = (1 - app.mask_alpha) .* image(ii) ...
                    + app.mask_alpha .* double(mask(ii));
            end
            %
        end
        %
        function update_mask_buttons(app, active_app, flag_type)
            %%
            % switch between multinuc and flag masks
            %%
            switch flag_type
                case 'MultiNuc'
                    hnd1 = active_app.MultiNucMaskButton;
                    hnd2 = active_app.FlagMaskButton;
                case 'Flag'
                    hnd1 = active_app.FlagMaskButton;
                    hnd2 = active_app.MultiNucMaskButton;
            end
            %
            handle_button_click(app, hnd1,'on')
            handle_button_click(app, hnd2,'off') 
            %
        end
        %
        function draw_ui_instructions(app)
            %%
            %
            %%
            if app.display_add_cell_instructions
                msg = ['This is the first time a drawing window has ',...
                    'been selected in this session. Here are some ',...
                    'hints to help you get started: ',...
                    'Select the "Draw" button to begin drawing (the ',...
                    'pen with a circle in it).', ...
                    'If you wish to add another cell just ',...
                    'reselect the button. You may add as many ',...
                    'new cells as you like, a tally is recorded in ',...
                    'the figure header. If you wish to redraw a cell ',...
                    'you will have to delete the previous cell first. ',...
                    'You may either "Accept" or "Reject" all ',...
                    'cells at any time using the "check" or "X" buttons.'];
                [~] = uiconfirm(app.active_ui, msg, 'Helpful hints', ...
                    'Icon', 'info', 'Options',{'OK'}, 'DefaultOption', 1);
            end
            %
            app.display_add_cell_instructions = false;
            %
        end
        %
        function add_cell_row(app, active_app)
            %
            % display the images
            %
            active_app.cells_added_count = active_app.cells_added_count + 1;
            %
            next_row_index = height(active_app.overlap_table) + 1;
            %
            % add a new row to the table
            %
            tmp = array2table(zeros(1, width(active_app.overlap_table)));
            tmp.Properties.VariableNames ...
                = active_app.overlap_table.Properties.VariableNames;
            active_app.overlap_table = [active_app.overlap_table;tmp];
            %
            % assign the pairid and cell id as next row index 
            %
            active_app.overlap_table.pairid(next_row_index) = next_row_index; 
            active_app.current_cell_table_index = next_row_index; 
            %
            % set cell defaults
            %
            active_app.overlap_table.counter(next_row_index) = ...
                max(active_app.overlap_table.counter) + 1;
            %
            active_app.overlap_table.cell_check(next_row_index) = 1;
            active_app.overlap_table.class_selection(next_row_index) = ...
                app.class_ids.drawn;
            active_app.overlap_table.flags(next_row_index) = 0;
            active_app.paired_cells(next_row_index) = {0};
            active_app.overlap_table.ground_level(next_row_index) = 1;
            %
            active_app.ObjectPlaneSpinner.Value = 1;
            %
            % update the cell count in the app header
            %
            app.active_ui.Name = [app.image_id,...
                '; Currently Adding ', num2str(...
                active_app.cells_added_count), ' Object(s)'];
            %
        end
        %
        function save_with_dialog_box(app, msg)
            %
            save = uiconfirm(app.SegToolUI, msg,'Finished',...
                'Icon','success', 'Options', {'Yes', 'No'},...
                'DefaultOption', 1, 'CancelOption', 2);
            %
            if strcmp(save, 'Yes')
                save_protocol(app);
            end
            %
        end
        %
        function save_success = cleanup_and_save(app)
            %
            msg = 'Clean up the image before saving it?';
            save = uiconfirm(app.active_ui, msg,'Finished',...
                'Icon','success', 'Options', {'Yes', 'No'},...
                'DefaultOption', 1, 'CancelOption', 2);
            %
            if strcmp(save, 'Yes')
                save_success = cleanup_overlapping_cells(app);
            else 
                save_success = save_protocol(app);
            end
            %
        end
        %
        function save_success = cleanup_overlapping_cells(app)
            %%
            % sometimes cells are accidentally added over preexisting cells
            % At the end of the cell by cell review this is cleaned up.
            % can be skipped with the FV (skip to full view) button
            %%
            save_success = 0;
            %
            if app.skip_to_full_view
                %
                uialert(app.active_ui, ...
                    ['Skipping to whole image review, ',...
                'overlapping cells will not be cleaned up.'], ...
                'Finished','Icon',...
                'success','CloseFcn','uiresume(gcbf)');
                uiwait(gcbf)
                %
                return
            end
            %
            if app.finished_status == 1
                %
                save = uiconfirm(app.active_ui, ['Last cell has been ',...
                    'reviewed for the image. ',...
                    'Whole image view will be displayed ',...
                    '... clean up and save first?'], ...
                    'Finished','Icon',...
                    'success', 'Options', {'Yes', 'No'},...
                    'DefaultOption', 1, 'CancelOption', 2);
                %
                if strcmp(save, 'No')
                    return
                end
                %
            end
            %
            % clean up overlapping cells, keep the last one in the table
            %
            app.pgbar = uiprogressdlg(app.active_ui,'Title',...
                'Please Wait','Message','Cleaning up...');
            %
            a = app.active_app ;
            app.active_app = app.apps.full_image_view;
            mask = compile_mask(app, app, 'Labels');
            app.active_app = a;
            %
            new_paired_cells = label2idx(mask);
            empty_cells = cellfun(@(x)isempty(x), new_paired_cells);
            empty_cell_table = app.overlap_table(empty_cells, :);
            overwritten_cells = empty_cell_table( ...
                empty_cell_table.class_selection > 0, :);
            app.overlap_table.class_selection( ...
                overwritten_cells.pairid) = app.class_ids.Trash;
            %
            nn = length(app.paired_cells);
            u = 0;
            %
            for i1 = 1:nn
                %
                pp = i1 / nn;
                if pp > u
                    app.update_pgbar(app.pgbar, pp, ['Cleaning up... ', ...
                        num2str(100 * round(pp, 4)), '%'])
                    u = u + (10 / nn);
                end
                %
                mask_2 = mask;
                mask_2(mask_2 ~= i1) = 0;
                CC = bwconncomp(mask_2);
                if CC.NumObjects > 1
                    [~, idx] = max(cellfun(@numel,CC.PixelIdxList));
                    new_paired_cells(i1) = CC.PixelIdxList(idx);
                end
                %
            end
            %
            app.paired_cells = new_paired_cells';
            %
            app.update_pgbar(app.pgbar, 1, 'Finishing')
            close(app.pgbar);
            %
            save_success = save_protocol(app);
            %
        end
        %
        function save_success = save_protocol(app)
            %%
            % save both the label matrix and the overlap table
            %%
            %
            save_success = 0;
            %
            if app.finished_status == 2
                app.finished_status = 3;
                save_success = cleanup_and_save(app);
                app.finished_status = 2;
                return
            end
            %
            try
                %
                set(app.object_handles,'Enable', 'off')
                %
                save_progress_bar = uiprogressdlg(...
                    app.active_ui,'Title','Please Wait', 'Message', ...
                    'Saving current comparison image and table');
                %
            catch
                %
                uialert(app.active_ui, ['Comparision ',....
                    'image not saved! Please try again.'],...
                    "Saving Result Error");
                %
                save_progress_bar.Value = 1; pause(.5)
                close(save_progress_bar);
                %
                set(app.object_handles,'Enable', 'on')
                %
                return
                %
            end
            %
            save_label_matrix(app)
            save_cell_table(app)
            %
            switch app.error
                case 1
                    uialert(app.active_ui,['Comparision ',....
                        'image not saved! Check that you have',...
                        ' access to the output location in: ',...
                        app.data_directory], "Saving Result Error");
                case 2
                    uialert(app.active_ui,['Comparision ',....
                        'csv file not saved! Check that you have',...
                        ' access to the output location in: ',...
                        app.data_directory],"Saving Result Error");
            end
            %
            save_progress_bar.Value = 1; pause(.5)
            close(save_progress_bar); app.error = 0;
            set(app.object_handles,'Enable', 'on')
            %
            save_success = 1;
            %
        end
        %
        function save_label_matrix(app)
            %%
            % save the drawn segmentation image
            %%
            %
            x = app.overlap_table;
            ii = x.class_selection == app.class_ids.drawn;
            %
            idx = x.pairid(ii);
            gl = x.ground_level(ii);
            %
            mlow = min(gl);
            mhigh = max(gl);
            im = app.template_image;
            %
            for m_count = mlow:mhigh
                ii = gl == m_count;
                cells_n1 = app.paired_cells(idx(ii));
                im1 = create_label_matrix(app, cells_n1, 1, idx(ii));
                im(im1(:,:,1) > 0) = im1(im1(:,:,1) > 0);
                %
            end
            %
            try
                if exist(app.output_file_props.tif,'file')
                    delete(app.output_file_props.tif)
                end
            catch
                app.error = 1;
                return
            end
            %
            d = datenum(datetime);
            f = dir(app.output_file_props.tif);
            if isempty(f)
                f(1).datenum = d - 100;
            end
            ic = 0;
            %
            while d > f.datenum
                %
                % write image
                %
                try
                    ii = Tiff(app.output_file_props.tif,'w');
                    %
                    ii.setTag(app.output_image_props)
                    d = uint16(im(:,:,1));
                    write(ii,d);
                    close(ii)
                    %
                catch
                    app.error = 1;
                    break
                end
                %
                f = dir(app.output_file_props.tif);
                if isempty(f)
                    f(1).datenum = d - 100;
                end
                %
                ic = ic + 1;
                if ic == 5
                    app.error = 1;
                    break
                end
                %
            end
        end
        %
        function save_cell_table(app)
            %%
            % This function saves the app.overlap_table as a .csv file 
            % without the cells in it
            %%
            x = app.overlap_table;
            %
            x.([app.image_1_abbreviation,'_cells']) = [];
            x.([app.image_2_abbreviation,'_cells']) = [];
            x.counter = [];
            %
            ofname = app.output_file_props.csv;
            %
            d = datenum(datetime);
            f = dir(ofname);
            if isempty(f)
                f(1).datenum = d - 100;
            end
            %
            ic = 0;
            while d > f.datenum
                try
                    writetable(x, ofname)
                catch
                end
                %
                pause(.5)
                f = dir(ofname);
                if isempty(f)
                    f(1).datenum = d - 100;
                end
                ic = ic + 1;
                if ic == 5
                    app.error = 2;
                    break
                end
            end
            %
        end
        %
    end
    %
    methods (Static, Access = public)
        %
        function esc()
            robot = java.awt.Robot;
            robot.keyPress    (java.awt.event.KeyEvent.VK_ESCAPE);
            robot.keyRelease  (java.awt.event.KeyEvent.VK_ESCAPE);
        end
        %
        function update_pgbar(pgbar, value, message)
            %
            pgbar.Value = value;
            pgbar.Message = message;
            pause(.000000001)
            %
        end
        %
        function out = struct_idx_lookup(structure, index)
            %
            fns = fieldnames(structure);
            out = fns(struct2array(structure) == index);
            out = out{1};
            %
        end
        %
        function label_matrix = create_border_matrix(...
                label_matrix, cells_at_level)
            %%
            % create a label matrix from the cells
            %%
            %
            for i1 = 1:length(cells_at_level)
                idx = cells_at_level{i1};
                if length(idx) == 1
                    continue
                end
                label_matrix(idx) = i1;
            end
            %
            % get boardering pixels by dilating and subtracting the cells
            % themselves
            %
            inside_cell_label_matrix = imerode(label_matrix, ones(3,3));
            label_matrix = (label_matrix - inside_cell_label_matrix);
            label_matrix(:,:,2) = inside_cell_label_matrix;
            %
        end
        %
        function handle_shortcut_action(hnd)
            %
            if hnd.Value == 1
                hnd.Value = 0;
            else
                hnd.Value = 1;
            end
            %
            myEvent.Origin = 'Value set programmatically';
            hnd.ValueChangedFcn(hnd, myEvent);
            %
        end
        %
        function id = current_cell_pairid_index(active_app)
            %%
            % get the current cells pair id index
            %%
            inp = active_app.current_cell_table_index;
            id = active_app.overlap_table.pairid(inp);
            %
        end
        %
        function imshow_base(active_app, im)
            %%
            % show the image on the active app, used in both add_cell and
            % final_review
            %%
            %
            %xlim(active_app.ImageAxes, active_app.im_xlim)
            %ylim(active_app.ImageAxes, active_app.im_ylim)
            %
            if isempty(active_app.ImageAxes_) %|| ...
                    %any(active_app.ImageAxes.Position ~= ...
                    %active_app.stored_axes_position)
                %
                active_app.ImageAxes_ = imshow(im, 'Parent', ...
                    active_app.ImageAxes);
                set(active_app.ImageAxes_, 'BusyAction', 'queue');
                %active_app.stored_axes_position = ...
                %    active_app.ImageAxes.Position;
                %
            else
                set(active_app.ImageAxes_, 'CData', im);
            end
            %
        end
        %
    end
    %
    methods (Static, Access = private)
        %
        function obj = fill_objects(obj,y,x)
            %% ------------------------------------------------------------
            %% Created by: Benjamin Green - Johns Hopkins - 01/03/2019
            %% ------------------------------------------------------------
               %% Description
               % fill the cell object
               %% ------------------------------------------------------------
               s = size(obj);
               x = x + 1;
               y = y + 1;
               obj(sub2ind(s, y, x)) = 1;
               obj = imfill(obj);
               %
        end
        %
        function [w, tedge] = find_open_edges(...
                b, rows, w, tedge, ngbs_1, ngbs_2, ngbs_3)
            %%
            % find the edge where its pixel nieghborhood is missing a
            % boundary
            %%
            edges = b(rows,:);
            %
            for i1 = 1:length(edges(:, 1))
                x = edges(i1, :);
                neighbor = zeros(8, 2);
                neighbor(1, :) = [x(1) - 1, x(2) - 1];
                neighbor(2, :) = [x(1), x(2) - 1];
                neighbor(3, :) = [x(1) + 1, x(2) - 1];
                neighbor(4, :) = [x(1) - 1, x(2)];
                neighbor(5, :) = [x(1) + 1, x(2)];
                neighbor(6, :) = [x(1) - 1, x(2) + 1];
                neighbor(7, :) = [x(1), x(2) + 1];
                neighbor(8, :) = [x(1) + 1, x(2) + 1];
                neighborhood(1) = sum(ismember(...
                    neighbor(ngbs_1, :), b, 'rows') )> 0;
                neighborhood(2) = sum(ismember(...
                    neighbor(ngbs_2, :), b, 'rows')) > 0;
                neighborhood(3) = sum(ismember(...
                    neighbor(ngbs_3, :), b, 'rows')) > 0;
                val = sum(neighborhood);
                if val == 1
                    tedge(w, :) = x;
                    w = w + 1;
                end
            end
            %
        end
        %
    end
    %

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function SegToolstartupFcn(app)
            %
            % Configure image axes
            %
            for axes_id = app.axes_ids
                axis_id = [axes_id{1}, '_ImageAxes'];
                app.(axis_id) = axes('Parent', app.([axes_id{1}, '_ImagePanel']));
                app.(axis_id).Box = 'on';
                app.(axis_id).XTick = [];
                app.(axis_id).XTickLabel = [];
                app.(axis_id).YTick = [];
                app.(axis_id).ZTick = [];
                app.(axis_id).Color = [1 1 1];
                app.(axis_id).Units = 'normalized';
                app.(axis_id).Visible = 'on';
                app.(axis_id).Colormap = gray(256);
                app.(axis_id).Color = 'black';
                app.(axis_id).Clipping = 'off';
                axis(app.(axis_id), 'image');
                %app.AM_ImageAxes.DataAspectRatioMode = 'auto'
                app.(axis_id).Position = [0 0 1 1];
                
            end
            %
            % turn off all handles except those for loading an image,
            % avoids users clicking buttons leading to errors.
            %
            set_active_select_axis_and_ui_seg_tool_base(app);
            enable_default_handles(app)
            %
        end

        % Value changed function: AButton
        function AButtonValueChanged(app, event)
            %
            handle_class_selection_click(app, 'A')
            %
        end

        % Value changed function: ABButton
        function ABButtonValueChanged(app, event)
            %
            handle_class_selection_click(app, 'AB')
            %
        end

        % Value changed function: BButton
        function BButtonValueChanged(app, event)
            %
            handle_class_selection_click(app, 'B')
            %
        end

        % Value changed function: NeitherButton
        function NeitherButtonValueChanged(app, event)
            %
            handle_class_selection_click(app, 'Neither')
            %
        end

        % Value changed function: ArtifactButton
        function ArtifactButtonValueChanged(app, event)
            %
            handle_class_selection_click(app, 'Artifact')
            %
        end

        % Value changed function: DrawonDAPIButton
        function DrawonDAPIButtonValueChanged(app, event)
            %
            handle_class_selection_click(app, 'DrawonDAPI')
            %
        end

        % Value changed function: DrawonMemButton
        function DrawonMemButtonValueChanged(app, event)
            %
            handle_class_selection_click(app, 'DrawonMem')
            %
        end

        % Value changed function: AddcellButton
        function AddcellButtonValueChanged(app, event)
            %
            handle_button_click(app, app.AddcellButton, 'on')
            app.esc()
            %
            set(app.object_handles, 'Enable', 'off')
            add_cell_app = Addcell(app);
            waitfor(add_cell_app)
            set(app.object_handles, 'Enable', 'on')
            %
            set_active_select_axis_and_ui_seg_tool_base(app)
            %
            remove_artifacts(app)
            %
            app.overlap_table = set_overlap_counter(app, ...
                app.overlap_table);
            %
            handle_button_click(app, app.AddcellButton, 'off')
            %
            change_current_cell(app, 'NextCell')
            %
        end

        % Value changed function: FlagButton
        function FlagButtonValueChanged(app, event)
            %
            handle_flag_click(app, 'Flag') 
            %
        end

        % Value changed function: MultiNucButton
        function MultiNucButtonValueChanged(app, event)
            %
            handle_flag_click(app, 'MultiNuc')          
            %
        end

        % Value changed function: TrashButton
        function TrashButtonValueChanged(app, event)
           %
           do_trash(app)
           %
        end

        % Value changed function: ObjectPlaneSpinner
        function ObjectPlaneSpinnerValueChanged(app, event)
            %
            app.overlap_table.ground_level( ...
                app.current_cell_table_index) ...
                = app.ObjectPlaneSpinner.Value;
            %
            recompile_and_display(app)
            %
        end

        % Value changed function: forwardOnecellButton
        function forwardOnecellButtonValueChanged(app, event)
            %
            change_current_cell(app, 'forwardOnecell')
            %
        end

        % Value changed function: backOnecellButton
        function backOnecellButtonValueChanged(app, event)
            %
            change_current_cell(app, 'backOnecell')
            %
        end

        % Value changed function: NextCellButton
        function NextCellButtonValueChanged(app, event)
            %
            change_current_cell(app, 'NextCell')
            %
        end

        % Value changed function: JumptocellButton
        function JumptocellButtonValueChanged(app, event)
            %
            change_current_cell(app, 'Jumptocell')
            %
        end

        % Value changed function: ToggleSegButton
        function ToggleSegButtonValueChanged(app, event)
            %
            if app.ToggleSegButton.Value
                handle_button_click(app, app.AppliedSegButton, 'off')
                handle_button_click(app, app.ToggleSegButton, 'on')
            else
                handle_button_click(app, app.ToggleSegButton, 'off')
            end
            %
            display_images(app)
            %
        end

        % Value changed function: AppliedSegButton
        function AppliedSegButtonValueChanged(app, event)
            %
            if app.AppliedSegButton.Value
                handle_button_click(app, app.ToggleSegButton, 'off')
                handle_button_click(app, app.AppliedSegButton, 'on')
            else
                handle_button_click(app, app.AppliedSegButton, 'off')
            end
            %
            display_images(app)
            %
        end

        % Value changed function: DAPIButton
        function DAPIButtonValueChanged(app, event)
            %
            DAPI_click(app, app, 'off')
            %
        end

        % Value changed function: MEMButton
        function MEMButtonValueChanged(app, event)
            %
            MEM_click(app, app, 'off')
            %
        end

        % Value changed function: BrightnessSlider
        function BrightnessSliderValueChanged(app, event)
            %
            apply_image_colors(app, app)
            display_images(app)
            %
        end

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged(app, event)
            %
            apply_image_colors(app, app)
            display_images(app)
            %
        end

        % Key press function: SegToolUI
        function SegToolUIKeyPress(app, event)
            %
            switch event.Key
                %
                case 'q'
                    %
                    app.handle_shortcut_action(app.DrawonDAPIButton)
                    %
                case 'w'
                    %
                    app.handle_shortcut_action(app.DrawonMemButton)
                    %
                case 'a'
                    %
                    app.handle_shortcut_action(app.AButton)
                    %
                case 's'
                    %
                    app.handle_shortcut_action(app.ABButton)
                    %
                case 'd'
                    %
                    app.handle_shortcut_action(app.BButton)
                    %
                case 'z'
                    %
                    app.handle_shortcut_action(app.NeitherButton)
                    %
                case 'x'
                    %
                    app.handle_shortcut_action(app.ArtifactButton)
                    %
                case 'f'
                    %
                    app.handle_shortcut_action(app.FlagButton)
                    %
                case 'm'
                    %
                    app.handle_shortcut_action(app.MultiNucButton)
                    %
                case 't'
                    %
                    app.handle_shortcut_action(app.TrashButton)
                    %
                case 'shift'
                    %
                    app.handle_shortcut_action(app.SaveTableButton)
                    %
                case 'l'
                    %
                    app.handle_shortcut_action(app.LoadImageButton)
                    %
                case 'i'
                    %
                    app.handle_shortcut_action(app.iButton)
                    %
                case 'leftarrow'
                    %
                    change_current_cell(app, 'backOnecell')
                    %
                case 'rightarrow'
                    %
                    change_current_cell(app, 'forwardOnecell')
                    %
                case 'return'
                    %
                    change_current_cell(app, 'NextCell')
                    %
                %case 'escape'
                    %
                %    handle_button_interrupt(app, ...
                %        app.button_interrupt_options.trash)
                    %
            end
            %
        end

        % Value changed function: LoadImageButton
        function LoadImageButtonValueChanged(app, event)
            %
            handle_button_click(app, app.LoadImageButton, 'on')
            %    
            % if app.current_cell_number is not empty then there are 
            % results and they should be saved before moving on to next 
            % image
            %
            if ~isempty(app.overlap_table)
                msg = 'Loading new image. Save current table first?';
                save_with_dialog_box(app, msg)
            end
            %
            set(app.object_handles,'Enable', 'off')
            %
            % Display uigetfile dialog
            %
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            %
            [f, p] = uigetfile(filterspec);
            %
            if isequal(f, 0)
                %
                uialert(app.SegToolUI,['User selected Cancel.',...
                   ' Please select a valid input file'],'Invalid File',...
                   'Icon','warning')
                enable_default_handles(app)
                %
                figure(app.SegToolUI)
                handle_button_click(app, app.LoadImageButton, 'off')
                %
                return
                %
            end
            %
            figure(app.SegToolUI)
            %
            app.pgbar = uiprogressdlg(app.SegToolUI,'Title',...
                'Please Wait','Message',['checking file names ',...
                    'and file structure']);
            %
            input_image_filename = [p f];
            update_image(app, input_image_filename);
            %
            try
                %
                app.update_pgbar(app.pgbar, 1, 'Finishing')
                close(app.pgbar);
                %
            catch
            end
            %
            handle_button_click(app, app.LoadImageButton, 'off')
            %
        end

        % Value changed function: SaveTableButton
        function SaveTableButtonValueChanged(app, event)
            %
            set(app.SaveTableButton,'Enable', 'off')
            handle_button_click(app, app.SaveTableButton, 'on')
            save_protocol(app);
            handle_button_click(app, app.SaveTableButton, 'off')
            set(app.SaveTableButton,'Enable', 'on')
            %
        end

        % Value changed function: iButton
        function iButtonValueChanged(app, event)
            %
            set(app.object_handles,'Enable', 'off')
            %
            handle_button_click(app, app.iButton, 'on')
            settings_app = Settings(app);
            waitfor(settings_app)
            handle_button_click(app, app.iButton, 'off')
            %
            enable_default_handles(app)
            %
        end

        % Value changed function: FVButton
        function FVButtonValueChanged(app, event)
            %
            if app.FVButton.Value
                handle_button_click(app, app.FVButton, 'on')
            else
                handle_button_click(app, app.FVButton, 'off')
            end
            %
            app.skip_to_full_view = app.FVButton.Value;
            %
        end

        % Close request function: SegToolUI
        function SegToolUICloseRequest(app, event)
            %
            set(app.object_handles,'Enable', 'off')
            %
            if ~isempty(app.overlap_table)
                %
                closing_dialog_app = ClosingDialog(app);
                waitfor(closing_dialog_app)
                %
            else
                delete(app)
            end   
            %
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SegToolUI = uifigure('Visible', 'off');
            app.SegToolUI.Name = 'Segmentation Tool';
            app.SegToolUI.CloseRequestFcn = createCallbackFcn(app, @SegToolUICloseRequest, true);
            app.SegToolUI.KeyPressFcn = createCallbackFcn(app, @SegToolUIKeyPress, true);
            app.UIGridLayout = uigridlayout(app.SegToolUI);
            app.RightSideGridLayout = uigridlayout(app.UIGridLayout);
            app.ToolsPanel = uipanel(app.RightSideGridLayout);
            app.ToolsGridLayout = uigridlayout(app.ToolsPanel);
            app.ClassificationPanel = uipanel(app.ToolsGridLayout);
            app.ClassificationGridLayout = uigridlayout(app.ClassificationPanel);
            app.ClassificationLabel = uilabel(app.ClassificationGridLayout);
            app.ClassificationLabel.Text = ' Classification';
            app.DrawonDAPIButton = uibutton(app.ClassificationGridLayout, 'state');
            app.DrawonDAPIButton.ValueChangedFcn = createCallbackFcn(app, @DrawonDAPIButtonValueChanged, true);
            app.DrawonDAPIButton.Tooltip = {'Draw a cell on the DAPI image (use panel B for drawing)  (shortcut: Q).'};
            app.DrawonDAPIButton.Icon = 'drawing_icon.png';
            app.DrawonDAPIButton.Text = 'DAPI';
            app.DrawonMemButton = uibutton(app.ClassificationGridLayout, 'state');
            app.DrawonMemButton.ValueChangedFcn = createCallbackFcn(app, @DrawonMemButtonValueChanged, true);
            app.DrawonMemButton.Tooltip = {'Draw a cell on the DAPI & Membrane image (use panel B for drawing)  (shortcut: W).'};
            app.DrawonMemButton.Icon = 'drawing_icon.png';
            app.DrawonMemButton.Text = 'MEM';
            app.ArtifactButton = uibutton(app.ClassificationGridLayout, 'state');
            app.ArtifactButton.ValueChangedFcn = createCallbackFcn(app, @ArtifactButtonValueChanged, true);
            app.ArtifactButton.Tooltip = {'Label the cell as artifact which should not be used for further analysis  (shortcut: X).'};
            app.ArtifactButton.Text = 'Artifact';
            app.NeitherButton = uibutton(app.ClassificationGridLayout, 'state');
            app.NeitherButton.ValueChangedFcn = createCallbackFcn(app, @NeitherButtonValueChanged, true);
            app.NeitherButton.Tooltip = {'Mark the cell as neither A or B  (shortcut: Z).'};
            app.NeitherButton.Text = 'Neither';
            app.AButton = uibutton(app.ClassificationGridLayout, 'state');
            app.AButton.ValueChangedFcn = createCallbackFcn(app, @AButtonValueChanged, true);
            app.AButton.Tooltip = {'Indicate that the segmentation in panel A is better than panel B (shortcut: A).'};
            app.AButton.Text = 'A';
            app.BButton = uibutton(app.ClassificationGridLayout, 'state');
            app.BButton.ValueChangedFcn = createCallbackFcn(app, @BButtonValueChanged, true);
            app.BButton.Tooltip = {'Indicate that the segmentation in panel B is better than panel A (shortcut: D).'};
            app.BButton.Text = 'B';
            app.ABButton = uibutton(app.ClassificationGridLayout, 'state');
            app.ABButton.ValueChangedFcn = createCallbackFcn(app, @ABButtonValueChanged, true);
            app.ABButton.Tooltip = {'Indicate that the segmentation in panel A is the same or similar to panel B  (shortcut: S).'};
            app.ABButton.Text = 'A = B';
            app.DisplayPanel = uipanel(app.ToolsGridLayout);
            app.DisplayGridLayout = uigridlayout(app.DisplayPanel);
            app.ToggleSegButton = uibutton(app.DisplayGridLayout, 'state');
            app.ToggleSegButton.ValueChangedFcn = createCallbackFcn(app, @ToggleSegButtonValueChanged, true);
            app.ToggleSegButton.Tooltip = {'Display the original segmentation mask for the respective algorithms.'};
            app.ToggleSegButton.Icon = 'show_a_b_seg_icon.png';
            app.ToggleSegButton.Text = 'A/B Seg';
            app.DisplayLabel = uilabel(app.DisplayGridLayout);
            app.DisplayLabel.Text = ' Display';
            app.AppliedSegButton = uibutton(app.DisplayGridLayout, 'state');
            app.AppliedSegButton.ValueChangedFcn = createCallbackFcn(app, @AppliedSegButtonValueChanged, true);
            app.AppliedSegButton.Tooltip = {'Display the updated segmentation from the user''s choices.'};
            app.AppliedSegButton.Icon = 'show_applied_seg_icon.png';
            app.AppliedSegButton.Text = 'Applied';
            app.DAPIButton = uibutton(app.DisplayGridLayout, 'state');
            app.DAPIButton.ValueChangedFcn = createCallbackFcn(app, @DAPIButtonValueChanged, true);
            app.DAPIButton.Tooltip = {'Change DAPI brightness or contrast.'};
            app.DAPIButton.Text = 'DAPI';
            app.MEMButton = uibutton(app.DisplayGridLayout, 'state');
            app.MEMButton.ValueChangedFcn = createCallbackFcn(app, @MEMButtonValueChanged, true);
            app.MEMButton.Tooltip = {'Change membrane brightness or contrast.'};
            app.MEMButton.Text = 'MEM';
            app.ContrastSliderLabel = uilabel(app.DisplayGridLayout);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.DisplayGridLayout);
            app.ContrastSlider.MajorTicks = [0 25 50 75 100];
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.ContrastSlider.MinorTicks = [];
            app.ContrastSlider.Value = 50;
            app.BrightnessSliderLabel = uilabel(app.DisplayGridLayout);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.DisplayGridLayout);
            app.BrightnessSlider.MajorTicks = [0 25 50 75 100];
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessSliderValueChanged, true);
            app.BrightnessSlider.MinorTicks = [];
            app.BrightnessSlider.Value = 50;
            app.AdditionalOptionsPanel = uipanel(app.ToolsGridLayout);
            app.AdditionalOptionsGridLayout = uigridlayout(app.AdditionalOptionsPanel);
            app.ObjectPlaneSpinnerLabel = uilabel(app.AdditionalOptionsGridLayout);
            app.ObjectPlaneSpinnerLabel.Text = 'Object Plane';
            app.ObjectPlaneSpinner = uispinner(app.AdditionalOptionsGridLayout);
            app.ObjectPlaneSpinner.Limits = [-5 5];
            app.ObjectPlaneSpinner.RoundFractionalValues = 'on';
            app.ObjectPlaneSpinner.ValueDisplayFormat = '%.0f';
            app.ObjectPlaneSpinner.ValueChangedFcn = createCallbackFcn(app, @ObjectPlaneSpinnerValueChanged, true);
            app.ObjectPlaneSpinner.Tooltip = {'Moves the object in front of or behind other objects.'};
            app.ObjectPlaneSpinner.Value = 1;
            app.AddcellButton = uibutton(app.AdditionalOptionsGridLayout, 'state');
            app.AddcellButton.ValueChangedFcn = createCallbackFcn(app, @AddcellButtonValueChanged, true);
            app.AddcellButton.Tooltip = {'Add a cell to the region using a pop out window.'};
            app.AddcellButton.Icon = 'add_cell_icon.png';
            app.AddcellButton.Text = '';
            app.FlagButton = uibutton(app.AdditionalOptionsGridLayout, 'state');
            app.FlagButton.ValueChangedFcn = createCallbackFcn(app, @FlagButtonValueChanged, true);
            app.FlagButton.Tooltip = {'Flag the current cell for review at the final image view stage  (shortcut: F).'};
            app.FlagButton.Icon = 'flag_icon.png';
            app.FlagButton.Text = '';
            app.MultiNucButton = uibutton(app.AdditionalOptionsGridLayout, 'state');
            app.MultiNucButton.ValueChangedFcn = createCallbackFcn(app, @MultiNucButtonValueChanged, true);
            app.MultiNucButton.Tooltip = {'Delineate the current cell as multi-nucleated  (shortcut: M).'};
            app.MultiNucButton.Icon = 'multi_nucleated_cell_icon.png';
            app.MultiNucButton.Text = '';
            app.TrashButton = uibutton(app.AdditionalOptionsGridLayout, 'state');
            app.TrashButton.ValueChangedFcn = createCallbackFcn(app, @TrashButtonValueChanged, true);
            app.TrashButton.Tooltip = {'Remove an object by clickng on it in the top right panel (shortcut: T).'};
            app.TrashButton.Icon = 'trash_can_icon.png';
            app.TrashButton.Text = '';
            app.ChangeCellGridLayout = uigridlayout(app.ToolsGridLayout);
            app.backOnecellButton = uibutton(app.ChangeCellGridLayout, 'state');
            app.backOnecellButton.ValueChangedFcn = createCallbackFcn(app, @backOnecellButtonValueChanged, true);
            app.backOnecellButton.Tooltip = {'Go back.'};
            app.backOnecellButton.Icon = 'left_arrow_icon.svg';
            app.backOnecellButton.Text = '';
            app.forwardOnecellButton = uibutton(app.ChangeCellGridLayout, 'state');
            app.forwardOnecellButton.ValueChangedFcn = createCallbackFcn(app, @forwardOnecellButtonValueChanged, true);
            app.forwardOnecellButton.Tooltip = {'Go to the next cell in the list. '};
            app.forwardOnecellButton.Icon = 'right_arrow_icon.svg';
            app.forwardOnecellButton.Text = '';
            app.NextCellButton = uibutton(app.ChangeCellGridLayout, 'state');
            app.NextCellButton.ValueChangedFcn = createCallbackFcn(app, @NextCellButtonValueChanged, true);
            app.NextCellButton.Tooltip = {'Go to the next cell that has not been assessed.'};
            app.NextCellButton.Text = 'Next Cell';
            app.JumptocellButton = uibutton(app.ChangeCellGridLayout, 'state');
            app.JumptocellButton.ValueChangedFcn = createCallbackFcn(app, @JumptocellButtonValueChanged, true);
            app.JumptocellButton.Tooltip = {'Jump to a specific cell / object id.'};
            app.JumptocellButton.Text = 'Jump to cell:';
            app.JumpCellButton = uieditfield(app.ChangeCellGridLayout, 'numeric');
            app.JumpCellButton.ValueDisplayFormat = '%.0f';
            app.SettingsGridLayout = uigridlayout(app.ToolsGridLayout);
            app.LoadImageButton = uibutton(app.SettingsGridLayout, 'state');
            app.LoadImageButton.ValueChangedFcn = createCallbackFcn(app, @LoadImageButtonValueChanged, true);
            app.LoadImageButton.Tag = 'load_image';
            app.LoadImageButton.Tooltip = {'load a new image'};
            app.LoadImageButton.Icon = 'load_image_icon.png';
            app.LoadImageButton.Text = '';
            app.SaveTableButton = uibutton(app.SettingsGridLayout, 'state');
            app.SaveTableButton.ValueChangedFcn = createCallbackFcn(app, @SaveTableButtonValueChanged, true);
            app.SaveTableButton.Tooltip = {'Save the table.'};
            app.SaveTableButton.Icon = 'save_icon.svg';
            app.SaveTableButton.Text = '';
            app.iButton = uibutton(app.SettingsGridLayout, 'state');
            app.iButton.ValueChangedFcn = createCallbackFcn(app, @iButtonValueChanged, true);
            app.iButton.Tag = 'i';
            app.iButton.Tooltip = {'change the settings for the input image.'};
            app.iButton.Icon = 'settings_icon.png';
            app.iButton.Text = '';
            app.FVButton = uibutton(app.SettingsGridLayout, 'state');
            app.FVButton.ValueChangedFcn = createCallbackFcn(app, @FVButtonValueChanged, true);
            app.FVButton.Tag = 'FV';
            app.FVButton.Tooltip = {'immediately go to full view on load and skip the clean up step.'};
            app.FVButton.Icon = 'skip_to_end_icon.png';
            app.FVButton.Text = '';
            app.LeftSideGridLayout = uigridlayout(app.UIGridLayout);
            app.Images_Panel = uipanel(app.LeftSideGridLayout);
            app.ImagesGridLayout = uigridlayout(app.Images_Panel);
            app.AM_ImagePanel = uipanel(app.ImagesGridLayout);
            app.BM_ImagePanel = uipanel(app.ImagesGridLayout);
            app.AD_ImagePanel = uipanel(app.ImagesGridLayout);
            app.BD_ImagePanel = uipanel(app.ImagesGridLayout);
            app.LabelsGridLayout = uigridlayout(app.UIGridLayout);
            app.ALabel = uilabel(app.LabelsGridLayout);
            app.ALabel.Text = 'A';
            app.BLabel = uilabel(app.LabelsGridLayout);
            app.BLabel.Text = 'B';
            % Show the figure after all components are created
            app.SegToolUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SegTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SegToolUI)

            % Execute the startup function
            runStartupFcn(app, @SegToolstartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SegToolUI)
        end
    end
end