classdef Settings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SettingsUI                      matlab.ui.Figure
        BoundaryTypeButtonGroup         matlab.ui.container.ButtonGroup
        NucleusButton                   matlab.ui.control.ToggleButton
        MembraneButton                  matlab.ui.control.ToggleButton
        inputdataLabel                  matlab.ui.control.Label
        inputdata_1                     matlab.ui.control.EditField
        boundaryformatDropDownLabel     matlab.ui.control.Label
        boundaryformat_1                matlab.ui.control.DropDown
        tableabbrevationEditFieldLabel  matlab.ui.control.Label
        tableabbrevation_1              matlab.ui.control.EditField
        DataType1Label                  matlab.ui.control.Label
        DataType2Label                  matlab.ui.control.Label
        ACCEPTButton                    matlab.ui.control.Button
        boundarylayersEditFieldLabel    matlab.ui.control.Label
        boundarylayers_1                matlab.ui.control.EditField
        inputdataLabel_2                matlab.ui.control.Label
        inputdata_2                     matlab.ui.control.EditField
        boundaryformatDropDown_2Label   matlab.ui.control.Label
        boundaryformat_2                matlab.ui.control.DropDown
        tableabbrevationEditField_2Label  matlab.ui.control.Label
        tableabbrevation_2              matlab.ui.control.EditField
        boundarylayersEditField_2Label  matlab.ui.control.Label
        boundarylayers_2                matlab.ui.control.EditField
        ImageVisualizationLayersEditFieldLabel  matlab.ui.control.Label
        imagelayers_1                   matlab.ui.control.EditField
        SegmentationShadingEditFieldLabel  matlab.ui.control.Label
        SegmentationShadingEditField    matlab.ui.control.NumericEditField
    end

    %
    properties (Access = private)
        %
        seg_tool_base % main app object
        boundary_type
        %
    end
    %
    methods (Access = private)
        %
        function get_input_variables(app, id)
            %
            change_value(app, id, 'tableabbrevation', 'abbreviation')
            change_value(app, id, 'boundaryformat', 'input_boundary_type')
            change_value(app, id, 'inputdata', 'type')
            change_value(app, id, 'boundarylayers', 'layers', ...
                app.boundary_type)
            %
        end
        %
        function change_value(app, id, value_name, property_name, ...
                property_exten)
            %
            if nargin > 4
                property = app.seg_tool_base.(['image_', id,'_', ...
                    property_name, '_', property_exten]);
                property = replace(num2str(property), '  ', ',');
            else
                property = app.seg_tool_base.(...
                    ['image_', id,'_', property_name]);
            end
            %
            app.([value_name, '_', id]).Value = property;
            %
        end
        %
        function change_property(app, id, value_name, property_name, ...
                property_exten)
            %
            if nargin > 4
                %
                property = ['image_', id,'_', ...
                    property_name, '_', property_exten];
                value = str2double(strsplit(...
                    app.([value_name, '_', id]).Value, ','));
                %
            else
                %
                property = ['image_', id,'_', property_name];
                value = app.([value_name, '_', id]).Value;
                %
            end
            %
            app.seg_tool_base.(property) = value;
            %
        end
        %
        function update_app_settings(app, id)
            %
            change_property(app, id, 'inputdata', 'type')
            change_property(app, id, 'tableabbrevation', 'abbreviation')
            change_property(app, id, 'boundaryformat', ...
                'input_boundary_type')
            change_property(app, id, 'boundarylayers', 'layers', ...
                app.boundary_type)
            %
        end
        %
    end
    %

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupSettingsApp(app, mainapp)
            %
            app.seg_tool_base = mainapp;
            app.boundary_type = app.seg_tool_base.boundary_type;
            %
            if strcmpi(app.boundary_type, 'nucleus')
                %
                handle_button_click(app.seg_tool_base, ...
                    app.NucleusButton, 'on')
                %
            else
                %
                handle_button_click(app.seg_tool_base, ...
                    app.MembraneButton, 'on')
                %
            end
            %
            change_value(app, '1', 'imagelayers', 'color', 'layers')
            %
            get_input_variables(app, '1')
            get_input_variables(app, '2')
            %
            app.SegmentationShadingEditField.Value = ...
                app.seg_tool_base.mask_alpha;
            %
            msg = ["This is the settings menu. Here you can ",...
                "toggle how the images are read in and handled. ",...
                "Some defaults are applied. ",...
                "",...
                "Note that changes will only occur during image loading."];
            uialert(app.SettingsUI, msg, 'Helpful hints', 'Icon', 'info')
            %
        end

        % Close request function: SettingsUI
        function SettingsUICloseRequest(app, event)
            %
            msg = 'Reject all settings and return to main app with previous?';
            title = 'Confirm "Reject"';
            del_var = uiconfirm(app.SettingsUI,msg,title,...
                'Options',{'Reject','Cancel'},...
                'DefaultOption',1,'CancelOption',2);
            %
            if strcmp(del_var, 'Reject')
                delete(app)
            end
            %
        end

        % Button pushed function: ACCEPTButton
        function ACCEPTButtonPushed(app, event)
            %
            change_property(app, '1', 'imagelayers', 'color', 'layers')
            app.seg_tool_base.boundary_type = app.boundary_type;
            app.seg_tool_base.mask_alpha = ...
                app.SegmentationShadingEditField.Value;
            update_app_settings(app, '1')
            update_app_settings(app, '2')
            %
            delete(app)
            %
        end

        % Selection changed function: BoundaryTypeButtonGroup
        function BoundaryTypeButtonGroupSelectionChanged(app, event)
            %
            if app.NucleusButton.Value
                app.boundary_type = 'nucleus';
                handle_button_click(app.seg_tool_base, ...
                    app.NucleusButton,'on')
                handle_button_click(app.seg_tool_base, ...
                    app.MembraneButton,'off')
            else
                app.boundary_type = 'membrane';
                handle_button_click(app.seg_tool_base, ...
                    app.NucleusButton,'off')
                handle_button_click(app.seg_tool_base, ...
                    app.MembraneButton,'on')
            end
            %
            change_value(app, '1', 'boundarylayers', 'layers', ...
                app.boundary_type)
            change_value(app, '2', 'boundarylayers', 'layers', ...
                app.boundary_type)
            %
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SettingsUI = uifigure('Visible', 'off');
            app.SettingsUI.Name = 'Settings';
            app.SettingsUI.CloseRequestFcn = createCallbackFcn(app, @SettingsUICloseRequest, true);
            app.BoundaryTypeButtonGroup = uibuttongroup(app.SettingsUI);
            app.BoundaryTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BoundaryTypeButtonGroupSelectionChanged, true);
            app.BoundaryTypeButtonGroup.Title = 'Boundary Type';
            app.NucleusButton = uitogglebutton(app.BoundaryTypeButtonGroup);
            app.NucleusButton.Text = 'Nucleus';
            app.NucleusButton.Value = true;
            app.MembraneButton = uitogglebutton(app.BoundaryTypeButtonGroup);
            app.MembraneButton.Text = 'Membrane';
            app.inputdataLabel = uilabel(app.SettingsUI);
            app.inputdataLabel.Text = 'input data';
            app.inputdata_1 = uieditfield(app.SettingsUI, 'text');
            app.inputdata_1.Value = 'inform_data';
            app.boundaryformatDropDownLabel = uilabel(app.SettingsUI);
            app.boundaryformatDropDownLabel.Text = 'boundary format';
            app.boundaryformat_1 = uidropdown(app.SettingsUI);
            app.boundaryformat_1.Items = {'mask', 'outline'};
            app.boundaryformat_1.Value = 'mask';
            app.tableabbrevationEditFieldLabel = uilabel(app.SettingsUI);
            app.tableabbrevationEditFieldLabel.Text = 'table abbrevation';
            app.tableabbrevation_1 = uieditfield(app.SettingsUI, 'text');
            app.tableabbrevation_1.Value = 'IF';
            app.DataType1Label = uilabel(app.SettingsUI);
            app.DataType1Label.Text = 'Data Type 1';
            app.DataType2Label = uilabel(app.SettingsUI);
            app.DataType2Label.Text = 'Data Type 2';
            app.ACCEPTButton = uibutton(app.SettingsUI, 'push');
            app.ACCEPTButton.ButtonPushedFcn = createCallbackFcn(app, @ACCEPTButtonPushed, true);
            app.ACCEPTButton.Text = {'ACCEPT'; ''};
            app.boundarylayersEditFieldLabel = uilabel(app.SettingsUI);
            app.boundarylayersEditFieldLabel.Text = 'boundary layers';
            app.boundarylayers_1 = uieditfield(app.SettingsUI, 'text');
            app.boundarylayers_1.Value = '12,13';
            app.inputdataLabel_2 = uilabel(app.SettingsUI);
            app.inputdataLabel_2.Text = 'input data';
            app.inputdata_2 = uieditfield(app.SettingsUI, 'text');
            app.inputdata_2.Value = 'superpixel';
            app.boundaryformatDropDown_2Label = uilabel(app.SettingsUI);
            app.boundaryformatDropDown_2Label.Text = 'boundary format';
            app.boundaryformat_2 = uidropdown(app.SettingsUI);
            app.boundaryformat_2.Items = {'mask', 'outline'};
            app.boundaryformat_2.Value = 'mask';
            app.tableabbrevationEditField_2Label = uilabel(app.SettingsUI);
            app.tableabbrevationEditField_2Label.Text = 'table abbrevation';
            app.tableabbrevation_2 = uieditfield(app.SettingsUI, 'text');
            app.tableabbrevation_2.Value = 'SP';
            app.boundarylayersEditField_2Label = uilabel(app.SettingsUI);
            app.boundarylayersEditField_2Label.Text = 'boundary layers';
            app.boundarylayers_2 = uieditfield(app.SettingsUI, 'text');
            app.boundarylayers_2.Value = '1,2';
            app.ImageVisualizationLayersEditFieldLabel = uilabel(app.SettingsUI);
            app.ImageVisualizationLayersEditFieldLabel.Text = 'Image Visualization Layers:';
            app.imagelayers_1 = uieditfield(app.SettingsUI, 'text');
            app.imagelayers_1.Value = '1, 9';
            app.SegmentationShadingEditFieldLabel = uilabel(app.SettingsUI);
            app.SegmentationShadingEditFieldLabel.Text = 'Segmentation Shading:';
            app.SegmentationShadingEditField = uieditfield(app.SettingsUI, 'numeric');
            app.SegmentationShadingEditField.Limits = [0 1];
            app.SegmentationShadingEditField.ValueDisplayFormat = '%.2f';
            app.SegmentationShadingEditField.Value = 0.45;
            % Show the figure after all components are created
            app.SettingsUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Settings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SettingsUI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupSettingsApp(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SettingsUI)
        end
    end
end