classdef fullimageView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        fullimageViewUI          matlab.ui.Figure
        UIGridLayout             matlab.ui.container.GridLayout
        RightSideGridLayout      matlab.ui.container.GridLayout
        ButtonsPanel             matlab.ui.container.Panel
        ButtonsGridLayout        matlab.ui.container.GridLayout
        ToolGridLayout           matlab.ui.container.GridLayout
        DrawButton               matlab.ui.control.StateButton
        MultiNucButton           matlab.ui.control.StateButton
        FlagButton               matlab.ui.control.StateButton
        TrashButton              matlab.ui.control.StateButton
        ObjectPlaneSpinner       matlab.ui.control.Spinner
        ObjectPlaneSpinnerLabel  matlab.ui.control.Label
        DisplayPanel             matlab.ui.container.Panel
        DisplayGridLayout        matlab.ui.container.GridLayout
        DisplayLabel             matlab.ui.control.Label
        ImageColorsGridLayout    matlab.ui.container.GridLayout
        DAPIButton               matlab.ui.control.StateButton
        MEMButton                matlab.ui.control.StateButton
        ShowMemButton            matlab.ui.control.StateButton
        ContrastSliderLabel      matlab.ui.control.Label
        ContrastSlider           matlab.ui.control.Slider
        BrightnessSliderLabel    matlab.ui.control.Label
        BrightnessSlider         matlab.ui.control.Slider
        MasksGridLayout          matlab.ui.container.GridLayout
        AppliedSegButton         matlab.ui.control.StateButton
        MaskedViewButton         matlab.ui.control.StateButton
        BackgroundMaskButton     matlab.ui.control.StateButton
        FlagMaskButton           matlab.ui.control.StateButton
        MultiNucMaskButton       matlab.ui.control.StateButton
        XEditFieldLabel          matlab.ui.control.Label
        XEditField               matlab.ui.control.NumericEditField
        YEditFieldLabel          matlab.ui.control.Label
        YEditField               matlab.ui.control.NumericEditField
        ExitButtonsPanel         matlab.ui.container.Panel
        ExitButtonsGridLayout    matlab.ui.container.GridLayout
        AcceptButton             matlab.ui.control.StateButton
        RejectButton             matlab.ui.control.StateButton
        iButton                  matlab.ui.control.StateButton
        SaveTableButton          matlab.ui.control.StateButton
        Panel                    matlab.ui.container.Panel
        LeftSideGridLayout       matlab.ui.container.GridLayout
        ImageAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        %% -----------------------------------------------
        % for image display
        im_xlim = 0; % x limits when displaying the image
        im_ylim = 0; % y limits when displaying the image
        ImageAxes_;
        stored_axes_position = [0 0 0 0];
        %% -----------------------------------------------
        % table variables for the final review
        %
        overlap_table % table of the cells
        draw_im % image for drawn cells 
        paired_cells % matlab cell struct where each stores value stores
        % linear indicies of the respective cell
        %% -----------------------------------------------
        % variables that store info for use
        current_cell_table_index;
        cells_added_count = -1; % number of cells added during the image 
        %
    end
    %
    properties (Access = private)
        %% -----------------------------------------------
        seg_tool_base % main app object
        mask_alpha_background = .15; % alpha for the background masks
        % review
        %% -----------------------------------------------
        % vars for gridded review
        %
        grid_size = 200; % size of the grid to view
        x_max_tiles = 20; % max number of tiles in the x direction
        y_max_tiles = 20; % max number of tiles in the y direction
        tiled_init = false; % when the tiling values are changed, axes are
        % locked to the tile once, else use the active x and y lims are 
        % used.
        tile_mask;
        %
    end
    %
    methods (Access = private)
        %
        function recompile_and_display_full_image_view(app)
            %%
            % recompile and display the cell segmentation
            %%
            %
            app.seg_tool_base.applied_seg_mask = ...
                compile_rgb_masks(app.seg_tool_base, app, 'Applied_RGB');
            compile_flag_masks(app.seg_tool_base, app)
            %
            if app.AppliedSegButton.Value
                display_images_full_image_view(app)
            end
            %
        end
        %
        function display_images_full_image_view(app)
            %%
            % calls the image display, storing the x and y limits 
            % before displaying to reset them. Separated from the imshow
            % b/c matlab was having some underlying bug when the storage
            % and the show were in the same function.
            %%
            %
            if app.tiled_init
               %
               if app.XEditField.Value ~= 0 ...
                    && app.YEditField.Value ~= 0
                    set_tile_lims(app)
                    %
                   xlim(app.ImageAxes, app.im_xlim)
                   ylim(app.ImageAxes, app.im_ylim)
                   %
               else
                   app.tiled_init = false;
               end
               %
            else
                app.im_xlim = xlim(app.ImageAxes);
                app.im_ylim = ylim(app.ImageAxes);
            end
            %
            imshow_full_image_view(app)
            %
        end
        %
        function imshow_full_image_view(app)
            %%
            % this function is used to change the cell show as well as its
            % apperance
            %%
            im = get_color_image(app.seg_tool_base, ...
                app.ShowMemButton.Value);
            %
            im = handle_display_masks(app, im);
            %
            app.seg_tool_base.imshow_base(app, im)
            %
        end
        %
        function set_tile_lims(app)
            %%
            % when the X and Y grid coordinates are updated 
            % set the new limits for viewing appropriately and build the
            % tile mask
            %%
            %
            if app.XEditField.Value > app.x_max_tiles
                app.XEditField.Value = app.x_max_tiles;
            end
            %
            if app.YEditField.Value > app.y_max_tiles
                app.YEditField.Value = app.y_max_tiles;
            end
            %
            tile_left = (...
                app.grid_size * (app.XEditField.Value - 1)) + 1;
            tile_right = tile_left + app.grid_size;
            %
            tile_bottom = (...
                app.grid_size * (app.YEditField.Value - 1)) + 1;
            tile_top = tile_bottom + app.grid_size;
            %
            if tile_right > app.seg_tool_base.nCols
                tile_right = app.seg_tool_base.nCols;
            end
            %
            if tile_top > app.seg_tool_base.nRows
                tile_top = app.seg_tool_base.nRows;
            end
            %
            app.im_xlim = [tile_left, tile_right];
            app.im_ylim = [tile_bottom, tile_top];
            %
            mask = ones(app.seg_tool_base.bounding_box.size(1:2)) * 255;
            %
            [x, y] = meshgrid(tile_left:tile_right,...
                tile_bottom:tile_top);
            mask(reshape(y, [], 1), reshape(x, [], 1)) = 0;
            %
            app.tile_mask = repmat(mask, [1 1 3]);
            %
            app.tiled_init = false;
            %
        end
        %
        function im = handle_display_masks(app, im)
            %%
            % handle the masks for the display image
            %%
            im = handle_segmentation_mask(app, im);
            im = handle_special_seg_masks(app, im);
            im = handle_flag_masks(app, im);
            im = handle_tile_mask(app, im);
            im = uint8(im);
            %
        end
        %
        function im = handle_segmentation_mask(app, im)
            %
            if app.AppliedSegButton.Value
                %
                seg = app.seg_tool_base.applied_seg_mask;
                im = apply_mask_colors(app.seg_tool_base, im, seg);
                %
            end
            %
        end
        %
        function im = handle_special_seg_masks(app, im)
            %%
            % handle display for masks that rely on the segmentation
            %%
            if app.MaskedViewButton.Value
                %
                seg = compile_mask(app.seg_tool_base, app, ...
                    'AppliedSegCells');
                seg = find(seg);
                %
                im(seg) = 0;
                im(seg + app.seg_tool_base.nRows * ...
                   app.seg_tool_base.nCols) = 0;
                im(seg + 2 * app.seg_tool_base.nRows * ...
                    app.seg_tool_base.nCols) = 0;
                %
            end
            %
            if app.BackgroundMaskButton.Value 
                %
                seg = compile_mask(app.seg_tool_base, app, ...
                    'AppliedSegCells');
                seg = repmat((seg == 0) * 255, [1 1 3]);
                ii = seg > 0;
                %
                im(ii) = (1 - app.mask_alpha_background) .* im(ii) ...
                        + app.mask_alpha_background .* double(seg(ii));
                %
            end
            %
        end
        %
        function im = handle_flag_masks(app, im)
            %%
            % handle display for masks that rely on the flags
            %%
            seg = [];
            %
            if app.FlagMaskButton.Value 
                %
                seg = app.seg_tool_base.Flag_mask;
                %
            elseif app.MultiNucMaskButton.Value 
                %
                seg = app.seg_tool_base.MultiNuc_mask;
                %
            end
            %
            im = apply_mask_colors(app.seg_tool_base, im, seg);
            %
        end
        %
        function im = handle_tile_mask(app, im)
            %%
            % handle the masking when tiled view is active
            %%
            if app.XEditField.Value ~= 0 ...
                    && app.YEditField.Value ~= 0
                %
                ii = app.tile_mask > 0;
                %
                im(ii) = (1 - app.mask_alpha_background) .* im(ii) ...
                        + app.mask_alpha_background .* ...
                        double(app.tile_mask(ii));
                %
            end
            %
        end
        %
        function do_flag(app, button_id)
            %
            button_value = app.([button_id, 'Button']).Value;
            %
            handle_button_interrupt(app)
            %
            if ~button_value
                display_images_full_image_view(app)
                return
            end
            %
            handle_button_click(app.seg_tool_base, ...
                app.([button_id, 'Button']),'on')
            %
            update_mask_buttons(app.seg_tool_base, app, button_id)
            %
            toggle_seg(app)
            %
            display_images_full_image_view(app)
            %
            flag_object(app.seg_tool_base, app, button_id)
            %
            display_images_full_image_view(app)
            %
            handle_button_click(app.seg_tool_base, ...
                app.([button_id, 'Button']),'off')
            %
        end
        %
        function do_trash(app)
            %%
            % allow the user to select a cell to delete and handle all 
            % buttons
            %%
            trash = app.TrashButton.Value;
            %
            handle_button_interrupt(app)
            %
            if ~trash
                display_images_full_image_view(app)
                return
            end
            %
            handle_button_click(app.seg_tool_base, app.TrashButton,'on')               
            %
            apply_seg(app)
            %
            delete_object(app.seg_tool_base, app)
            %
            recompile_and_display_full_image_view(app)
            %
            handle_button_click(app.seg_tool_base, app.TrashButton,'off')
            %
        end
        %
        function apply_seg(app)
            %%
            % turn on the applied segmentation mask 
            %%
            if ~app.AppliedSegButton.Value
                toggle_seg(app)
                display_images_full_image_view(app)
            end
            %
        end
        %
        function toggle_seg(app)
            %%
            % turn on the applied segmentation mask 
            %%
            handle_button_click(app.seg_tool_base, ...
                app.AppliedSegButton, 'on')
            handle_button_click(app.seg_tool_base, ...
                app.MaskedViewButton, 'off')
            handle_button_click(app.seg_tool_base, ...
                app.BackgroundMaskButton, 'off')
            %
        end
        %
        function reject_and_exit_app(app)
            %
            handle_button_interrupt(app)
            handle_button_click(app.seg_tool_base, app.RejectButton, 'on');
            %
            title = 'Confirm "Reject"';
            msg = ['Reject all edits and ',...
                   'return to main application?'];
            del_var = uiconfirm(app.fullimageViewUI, msg, title,...
                'Options', {'Confirm', 'Cancel'},...
                'DefaultOption', 1, 'CancelOption', 2);
            %
            if strcmp(del_var, 'Confirm')
                %
                % delete the app do not return anything
                %
                delete(app)
                %
            else
                %
                % for cancel, reset reject and return to app
                %
                handle_button_click(app.seg_tool_base, app.RejectButton, 'off');
                %
            end
            %
        end
        %
        function handle_button_interrupt(app)
            %%
            % resets the current cell object to null and handles button
            % interuption states
            %%
            %
            app.seg_tool_base.esc()
            %
            % removes any data for the 'new' cell
            % if a button is interrupted we don't keep
            % the drawn object, same for display option
            %
            pairid = app.seg_tool_base.current_cell_pairid_index(app);
            %
            if ~isempty(app.paired_cells(pairid))
                app.draw_im (app.draw_im  == pairid) = 0;
                app.paired_cells(pairid) = {0};
                recompile_and_display_full_image_view(app)
            end
            %
            handle_button_click(app.seg_tool_base, app.DrawButton, 'off')
            handle_button_click(app.seg_tool_base, app.FlagButton,'off')
            handle_button_click(app.seg_tool_base, app.MultiNucButton,'off')
            handle_button_click(app.seg_tool_base, app.TrashButton,'off')
            %
        end
        %
        function define_bounding_box(app)
            %%
            %
            %%
            top = 1;
            bottom = app.seg_tool_base.nRows;
            left = 1;
            right = app.seg_tool_base.nCols;
            %
            app.seg_tool_base.bounding_box.top = top;
            app.seg_tool_base.bounding_box.bottom = bottom;
            app.seg_tool_base.bounding_box.left = left;
            app.seg_tool_base.bounding_box.right = right;
            %
            app.seg_tool_base.bounding_box.cols = [1, right - left + 1];
            app.seg_tool_base.bounding_box.rows = [1, bottom - top + 1];
            app.seg_tool_base.bounding_box.size = ...
                [bottom - top + 1, right - left + 1];
            app.seg_tool_base.bounding_box.area = ...
                app.seg_tool_base.bounding_box.size(1) * ...
                app.seg_tool_base.bounding_box.size(2);
            app.seg_tool_base.bounding_box.template_image = zeros(...
                [app.seg_tool_base.bounding_box.size, 2]);
            %
        end
        %
        function set_active_select_axis_and_ui_full_image_view(app)
            %
            app.seg_tool_base.object_handles = findall(...
                app.fullimageViewUI, '-property', 'Enable');
            %
            app.seg_tool_base.axis_to_select_on = app.ImageAxes;
            app.seg_tool_base.active_ui = app.fullimageViewUI;
            app.seg_tool_base.active_app = ...
                app.seg_tool_base.apps.full_image_view;
            %
        end
        %
        function handle_segmentation_mask_click(app, button_id)
            %%
            % when a segmentation mask is clicked, turn off all
            % segmentation buttons, if value was turned on turn it on, then
            % redisplay the images 
            %%
            button_value = app.([button_id, 'Button']).Value;
            %
            handle_button_click(...
                app.seg_tool_base, app.AppliedSegButton,'off')
            handle_button_click(...
                app.seg_tool_base, app.MaskedViewButton,'off')
            handle_button_click(...
                app.seg_tool_base, app.BackgroundMaskButton,'off')
            %
            if button_value
                handle_button_click(app.seg_tool_base, ...
                    app.([button_id, 'Button']),'on')
                if ~strcmp(button_id, 'AppliedSeg')
                    handle_button_click(...
                        app.seg_tool_base, app.FlagMaskButton,'off')
                    handle_button_click(...
                        app.seg_tool_base, app.MultiNucMaskButton,'off')
                end
                %
            else
                %
                handle_button_click(...
                    app.seg_tool_base, app.FlagMaskButton,'off')
                handle_button_click(...
                    app.seg_tool_base, app.MultiNucMaskButton,'off')
                %
            end
            %
            display_images_full_image_view(app)
            %
        end
        %
        function handle_flag_mask_click(app, button_id)
            %%
            % when a segmentation mask is clicked, turn off all
            % segmentation buttons, if value was turned on turn it on, then
            % redisplay the images 
            %%
            button_value = app.([button_id, 'MaskButton']).Value;
            %
            handle_button_click(...
                app.seg_tool_base, app.FlagMaskButton,'off')
            handle_button_click(...
                app.seg_tool_base, app.MultiNucMaskButton,'off')
            %
            if button_value
                handle_button_click(app.seg_tool_base, ...
                    app.([button_id, 'MaskButton']),'on')
                toggle_seg(app)
            end
            %
            display_images_full_image_view(app)
            %
        end
        %
        function full_image_save(app)
            %
            app.paired_cells(...
                app.seg_tool_base.current_cell_pairid_index(app)) = [];
            app.overlap_table(app.current_cell_table_index, :) = [];
            %
            return_to_seg_base(app.seg_tool_base, app)
            %
            set(app.SaveTableButton,'Enable', 'off')
            handle_button_click(app.seg_tool_base, app.SaveTableButton, 'on')
            save_protocol(app.seg_tool_base);
            handle_button_click(app.seg_tool_base, app.SaveTableButton, 'off')
            set(app.SaveTableButton,'Enable', 'on')
            %
            app.cells_added_count = app.cells_added_count - 1;
            add_cell_row(app.seg_tool_base, app)
            recompile_and_display_full_image_view(app)
            %
        end
        %
    end
    %
 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            %
            % recieve variables from main app
            %
            app.seg_tool_base = mainapp;  
            app.overlap_table = app.seg_tool_base.overlap_table;
            app.paired_cells = app.seg_tool_base.paired_cells;
            app.draw_im = app.seg_tool_base.draw_im;
            %
            app.im_xlim = [1, app.seg_tool_base.nCols];
            app.im_ylim = [1, app.seg_tool_base.nRows];
            %
            app.x_max_tiles = ceil( ...
                app.seg_tool_base.nCols / app.grid_size); 
            app.y_max_tiles = ceil( ...
                app.seg_tool_base.nRows / app.grid_size);
            %
            define_bounding_box(app)
            %
            % Configure image axes
            %
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            %
            xlim(app.ImageAxes, app.im_xlim)
            ylim(app.ImageAxes, app.im_ylim)
            %
            if ~app.seg_tool_base.display_add_cell_instructions
               msg = ['Please use this window to clean up annotations. ',...
                   'Keep an eye out for oversegmentation (one cell split ',...
                   'into two), undersegmentation (two cells merged as one) ',...
                   'and cells that were missed on the first pass.'];
                [~] = uiconfirm(app.fullimageViewUI, msg,'Helpful hints',...
                    'Icon','info', 'Options',{'OK'}, 'DefaultOption',1);
            end
            %
            set_active_select_axis_and_ui_full_image_view(app)
            %
            draw_ui_instructions(app.seg_tool_base)
            %
            add_cell_row(app.seg_tool_base, app)
            handle_button_click(app.seg_tool_base, app.AppliedSegButton,'on')
            %
            im_c = get_image_region(app.seg_tool_base, ...
                app.seg_tool_base.input_color_vectors);
            app.seg_tool_base.region_color_vectors = reshape(im_c, [], 2);
            apply_image_colors(app.seg_tool_base, app)
            %
            recompile_and_display_full_image_view(app)
            %
        end

        % Value changed function: MEMButton
        function MEMButtonValueChanged(app, event)
            %
            MEM_click(app.seg_tool_base, app, 'off')
            %
        end

        % Value changed function: DAPIButton
        function DAPIButtonValueChanged(app, event)
            %
            DAPI_click(app.seg_tool_base, app, 'off')
            %  
        end

        % Value changed function: BrightnessSlider, ContrastSlider
        function ColorSliderValueChanged(app, event)
            %
            apply_image_colors(app.seg_tool_base, app)
            display_images_full_image_view(app)
            %
        end

        % Value changed function: ShowMemButton
        function ShowMemButtonValueChanged(app, event)
            %
            if app.ShowMemButton.Value
                handle_button_click(app.seg_tool_base, ...
                    app.ShowMemButton,'on')
            else
                handle_button_click(app.seg_tool_base, ...
                    app.ShowMemButton,'off')
            end
            %
            display_images_full_image_view(app)
            %
        end

        % Value changed function: AppliedSegButton
        function AppliedSegButtonValueChanged(app, event)
            %
            handle_segmentation_mask_click(app, 'AppliedSeg')
            %
        end

        % Value changed function: MaskedViewButton
        function MaskedViewButtonValueChanged(app, event)
            %
            handle_segmentation_mask_click(app, 'MaskedView')
            %
        end

        % Value changed function: BackgroundMaskButton
        function BackgroundMaskButtonValueChanged(app, event)
            %
            handle_segmentation_mask_click(app, 'BackgroundMask')
            %
        end

        % Value changed function: FlagMaskButton
        function FlagMaskButtonValueChanged(app, event)
            %
            handle_flag_mask_click(app, 'Flag')
            %
        end

        % Value changed function: MultiNucMaskButton
        function MultiNucMaskButtonValueChanged(app, event)
            %
            handle_flag_mask_click(app, 'MultiNuc')
            %
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            %
            if app.XEditField.Value == 0
                app.im_xlim = [1, app.seg_tool_base.nCols];
                app.im_ylim = [1, app.seg_tool_base.nRows];
            else
                app.im_xlim = xlim(app.ImageAxes);
                app.im_ylim = ylim(app.ImageAxes);
            end
            %
            app.tiled_init = true;
            %
            display_images_full_image_view(app)
            %
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            %
            if app.YEditField.Value == 0
                app.im_xlim = [1, app.seg_tool_base.nCols];
                app.im_ylim = [1, app.seg_tool_base.nRows];
            else
                app.im_xlim = xlim(app.ImageAxes);
                app.im_ylim = ylim(app.ImageAxes);
            end
            %
            app.tiled_init = true;
            %
            display_images_full_image_view(app)
            %
        end

        % Value changed function: DrawButton
        function DrawButtonValueChanged(app, event)
            %
            button_value = app.DrawButton.Value;
            handle_button_interrupt(app)
            %
            if button_value
                %
                handle_button_click(app.seg_tool_base, app.DrawButton, ...
                    'on')
                do_draw(app.seg_tool_base, app, app.ImageAxes)
                toggle_seg(app)
                add_cell_row(app.seg_tool_base, app)
                recompile_and_display_full_image_view(app)
                %
            else
                display_images_full_image_view(app)
            end
            %
            handle_button_click(app.seg_tool_base, app.DrawButton, 'off')
            %
        end

        % Value changed function: MultiNucButton
        function MultiNucButtonValueChanged(app, event)
            %
            do_flag(app, 'MultiNuc')
            %
        end

        % Value changed function: FlagButton
        function FlagButtonValueChanged(app, event)
            %
            do_flag(app, 'Flag')
            %
        end

        % Value changed function: TrashButton
        function TrashButtonValueChanged(app, event)
            %
            do_trash(app)
            % 
        end

        % Value changed function: ObjectPlaneSpinner
        function ObjectPlaneSpinnerValueChanged(app, event)
           %
            if app.cells_added_count > 0
                %
                app.overlap_table.ground_level(...
                    app.current_cell_table_index - 1) ...
                    = app.ObjectPlaneSpinner.Value;
                recompile_and_display_full_image_view(app)
                %
            else 
                %
                app.ObjectPlaneSpinner.Value = 1;
                %
            end
            %
            handle_button_interrupt(app)
            %
        end

        % Value changed function: AcceptButton
        function AcceptButtonValueChanged(app, event)
            %
            handle_button_interrupt(app)
            handle_button_click(app.seg_tool_base, app.AcceptButton, 'on');
            %
            msg = 'Accept drawn cell segmentation and close?';
            title = 'Confirm "Accept"';
            selection = uiconfirm(app.fullimageViewUI,msg,title);
            %
            if strcmp(selection,'OK')
                %
                app.paired_cells(...
                    app.seg_tool_base.current_cell_pairid_index(app)) = [];
                app.overlap_table(app.current_cell_table_index, :) = [];
                %
                return_to_seg_base(app.seg_tool_base, app)
                %
                delete(app)
            else
                handle_button_click(app.seg_tool_base, ...
                    app.AcceptButton, 'off');
            end
            %
        end

        % Value changed function: RejectButton
        function RejectButtonValueChanged(app, event)
            %
            reject_and_exit_app(app)
            %
        end

        % Close request function: fullimageViewUI
        function fullimageViewUICloseRequest(app, event)
            %
            reject_and_exit_app(app)
            %
        end

        % Value changed function: SaveTableButton
        function SaveTableButtonValueChanged(app, event)
            full_image_save(app)
        end

        % Value changed function: iButton
        function iButtonValueChanged(app, event)
            %
            set(app.seg_tool_base.object_handles,'Enable', 'off')
            %
            handle_button_click(app.seg_tool_base, app.iButton, 'on')
            settings_app = Settings(app.seg_tool_base);
            waitfor(settings_app)
            handle_button_click(app.seg_tool_base, app.iButton, 'off')
            %
            set(app.seg_tool_base.object_handles,'Enable', 'on')
            %
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.fullimageViewUI = uifigure('Visible', 'off');
            app.fullimageViewUI.Name = 'MATLAB App';
            app.fullimageViewUI.CloseRequestFcn = createCallbackFcn(app, @fullimageViewUICloseRequest, true);
            app.UIGridLayout = uigridlayout(app.fullimageViewUI);
            app.RightSideGridLayout = uigridlayout(app.UIGridLayout);
            app.ButtonsPanel = uipanel(app.RightSideGridLayout);
            app.ButtonsGridLayout = uigridlayout(app.ButtonsPanel);
            app.ToolGridLayout = uigridlayout(app.ButtonsGridLayout);
            app.DrawButton = uibutton(app.ToolGridLayout, 'state');
            app.DrawButton.ValueChangedFcn = createCallbackFcn(app, @DrawButtonValueChanged, true);
            app.DrawButton.Tooltip = {'Draw an annotation. Left click and hold to draw, right click to close the annotation.'};
            app.DrawButton.Icon = 'drawing_icon.png';
            app.DrawButton.Text = '';
            app.MultiNucButton = uibutton(app.ToolGridLayout, 'state');
            app.MultiNucButton.ValueChangedFcn = createCallbackFcn(app, @MultiNucButtonValueChanged, true);
            app.MultiNucButton.Tooltip = {'Delineate a cell as multi-nucleated. Click on the icon, then click the cell. Repeat to remove.'};
            app.MultiNucButton.Icon = 'multi_nucleated_cell_icon.png';
            app.MultiNucButton.Text = '';
            app.FlagButton = uibutton(app.ToolGridLayout, 'state');
            app.FlagButton.ValueChangedFcn = createCallbackFcn(app, @FlagButtonValueChanged, true);
            app.FlagButton.Tooltip = {'Flag a cell to come back to or remove a flag. Click on the icon, then click the cell to flag. Repeat to remove.'};
            app.FlagButton.Icon = 'flag_icon.png';
            app.FlagButton.Text = '';
            app.TrashButton = uibutton(app.ToolGridLayout, 'state');
            app.TrashButton.ValueChangedFcn = createCallbackFcn(app, @TrashButtonValueChanged, true);
            app.TrashButton.Tooltip = {'Remove an object.'};
            app.TrashButton.Icon = 'trash_can_icon.png';
            app.TrashButton.Text = '';
            app.ObjectPlaneSpinner = uispinner(app.ToolGridLayout);
            app.ObjectPlaneSpinner.Limits = [-5 5];
            app.ObjectPlaneSpinner.RoundFractionalValues = 'on';
            app.ObjectPlaneSpinner.ValueDisplayFormat = '%.0f';
            app.ObjectPlaneSpinner.ValueChangedFcn = createCallbackFcn(app, @ObjectPlaneSpinnerValueChanged, true);
            app.ObjectPlaneSpinner.Tooltip = {'Moves the object in front of or behind other objects.'};
            app.ObjectPlaneSpinner.Value = 1;
            app.ObjectPlaneSpinnerLabel = uilabel(app.ToolGridLayout);
            app.ObjectPlaneSpinnerLabel.Text = 'Object Plane';
            app.DisplayPanel = uipanel(app.ButtonsGridLayout);
            app.DisplayGridLayout = uigridlayout(app.DisplayPanel);
            app.DisplayLabel = uilabel(app.DisplayGridLayout);
            app.DisplayLabel.Text = ' Display';
            app.ImageColorsGridLayout = uigridlayout(app.DisplayGridLayout);
            app.DAPIButton = uibutton(app.ImageColorsGridLayout, 'state');
            app.DAPIButton.ValueChangedFcn = createCallbackFcn(app, @DAPIButtonValueChanged, true);
            app.DAPIButton.Tooltip = {'Change DAPI brightness or contrast.'};
            app.DAPIButton.Text = 'DAPI';
            app.MEMButton = uibutton(app.ImageColorsGridLayout, 'state');
            app.MEMButton.ValueChangedFcn = createCallbackFcn(app, @MEMButtonValueChanged, true);
            app.MEMButton.Tooltip = {'Change membrane brightness or contrast.'};
            app.MEMButton.Text = 'MEM';
            app.ShowMemButton = uibutton(app.ImageColorsGridLayout, 'state');
            app.ShowMemButton.ValueChangedFcn = createCallbackFcn(app, @ShowMemButtonValueChanged, true);
            app.ShowMemButton.Tooltip = {'Toggle the membrane stain on and off.'};
            app.ShowMemButton.Icon = 'show_membrane_icon.png';
            app.ShowMemButton.Text = '';
            app.ContrastSliderLabel = uilabel(app.ImageColorsGridLayout);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.ImageColorsGridLayout);
            app.ContrastSlider.MajorTicks = [0 25 50 75 100];
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ColorSliderValueChanged, true);
            app.ContrastSlider.MinorTicks = [];
            app.ContrastSlider.Value = 50;
            app.BrightnessSliderLabel = uilabel(app.ImageColorsGridLayout);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.ImageColorsGridLayout);
            app.BrightnessSlider.MajorTicks = [0 25 50 75 100];
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @ColorSliderValueChanged, true);
            app.BrightnessSlider.MinorTicks = [];
            app.BrightnessSlider.Value = 50;
            app.MasksGridLayout = uigridlayout(app.DisplayGridLayout);
            app.AppliedSegButton = uibutton(app.MasksGridLayout, 'state');
            app.AppliedSegButton.ValueChangedFcn = createCallbackFcn(app, @AppliedSegButtonValueChanged, true);
            app.AppliedSegButton.Tooltip = {'Show the current cell segmentation.'};
            app.AppliedSegButton.Icon = 'show_applied_seg_icon.png';
            app.AppliedSegButton.Text = '';
            app.AppliedSegButton.Value = true;
            app.MaskedViewButton = uibutton(app.MasksGridLayout, 'state');
            app.MaskedViewButton.ValueChangedFcn = createCallbackFcn(app, @MaskedViewButtonValueChanged, true);
            app.MaskedViewButton.Tooltip = {'Mask out regions already annotated in black.'};
            app.MaskedViewButton.Icon = 'foreground_icon.png';
            app.MaskedViewButton.Text = '';
            app.BackgroundMaskButton = uibutton(app.MasksGridLayout, 'state');
            app.BackgroundMaskButton.ValueChangedFcn = createCallbackFcn(app, @BackgroundMaskButtonValueChanged, true);
            app.BackgroundMaskButton.Tooltip = {'Mask out regions not annotated in grey.'};
            app.BackgroundMaskButton.Icon = 'background_icon.png';
            app.BackgroundMaskButton.Text = '';
            app.FlagMaskButton = uibutton(app.MasksGridLayout, 'state');
            app.FlagMaskButton.ValueChangedFcn = createCallbackFcn(app, @FlagMaskButtonValueChanged, true);
            app.FlagMaskButton.Tooltip = {'mask the flagged cells.'};
            app.FlagMaskButton.Icon = 'flag_icon.png';
            app.FlagMaskButton.Text = '';
            app.MultiNucMaskButton = uibutton(app.MasksGridLayout, 'state');
            app.MultiNucMaskButton.ValueChangedFcn = createCallbackFcn(app, @MultiNucMaskButtonValueChanged, true);
            app.MultiNucMaskButton.Tooltip = {'mask the multi-nucleated cells.'};
            app.MultiNucMaskButton.Icon = 'multi_nucleated_cell_icon.png';
            app.MultiNucMaskButton.Text = '';
            app.XEditFieldLabel = uilabel(app.MasksGridLayout);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.MasksGridLayout, 'numeric');
            app.XEditField.Limits = [0 20];
            app.XEditField.RoundFractionalValues = 'on';
            app.XEditField.ValueDisplayFormat = '%.0f';
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.XEditField.Tooltip = {'Use the X and Y values to move across the image in a grid of 200 x 200 pixels. Regions outside the tile will be grayed out. Set X or Y to 0 to turn off.'};
            app.YEditFieldLabel = uilabel(app.MasksGridLayout);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.MasksGridLayout, 'numeric');
            app.YEditField.Limits = [0 20];
            app.YEditField.ValueDisplayFormat = '%.0f';
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.YEditField.Tooltip = {'Use the X and Y values to move across the image in a grid of 200 x 200 pixels. Regions outside the tile will be grayed out. Set X or Y to 0 to turn off.'};
            app.ExitButtonsPanel = uipanel(app.RightSideGridLayout);
            app.ExitButtonsGridLayout = uigridlayout(app.ExitButtonsPanel);
            app.AcceptButton = uibutton(app.ExitButtonsGridLayout, 'state');
            app.AcceptButton.ValueChangedFcn = createCallbackFcn(app, @AcceptButtonValueChanged, true);
            app.AcceptButton.Tooltip = {'Exit and save work'};
            app.AcceptButton.Icon = 'done_icon.png';
            app.AcceptButton.Text = '';
            app.RejectButton = uibutton(app.ExitButtonsGridLayout, 'state');
            app.RejectButton.ValueChangedFcn = createCallbackFcn(app, @RejectButtonValueChanged, true);
            app.RejectButton.Tooltip = {'Exit and DON''T save any work.'};
            app.RejectButton.Icon = 'reject_icon.png';
            app.RejectButton.Text = '';
            app.iButton = uibutton(app.ExitButtonsGridLayout, 'state');
            app.iButton.ValueChangedFcn = createCallbackFcn(app, @iButtonValueChanged, true);
            app.iButton.Tag = 'i';
            app.iButton.Tooltip = {'change the settings for the input image.'};
            app.iButton.Icon = 'settings_icon.png';
            app.iButton.Text = '';
            app.SaveTableButton = uibutton(app.ExitButtonsGridLayout, 'state');
            app.SaveTableButton.ValueChangedFcn = createCallbackFcn(app, @SaveTableButtonValueChanged, true);
            app.SaveTableButton.Tooltip = {'Save the table.'};
            app.SaveTableButton.Icon = 'save_icon.svg';
            app.SaveTableButton.Text = '';
            app.Panel = uipanel(app.UIGridLayout);
            app.LeftSideGridLayout = uigridlayout(app.Panel);
            app.ImageAxes = uiaxes(app.LeftSideGridLayout);
            title(app.ImageAxes, '')
            xlabel(app.ImageAxes, '')
            ylabel(app.ImageAxes, '')
            app.ImageAxes.XAxisLocation = 'origin';
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = '';
            app.ImageAxes.YTick = [];
            app.ImageAxes.YTickLabel = {};
            app.ImageAxes.ZTick = [0 0.5 1];
            app.ImageAxes.ZTickLabel = '';
            % Show the figure after all components are created
            app.fullimageViewUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fullimageView(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.fullimageViewUI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.fullimageViewUI)
        end
    end
end