classdef SegTool_5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LoadButton                      matlab.ui.control.Button
        MachineLearningSegmentationLabel  matlab.ui.control.Label
        inFormSegmentationLabel         matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        GobacktolastcellButton          matlab.ui.control.Button
        GotonextcellButton              matlab.ui.control.Button
        SegOptions                      matlab.ui.container.ButtonGroup
        SelectMLSegmentationButton      matlab.ui.control.RadioButton
        SelectinFormSegmentationButton  matlab.ui.control.RadioButton
        DrawNewSegmentationButton       matlab.ui.control.RadioButton
        RejectCellButton                matlab.ui.control.RadioButton
        Panel_2                         matlab.ui.container.Panel
        BrightnessSliderLabel           matlab.ui.control.Label
        BrightnessSlider                matlab.ui.control.Slider
        ContrastSliderLabel             matlab.ui.control.Label
        ContrastSlider                  matlab.ui.control.Slider
        ButtonGroup                     matlab.ui.container.ButtonGroup
        DAPIButton                      matlab.ui.control.RadioButton
        MembraneButton                  matlab.ui.control.RadioButton
        ToggleSegmentationButton        matlab.ui.control.StateButton
        Panel_3                         matlab.ui.container.Panel
        SaveTableButton                 matlab.ui.control.Button
    end

    
    properties (Access = public)
        im_ML % Machine learning label matrix
        im_IF % inForm label matrix
        nRows % rows
        nCols % columns
        mem_dapi_im % dapi and membrane component layers in column vector format
        mycols = [0 0 1; % DAPI
                 1 1 1];   % Membrane
       wd % directory with the inform and ML_data subfolders in it
       fname % current image name
       a_m = 2.5;
       a_d = 2.5;
       b_m = 200;
       b_d = 200;
       IF_cells % cell array of cells in inForm label matrix
       ML_cells % cell array of cells in ML label matrix
       m % temporary centroid or midpoint variable passed between functions, created from label matrix 
       IF_table % intial table with all inForm cells in it
       ML_table %intial table with all machine learning cells in it
       cells_n % cells variable passed between functions
       q % temporary table
       overlap_table % final\ resulting table with overlaps and other relevant info
       o_tbl_IF
       o_tbl_ML
       n % current cell row for analysis
       ML_Image
       ML_Image_2
       IF_Image
       IF_Image_2
       %
    end
    
    methods (Access = private)
        
        function updateimage(app,imagefile)
            %
            ML_fd = 'ML_data';
            app.fname = extractBetween(imagefile,'Component_Tiffs\','_component');
            app.fname = app.fname{1};
            app.wd = extractBefore(imagefile, ['\',ML_fd,'\Component_Tiffs']);
            % Get the ML image
            try
                for i1 = 1:2
                    im_ML_tmp(:,:,i1) = imread(imagefile, i1);
                end
                app.im_ML = im_ML_tmp;
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Finding ML Image Error');
                return;
            end            
            %
            % get the inform image name and number of layers
            %
            imagefile2 = replace(imagefile, ML_fd, 'inform_data');
            imagefile2 = replace(imagefile2,'_seg','_w_seg');
            %
            try 
                im_info = imfinfo(imagefile2);
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Finding inForm Image Error');
                return;
            end
            %
            layers = length(im_info);
            app.nRows = im_info(1).Height;
            app.nCols = im_info(1).Width;
            %
            if layers ~= 15
               uialert(app.UIFigure, 'inForm Image Error: MISSING IMAGE LAYERS');
               return;
            end
            %
            % read INFORM IMAGE
            %
            ic = 0;
            for i1 = 11:15
                ic = ic + 1;
                im_IF_tmp1(:,:,ic) = imread(imagefile2,i1);
            end
            app.im_IF = im_IF_tmp1;
            %
            for i1 = [1, 9]
                im = imread(imagefile2, i1);
                im = im ./ max(im,[],'all');
                im_IF_tmp(:,i1) = reshape(im, [], 1);
            end
            % 
            % Display DAPI and Membrane
            %
            app.mem_dapi_im = im_IF_tmp(:,[1 9]);
            %
            comp_p_file_IF = [app.wd,'\inform_data\Component_Tiffs\',app.fname,'_overlap_table.mat'];
            %
            if exist(comp_p_file_IF,'file')
               x = load(comp_p_file_IF);
               app.overlap_table = x.x;
            else
                compute_pairs(app)
            end
            %
            app.n = 1;
            disp_images(app,1,app.n,height(app.overlap_table))
        end
        
        function compute_pairs(app)
            %%
            %
            % this is where the tables are computed for cells and cell overlap pct is
            % assigned
            %
            %%
            %
            % ML cell table
            %
            app.cells_n = label2idx(app.im_ML)';
            im = app.im_ML(:,:,1);
            m1 = regionprops(im,'centroid');
            app.m = struct2cell(m1);
            crt_int_tbl(app, 'ML');
            %
            % get distinct objects in inform
            %
            getIFdistinct(app);
            %
            % create the IF table
            %
            crt_int_tbl(app, 'IF');
            %
            % get the cells which pixels overlap with ML
            %
            comp_overlap(app);
            app.q.jp = app.q.IF_pct .* app.q.ML_pct;
            %
            app.q.jp = app.q.IF_pct .* app.q.ML_pct;
            %
            add_no(app);
            x = app.q;
            x.include_cell = zeros(height(x),1);
            c = zeros(height(x),1);
            c = num2cell(c);
            x.final_cells = c;
            x.final_cell_x = c;
            x.final_cell_y = c;
            %
            app.overlap_table = x;
            %
            save([app.wd,'\inform_data\Component_Tiffs\',app.fname,'_overlap_table'],'x')
            %
        end
        
        function crt_int_tbl(app, tt)
            %%
            % intialize the table for the overlap computation
            %
            %%
            cells_n1 = app.cells_n;
            m1 = app.m;
            m1 = cell2mat(m1);
            m_o = m1(1:2:end);
            m_e = m1(2:2:end);
            %
            tbl = table();
            tbl.([tt,'_cellid']) = (1:length(cells_n1))';
            tbl.([tt,'_cells']) = cells_n1;
            tbl.([tt,'_X_centroid']) = m_o';
            tbl.([tt,'_Y_centroid']) = m_e';
            tbl.([tt,'_n_pixels']) = cellfun('length',cells_n1);
            %
            if strcmp(tt, 'ML')
                app.ML_table = tbl;
            elseif strcmp(tt,'IF')
                app.IF_table = tbl;
            end
            %
        end
        
        function getIFdistinct(app)
            %%
            % get the distinct cell objects from both inform cell segmentation layers
            %
            %%
            %
            % get distinct objects in inform
            %
            im = app.im_IF(:,:,2);
            IF_cells_1 = label2idx(im);
            m1 = regionprops(im,'centroid');
            m1 = struct2cell(m1);
            %
            im = app.im_IF(:,:,3);
            IF_cells_2 = label2idx(im);
            m2 = regionprops(im,'centroid');
            m2 = struct2cell(m2);
            %
            if length(IF_cells_1) > length(IF_cells_2)
                nn = length(IF_cells_1);
            else
                nn = length(IF_cells_2);
            end
            %
            IF_cellso = cell(nn, 1);
            mo = cell(1,nn);
            %
            ii = ~cellfun(@isempty,IF_cells_1);
            IF_cellso(ii) = IF_cells_1(ii);
            mo(ii) = m1(ii);
            %
            ii = ~cellfun(@isempty,IF_cells_2);
            IF_cellso(ii) = IF_cells_2(ii);
            mo(ii) = m2(ii);
            %
            app.cells_n = IF_cellso;
            app.m = mo;
            %
        end
        
        function comp_overlap(app)
            %%
            % This function computes the overlaps and adds them to the appropriate rows
            % in the table
            %
            %%
            o = [];
            for level = 1:2
                im1 = app.im_IF(:,:,level+1);
                overlap = im1 > 0 & app.im_ML > 0;
                pairs = [im1(overlap), app.im_ML(overlap)];
                [ii, ~, kk] = unique(pairs, 'rows');
                pairs_cnt = [ii,accumarray(kk,1)];
                %
                idx1 = pairs_cnt(:,1);
                idx2 = pairs_cnt(:,2);
                %
                crt_ovr_tbl(app,idx1,...
                    pairs_cnt(:,2) ,pairs_cnt(:,3), 'IF');
                %
                crt_ovr_tbl(app,idx2,...
                    pairs_cnt(:,1) ,pairs_cnt(:,3), 'ML');
                %
                tbl = outerjoin(app.o_tbl_IF,app.o_tbl_ML,'MergeKeys',1);
                %
                tbl.IF_level = repmat(level, height(tbl),1);
                o = [o;tbl];
            end
            %
            app.q = o;
            %
        end
        
        function crt_ovr_tbl(app,idx,pairs,cnt,tt)
            %%
            % define the table with the overlaps in it
            %
            %%
            if strcmp(tt, 'ML')
                tbl_in = app.ML_table;
            elseif strcmp(tt, 'IF')
                tbl_in = app.IF_table;
            end
            %
            tbl = tbl_in(idx,:);
            tbl.([tt,'_overlap']) =  cnt;
            tbl.([tt,'_pct']) = double(tbl.([tt,'_overlap']))...
                ./ tbl.([tt,'_n_pixels']);
            tbl.([tt,'_paired_w']) = pairs;
            tbl.cellid = (1:height(tbl))';
            %
            if strcmp(tt, 'ML')
                app.o_tbl_ML = tbl;
            elseif strcmp(tt, 'IF')
                app.o_tbl_IF = tbl;
            end
            %
        end
        
        function add_no(app)
            %
            tbl = app.q;
            colnames = tbl.Properties.VariableNames;
            %
            z = height(tbl);
            IF_tableo = app.IF_table;
            idx1 = unique(IF_tableo.IF_cellid);
            idx2 = unique(tbl.IF_cellid);
            ii = ismember(idx1, idx2);
            %
            no_o_tbl(app, ii, IF_tableo, colnames, z);
            %
            z = height(app.q);
            ML_tableo = app.ML_table;
            idx1 = unique(ML_tableo.ML_cellid);
            idx2 = unique(tbl.ML_cellid);
            ii = ismember(idx1, idx2);
            %
            no_o_tbl(app, ii, ML_tableo, colnames, z);
            %
        end
        
        function no_o_tbl(app, idx, tbl_in, colnames, z)
            %%
            % create the table of the size of the overlap table for cells without
            % overlaps
            %
            %%
            no_tbl = tbl_in;
            no_tbl(idx,:) = [];
            no_tbl.cellid = (z + 1:z + height(no_tbl))';
            colnames_no = no_tbl.Properties.VariableNames;
            ii = ~ismember(colnames, colnames_no);
            tbl = array2table(zeros(height(no_tbl),sum(ii)));
            tbl = [no_tbl, tbl];
            tbl.Properties.VariableNames = [colnames_no,colnames(ii)];
            %
            tt = {'IF','ML'};
            for i1 = 1:2
                tbl.([tt{i1},'_cells']) = num2cell(tbl.([tt{i1},'_cells']));
            end
            %
            app.q = [app.q; tbl];
            %
        end
        
        function disp_images(app, nm, n1, nall)
            if nm
                ff = app.fname;
                if nargin > 2
                    n1 = num2str(n1);
                    nall = num2str(nall);
                    app.UIFigure.Name = ['Segmentation Tool: ',ff, '; Cell: ', n1, 'of', nall];
                else
                    app.UIFigure.Name = ['Segmentation Tool: ',ff];
                end
            end
            %
            imshow2(app, app.ML_Image, 1, 1)
            imshow2(app, app.ML_Image_2, 2, 1)
            %
            imshow2(app, app.IF_Image, 1, 2)
            imshow2(app, app.IF_Image_2, 2, 2)
            %
        end
        
        function imshow2(app, loc, layer, s_layer)
            %
            C = app.nCols;
            R = app.nRows;
            %
            im = app.mem_dapi_im;
            mycols_n = app.mycols;
            %
            % DAPI image
            %
            ii = app.DAPIButton.Value;
            if ii
                app.a_d = .05 * app.ContrastSlider.Value;
                app.b_d = 4 * app.BrightnessSlider.Value;
            end
            im_d =  app.b_d .* asinh(app.a_d .* im(:,1)) * mycols_n(1,:);
            %
            % Membrane image
            %
            if layer ~= 1
                ii = app.MembraneButton.Value;
                if ii
                    app.a_m = .05 * app.ContrastSlider.Value;
                    app.b_m = 4 * app.BrightnessSlider.Value;
                end
                im =  app.b_m .* asinh(app.a_m .* im(:,2)) * mycols_n(2,:);
                %
                im_d = im + im_d;
            end
            %
            %
            % if the cell counter is filled then cut that cell out of the
            % image
            %
            if ~isempty(app.n)
                inp = app.n;
                x = app.overlap_table;
                if x.IF_n_pixels(inp) > x.ML_n_pixels(inp)
                    x_center = x.IF_X_centroid(inp);
                    y_center = x.IF_Y_centroid(inp);
                else
                    x_center = x.ML_X_centroid(inp);
                    y_center = x.ML_Y_centroid(inp);
                end
                %
                ll = floor(x_center - 50);
                rr = floor(x_center + 50);
                tt = floor(y_center - 50);
                bb = floor(y_center + 50);
                %
                % if segmentation is turned on apply a segmentation masks
                %
                ii = app.ToggleSegmentationButton.Value;
                if ~ii
                    if s_layer == 2
                        ii = x.IF_cells{inp};
                    else
                        ii = x.ML_cells{inp};
                    end
                    aa = .35;
                    im2 = zeros(length(ii), 3);
                    im2(:,1) = 255;
                    im_d(ii,:) = (1-aa) .* im_d(ii,:) + aa.* im2(:, :);
                end
                im = reshape(im_d, R, C, 3);
                %
                if tt < 1
                    tt = 1;
                end
                if bb > R
                    bb = R; 
                end
                if ll < 1
                    ll = 1;
                end
                if rr > C
                    rr = C;
                end
                im = im(tt(1):bb(1),ll(1):rr(1),:);
                %
                im_d = reshape(im,[],3);
                R = bb(1) - tt(1) + 1;
                C = rr(1) - ll(1) + 1;
            end
            %
            im = reshape(im_d,R,C,3);
            im = uint8(im);
            %
            imshow(im, 'Parent',loc);
            %
        end
        %
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Configure image axes
            app.ML_Image = axes('Parent',app.Panel_3);
            app.ML_Image.Box = 'on';
            app.ML_Image.XTick = [];
            app.ML_Image.XTickLabel = [];
            app.ML_Image.YTick = [];
            app.ML_Image.ZTick = [];
            app.ML_Image.Color = [1 1 1];
            app.ML_Image.Units = 'pixels';
            app.ML_Image.Position = [11 297 281 281];
            app.ML_Image.Visible = 'on';
            app.ML_Image.Colormap = gray(256);
            axis(app.ML_Image, 'image');
            %
            app.ML_Image_2 = axes('Parent',app.Panel_3);
            app.ML_Image_2.Box = 'on';
            app.ML_Image_2.XTick = [];
            app.ML_Image_2.XTickLabel = [];
            app.ML_Image_2.YTick = [];
            app.ML_Image_2.ZTick = [];
            app.ML_Image_2.Color = [1 1 1];
            app.ML_Image_2.Units = 'pixels';
            app.ML_Image_2.Position = [11 12 281 281];
            app.ML_Image_2.Visible = 'on';
            app.ML_Image_2.Colormap = gray(256);
            axis(app.ML_Image_2, 'image');
            %
            app.IF_Image = axes('Parent',app.Panel_3);
            app.IF_Image.Box = 'on';
            app.IF_Image.XTick = [];
            app.IF_Image.XTickLabel = [];
            app.IF_Image.YTick = [];
            app.IF_Image.ZTick = [];
            app.IF_Image.Color = [1 1 1];
            app.IF_Image.Units = 'pixels';
            app.IF_Image.Position = [298 297 281 281];
            app.IF_Image.Visible = 'on';
            app.IF_Image.Colormap = gray(256);
            axis(app.IF_Image, 'image');
            %
            app.IF_Image_2 = axes('Parent',app.Panel_3);
            app.IF_Image_2.Box = 'on'; 
            app.IF_Image_2.XTick = [];
            app.IF_Image_2.XTickLabel = [];
            app.IF_Image_2.YTick = [];
            app.IF_Image_2.ZTick = [];
            app.IF_Image_2.Color = [1 1 1];
            app.IF_Image_2.Units = 'pixels';
            app.IF_Image_2.Position = [298 12 281 281];
            app.IF_Image_2.Visible = 'on';
            app.IF_Image_2.Colormap = gray(256);
            axis(app.IF_Image_2, 'image');
            %
            app.ContrastSlider.Value = 50;
            app.BrightnessSlider.Value = 50;
            % Update the image and histograms
            % updateimage(app, 'peppers.png');
        end

        % Callback function
        function DropDownValueChanged(app, event)
            % Update the image 
            updateimage(app, app.DropDown.Value);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % if app.n is not empty then there are results and they should
            % be saved before moving on to next image
            if ~isempty(app.n)
                x = app.overlap_table;
                save([app.wd,'\inform_data\Component_Tiffs\',app.fname, '_overlap_table'],'x')
            end
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               imagefile = [p f];
               updateimage(app, imagefile);
            end
        end

        % Value changed function: BrightnessSlider
        function BrightnessSliderValueChanged(app, event)
            disp_images(app, 0)
        end

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged(app, event)
            disp_images(app, 0)
        end

        % Value changed function: ToggleSegmentationButton
        function ToggleSegmentationButtonValueChanged(app, event)
            disp_images(app, 0)            
        end

        % Button pushed function: GobacktolastcellButton
        function GobacktolastcellButtonPushed(app, event)
            if ~isempty(app.n) && app.n > 1
                app.n = app.n - 1;
                disp_images(app, 1, app.n, height(app.overlap_table));
            end
        end

        % Button pushed function: GotonextcellButton
        function GotonextcellButtonPushed(app, event)
            if ~isempty(app.n)
                app.n = app.n + 1;
                disp_images(app, 1, app.n, height(app.overlap_table))
            end
        end

        % Button pushed function: SaveTableButton
        function SaveTableButtonPushed(app, event)
            if ~isempty(app.n)
                x = app.overlap_table;
                save([app.wd,'\inform_data\Component_Tiffs\',app.fname,'_overlap_table'],'x')
            end
        end

        % Selection changed function: SegOptions
        function SegOptionsSelectionChanged(app, event)
            if ~isempty(app.n)
                %
                % Update the chart according to which segmentation option is
                % selected
                %
                if app.SelectMLSegmentationButton.Value
                    inp = app.n;
                    app.overlap_table.include_cell(inp) = 2;
                    app.overlap_table.final_cells(inp) = app.overlap_table.ML_cells(inp);
                end
                %
                if app.SelectIFSegmentationButton.Value
                    inp = app.n;
                    app.overlap_table.include_cell(inp) = 1;
                    app.overlap_table.final_cells(inp) = app.overlap_table.IF_cells(inp);
                end
                %
                if app.RejectCellButton.Value
                    inp = app.n;
                    app.overlap_table.include_cell(inp) = -1;
                end
                %
                if app.DrawNewSegmentationButton.Value
                    inp = app.n;
                    disp_images(app, 1, app.n, height(app.overlap_table));
                    app.overlap_table.include_cell(inp) = 3;
                    pp = 1;
                    while pp == 1
                        cell = drawfreehand(app.IF_Image_2, 'Selected',false, 'FaceAlpha', .2, 'Multiclick',true);
                        try
                            mm = createMask(cell);
                            pp = 0;
                        catch
                            delete(cell)
                        end
                    end
                    idx = find(mm > 0);
                    app.overlap_table.final_cells(inp) = {idx};
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Segmentation Tool';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load new image ';
            app.MachineLearningSegmentationLabel = uilabel(app.UIFigure);
            app.MachineLearningSegmentationLabel.Text = 'Machine Learning Segmentation';
            app.inFormSegmentationLabel = uilabel(app.UIFigure);
            app.inFormSegmentationLabel.Text = 'inForm Segmentation';
            app.Panel = uipanel(app.UIFigure);
            app.GobacktolastcellButton = uibutton(app.Panel, 'push');
            app.GobacktolastcellButton.ButtonPushedFcn = createCallbackFcn(app, @GobacktolastcellButtonPushed, true);
            app.GobacktolastcellButton.Text = 'Go back to last cell';
            app.GotonextcellButton = uibutton(app.Panel, 'push');
            app.GotonextcellButton.ButtonPushedFcn = createCallbackFcn(app, @GotonextcellButtonPushed, true);
            app.GotonextcellButton.Text = 'Go to next cell';
            app.SegOptions = uibuttongroup(app.Panel);
            app.SegOptions.SelectionChangedFcn = createCallbackFcn(app, @SegOptionsSelectionChanged, true);
            app.SelectMLSegmentationButton = uiradiobutton(app.SegOptions);
            app.SelectMLSegmentationButton.Text = {'         Select '; '  ML Segmentation'};
            app.SelectMLSegmentationButton.Value = true;
            app.SelectinFormSegmentationButton = uiradiobutton(app.SegOptions);
            app.SelectinFormSegmentationButton.Text = {'         Select '; '  inForm Segmentation'};
            app.DrawNewSegmentationButton = uiradiobutton(app.SegOptions);
            app.DrawNewSegmentationButton.Text = {'         Draw '; '  New Segmentation'};
            app.RejectCellButton = uiradiobutton(app.SegOptions);
            app.RejectCellButton.Text = '  Reject Cell';
            app.Panel_2 = uipanel(app.UIFigure);
            app.BrightnessSliderLabel = uilabel(app.Panel_2);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.Panel_2);
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessSliderValueChanged, true);
            app.BrightnessSlider.MinorTicks = [];
            app.ContrastSliderLabel = uilabel(app.Panel_2);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.Panel_2);
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.ContrastSlider.MinorTicks = [];
            app.ButtonGroup = uibuttongroup(app.Panel_2);
            app.DAPIButton = uiradiobutton(app.ButtonGroup);
            app.DAPIButton.Text = 'DAPI';
            app.DAPIButton.Value = true;
            app.MembraneButton = uiradiobutton(app.ButtonGroup);
            app.MembraneButton.Text = 'Membrane';
            app.ToggleSegmentationButton = uibutton(app.Panel_2, 'state');
            app.ToggleSegmentationButton.ValueChangedFcn = createCallbackFcn(app, @ToggleSegmentationButtonValueChanged, true);
            app.ToggleSegmentationButton.Text = 'Toggle Segmentation';
            app.Panel_3 = uipanel(app.UIFigure);
            app.SaveTableButton = uibutton(app.UIFigure, 'push');
            app.SaveTableButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTableButtonPushed, true);
            app.SaveTableButton.Text = 'Save Table';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SegTool_5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end